classdef AppLogs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LogsUIFigure     matlab.ui.Figure
        FilesMenu        matlab.ui.container.Menu
        SaveAsCtrlSMenu  matlab.ui.container.Menu
        GridLayout       matlab.ui.container.GridLayout
        TextArea         matlab.ui.control.TextArea
    end


    methods (Access = protected)

        function [folder, isOK] = uiGetDirWrap(~, options)
            % uiGetDirWrap - uigetdir wrapper with flexible texts
            %
            % Usage:
            %   [folder,isOK] = uiGetDirWrap(struct("Title","保存先を選択","StartPath",pwd));
            %   [folder,isOK] = uiGetDirWrap(struct("Parent",app.UIFigure,"Title","Select folder"));
            %
            % options (struct)
            %   Parent    : (optional) uifigure handle for App Designer
            %   Title     : dialog title (string)
            %   StartPath : initial folder (string)

            arguments
                ~
                options.Parent = []
                options.Title (1, 1) string = "Select folder"
                options.StartPath (1, 1) string = string(pwd)
            end

            % uigetdir does not accept Parent in older MATLAB versions.
            % So we keep interface but call uigetdir with (startPath,title).
            folder0 = char(options.StartPath);
            title0 = char(options.Title);

            out = uigetdir(folder0, title0);

            if isequal(out, 0)
                folder = "";
                isOK = false;
            else
                folder = string(out);
                isOK = true;
            end

        end % function uiGetDirWrap

    end % methods (Access = protected)

    methods (Access = private)
        

        function loadLogs(app)

            system = System();
            filepath = system.getCacheDirectory();
            filename = fullfile(filepath, "openmebius2.log");

            maxLines = 5000;
            nLines = 0;
            fid = fopen(filename, 'r');

            if fid == -1
                app.TextArea.Value = {"Log file not found."};
                return;
            end

            while ~feof(fid)
                fgetl(fid);
                nLines = nLines + 1;
            end

            startLine = max(1, nLines - maxLines + 1);

            fid = fopen(filename, 'r');

            if fid == -1
                app.TextArea.Value = {"Log file not found."};
                return;
            end

            currentLine = 0;
            logLines = {};

            while ~feof(fid)
                line = fgetl(fid);
                currentLine = currentLine + 1;

                if currentLine >= startLine
                    logLines{end + 1} = line; %#ok<AGROW>
                end

            end

            fclose(fid);

            app.TextArea.Value = logLines;

        end % function loadLogs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.loadLogs();

        end

        % Menu selected function: SaveAsCtrlSMenu
        function SaveAsCtrlSMenuSelected(app, event)

            [folder, isOK] = app.uiGetDirWrap();

            if ~isOK
                return;
            end

            system = System();
            filepath = system.getCacheDirectory();
            sourceFile = fullfile(filepath, "openmebius2.log");
            destFile = fullfile(folder, "openmebius2.log");

            try
                copyfile(sourceFile, destFile);
                msg = "Log file saved to: " + destFile;
                disp(msg);
            catch ME
                msg = "Failed to save log file: " + ME.message;
                disp(msg);
            end

        end

        % Key press function: LogsUIFigure
        function LogsUIFigureKeyPress(app, event)

            key = event.Key;

            if strcmp(key, "s") && ismember("control", event.Modifier)
                app.SaveAsCtrlSMenuSelected();
            elseif strcmp(key, "escape")
                delete(app);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.LogsUIFigure = uifigure('Visible', 'off');
            app.LogsUIFigure.Name = 'Logs';
            app.LogsUIFigure.Icon = fullfile(pathToMLAPP, '+img', 'logo.png');
            app.LogsUIFigure.KeyPressFcn = createCallbackFcn(app, @LogsUIFigureKeyPress, true);
            app.FilesMenu = uimenu(app.LogsUIFigure);
            app.FilesMenu.Text = 'Files';
            app.SaveAsCtrlSMenu = uimenu(app.FilesMenu);
            app.SaveAsCtrlSMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsCtrlSMenuSelected, true);
            app.SaveAsCtrlSMenu.Text = 'Save As (Ctrl + S)';
            app.GridLayout = uigridlayout(app.LogsUIFigure);
            app.TextArea = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.LogsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppLogs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LogsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LogsUIFigure)
        end
    end
end