classdef OpenMebius2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpenMebius2UIFigure             matlab.ui.Figure
        ApplicationMenu                 matlab.ui.container.Menu
        ReloadWindowMenu                matlab.ui.container.Menu
        DuplicatecurrentprojectMenu     matlab.ui.container.Menu
        ClearcacheMenu                  matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        ExperimentaldataMenu            matlab.ui.container.Menu
        ExporttemplateExcelfileMenu     matlab.ui.container.Menu
        FilesMenu                       matlab.ui.container.Menu
        ImportMSdatafromtextfilesMenu   matlab.ui.container.Menu
        ModelMenu                       matlab.ui.container.Menu
        BatchMenu                       matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        ViewReportMenu                  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        ViewlogsMenu                    matlab.ui.container.Menu
        OpenMebius2manualMenu           matlab.ui.container.Menu
        AboutOpenMebius2Menu            matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        SubUIAxes                       matlab.ui.control.UIAxes
        MainUIAxes                      matlab.ui.control.UIAxes
        GridLayout9                     matlab.ui.container.GridLayout
        LogTextArea                     matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        StoichiometryTab                matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout12                    matlab.ui.container.GridLayout
        ModelReloadButton               matlab.ui.control.Button
        ModelEditButton                 matlab.ui.control.Button
        ModelSaveButton                 matlab.ui.control.Button
        ModelTable                      matlab.ui.control.Table
        MSTab                           matlab.ui.container.Tab
        GridLayout11_2                  matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        AtomTable                       matlab.ui.control.Table
        MSTable                         matlab.ui.control.Table
        GridLayout12_2                  matlab.ui.container.GridLayout
        MSReloadButton                  matlab.ui.control.Button
        MSEditButton                    matlab.ui.control.Button
        MSSaveButton                    matlab.ui.control.Button
        ExperimentTab                   matlab.ui.container.Tab
        GridLayout11_3                  matlab.ui.container.GridLayout
        GridLayout13_2                  matlab.ui.container.GridLayout
        ExpTable                        matlab.ui.control.Table
        GridLayout14                    matlab.ui.container.GridLayout
        BiomassTable                    matlab.ui.control.Table
        GridLayout12_3                  matlab.ui.container.GridLayout
        ExpCalculationButton            matlab.ui.control.Button
        ExpImportButton                 matlab.ui.control.Button
        ExpReloadButton                 matlab.ui.control.Button
        ExpSaveButton                   matlab.ui.control.Button
        TracerTab                       matlab.ui.container.Tab
        GridLayout11_4                  matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        LabelTable                      matlab.ui.control.Table
        UptakeTable                     matlab.ui.control.Table
        GridLayout12_4                  matlab.ui.container.GridLayout
        TracerConfigButton              matlab.ui.control.Button
        TracerReloadButton              matlab.ui.control.Button
        TracerSaveButton                matlab.ui.control.Button
        RunTab                          matlab.ui.container.Tab
        GridLayout11_5                  matlab.ui.container.GridLayout
        GridLayout12_5                  matlab.ui.container.GridLayout
        RunAutoButton                   matlab.ui.control.Button
        RunConfigButton                 matlab.ui.control.Button
        RunReloadButton                 matlab.ui.control.Button
        RunSaveButton                   matlab.ui.control.Button
        RunRunButton                    matlab.ui.control.Button
        RunTable                        matlab.ui.control.Table
        ResultTab                       matlab.ui.container.Tab
        GridLayout11_6                  matlab.ui.container.GridLayout
        GridLayout15_2                  matlab.ui.container.GridLayout
        ResultSubTable                  matlab.ui.control.Table
        ResultMainTable                 matlab.ui.control.Table
        GridLayout12_7                  matlab.ui.container.GridLayout
        ResultDropDown                  matlab.ui.control.DropDown
        GridLayout12_6                  matlab.ui.container.GridLayout
        ResultReportButton              matlab.ui.control.Button
        ResultReloadButton              matlab.ui.control.Button
        ResultSaveButton                matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        StatusHTML                      matlab.ui.control.HTML
        ProjectPanel                    matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout16_2                  matlab.ui.container.GridLayout
        TemplateModelBrowseButton       matlab.ui.control.Button
        TemplateModelDirectoryDropDown  matlab.ui.control.DropDown
        GridLayout16                    matlab.ui.container.GridLayout
        ProjectBrowseButton             matlab.ui.control.Button
        ProjectDirectoryDropDown        matlab.ui.control.DropDown
        ProjectCreateButton             matlab.ui.control.Button
        TemplateModelSaveButton         matlab.ui.control.Button
        GridLayout5_3                   matlab.ui.container.GridLayout
        TemplateModelLoadButton         matlab.ui.control.Button
        ProjectLabel_2                  matlab.ui.control.Label
        GridLayout5_2                   matlab.ui.container.GridLayout
        ProjectSaveButton               matlab.ui.control.Button
        GridLayout8                     matlab.ui.container.GridLayout
        OrganismEditField               matlab.ui.control.EditField
        OrganismEditFieldLabel          matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        ProjectAuthorEditField          matlab.ui.control.EditField
        ProjectauthorEditFieldLabel     matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        ProjectLoadButton               matlab.ui.control.Button
        GridLayout4                     matlab.ui.container.GridLayout
        ProjectNameEditField            matlab.ui.control.EditField
        ProjectnameEditFieldLabel       matlab.ui.control.Label
        ProjectLabel                    matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        AddLabelMenu                    matlab.ui.container.Menu
        RemoveLabelMenu                 matlab.ui.container.Menu
        ExperimentContextMenu           matlab.ui.container.ContextMenu
        ViewMStableMenu                 matlab.ui.container.Menu
        ContextMenuRun                  matlab.ui.container.ContextMenu
        AddbatchMenu                    matlab.ui.container.Menu
        DuplicatethisbatchMenu          matlab.ui.container.Menu
        RemovethisbatchMenu             matlab.ui.container.Menu
        ParallellabelingMenu            matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        RelativetoMenu                  matlab.ui.container.Menu
        ContextMenuResultSelect         matlab.ui.container.ContextMenu
        RangeplotMenu                   matlab.ui.container.Menu
        ViewsuggestionMenu              matlab.ui.container.Menu
        ContextMenu3                    matlab.ui.container.ContextMenu
        CopythistracerforallentriesMenu  matlab.ui.container.Menu
    end


    properties (Access = public)

        typeSimulation (1, 1) string {mustBeMember(typeSimulation, ["MDV", "Flux", "Label"])} = "Flux";
        report;

        LabelConfigApp;
        TracerConfigApp;
        RunConfigApp;
        MSViewApp;
        ComparisonViewApp;
        ViewComparisonApp;
        RunAddBatchApp;
        ViewSuggestionApp;
        RangePlotFigure;
        LogApp;
        ProgressBar CustomProgressBar

        % Styles
        styleError = uistyle('BackgroundColor', '#FFAABB');
        styleErrorDark = uistyle('BackgroundColor', '#332225');

        % Styles for results
        styleIsPassed = uistyle('FontColor', '#009E73', 'FontWeight', 'bold');
        styleIsNotPassed = uistyle('FontColor', '#D55E00', 'FontWeight', 'bold');
        styleIsPassedDark = uistyle('FontColor', '#4FD1A5', 'FontWeight', 'bold');
        styleIsNotPassedDark = uistyle('FontColor', '#FF9D66', 'FontWeight', 'bold');

        % Table styles
        styleSuccessIcon = uistyle('Icon', 'success', 'IconAlignment', 'rightmargin');
        styleWarningIcon = uistyle('Icon', 'warning', 'IconAlignment', 'rightmargin');
        styleErrorIcon = uistyle('Icon', 'error', 'IconAlignment', 'rightmargin');
        styleQuestionIcon = uistyle('Icon', 'question', 'IconAlignment', 'rightmargin');
        styleInfoIcon = uistyle('Icon', 'info', 'IconAlignment', 'rightmargin');

    end % properties (Access = public)

    %% Private properties

    properties (Access = private)

        % Directory
        directoryModel (1, 1) string
        directoryExp (1, 1) string
        directoryResult (1, 1) string

        % Editable array for locking and unlocking
        ModelTableEditable logical
        MSTableEditable logical
        AtomTableEditable logical
        BiomassTableEditable logical
        TracerTableEditable logical
        UptakeTableEditable logical
        RunTableEditable logical
        ResultMainTableEditable logical
        ResultSubTableEditable logical

    end % properties (Access=private)

    properties (Access = private)

        AppDependencies openmebius.bootstrap.MainAppDependencies
        ApplicationController openmebius.application.main ...
            .MainApplicationController
        Presenter openmebius.presentation.main.MainPresenter
        ProjectPresenter openmebius.presentation.project.ProjectPresenter
        ModelPresenter openmebius.presentation.model.ModelPresenter
        LabelConfigPresenter openmebius.presentation.model.LabelConfigPresenter
        BatchPresenter openmebius.presentation.batch.BatchPresenter
        RunConfigPresenter openmebius.presentation.batch.RunConfigPresenter
        BatchExperimentSelectionEditorPresenter openmebius.presentation.batch.BatchExperimentSelectionEditorPresenter
        ExperimentPresenter openmebius.presentation.experiment.ExperimentPresenter
        ResultPresenter openmebius.presentation.result.ResultPresenter
        ResultPlotPresenter openmebius.presentation.result.ResultPlotPresenter
        StatusState openmebius.presentation.status.SectionStatusState = ...
            openmebius.presentation.status.SectionStatusState()
        ProgressBarFactory openmebius.presentation.main.ProgressBarFactory
        ChildAppHost openmebius.presentation.lifecycle.ChildAppHost
        DialogService openmebius.presentation.dialog.AppDialogService
        BatchConfigurationController openmebius.application.batch.BatchConfigurationController
        BatchExperimentSelectionEditorController openmebius.application.batch.BatchExperimentSelectionEditorController
        ExperimentEditController openmebius.application.experiment.ExperimentEditController

        SlackNotifier openmebius.infrastructure.notification.SlackWebhookNotifier
        NotificationDispatcher openmebius.infrastructure.notification ...
            .NotificationDispatcher

        PreferencesApp

        MainInteractionSnapshot cell = {}
        IsUpdatingResultSelection (1, 1) logical = false

    end % properties (Access=private)

    methods (Access = public)

        %% Public check methods
        function isDark = isDarkTheme(app)

            isDark = app.OpenMebius2UIFigure ...
                .Theme.BaseColorStyle == "dark";

        end % function isDarkTheme

        function saveHistory(app)

            projectHistory = app.ProjectDirectoryDropDown.Items;
            tempModelHistory = app.TemplateModelDirectoryDropDown.Items;

            system = System();
            directoryLog = system.getCacheDirectory();

            if isempty(directoryLog)
                return;
            end

            fileLog = fullfile(directoryLog, 'history.mat');

            if ~isfolder(directoryLog)
                mkdir(directoryLog);
            end

            save(fileLog, 'projectHistory', 'tempModelHistory');

        end % function saveHistory

        function clearHistory(app)

            app.ProjectDirectoryDropDown.Items = {};
            app.TemplateModelDirectoryDropDown.Items = {};
            app.saveHistory();

        end % function clearHistory

        %% Public update functions
        function updateStatus(app, section, status)
            % UPDATESTATUS Update the status table with the current status

            arguments
                app
                section string {mustBeMember(section, ["model", "experiment", "batch", "result"])}
                status string {mustBeMember(status, ["init", "running", "finished", "error"])}
            end

            rows = app.StatusState.update(section, status);

            initStatusTable(app, update = true, rows = rows);

        end % method updateStatus

        function updateBatchTable(app)

            loadBatchTable(app);
            app.refreshPresentation();

        end % function updateBatchTable

        %% Public test methods
        function testResultSubTableCellSelection(app)
            % TESTRESULTSUBTABLECELLSELECTION Test the cell selection in the ResultSubTable
            %
            % Trigger the event.

            selection = app.ResultSubTable.Selection;

            ResultSubTableCellSelection(app, selection);

        end % function testResultSubTableCellSelection

        function testResultMainTableCellSelection(app)
            % TESTRESULTMAINTABLECELLSELECTION Test the cell selection in the ResultMainTable
            %
            % Trigger the event.

            selection = app.ResultMainTable.Selection;

            ResultMainTableCellSelection(app, selection);

        end % function testResultMainTableCellSelection

    end % methods (Access = public)

    methods (Access = protected)

        %% Protected wrapper functions
        function [folder, isOK] = uiGetDirWrap(app, options)

            arguments
                app
                options.Parent = []
                options.Title (1, 1) string = "Select folder"
                options.StartPath (1, 1) string = string(pwd)
            end

            [folder, isOK] = app.DialogService.selectFolder( ...
                Title = options.Title, ...
                StartPath = options.StartPath);

        end % function uiGetDirWrap

        function [files, isOK] = uiGetFileWrap(app, options)

            arguments
                app
                options.Parent = []
                options.Filter = "*.*"
                options.Title (1, 1) string = "Select file"
                options.StartPath (1, 1) string = string(pwd)
                options.MultiSelect (1, 1) string {mustBeMember(options.MultiSelect, ["off", "on"])} = "off"
                options.Save (1, 1) logical = false
                options.DefaultName (1, 1) string = ""
            end

            [files, isOK] = app.DialogService.selectFile( ...
                Filter = options.Filter, ...
                Title = options.Title, ...
                StartPath = options.StartPath, ...
                MultiSelect = options.MultiSelect, ...
                Save = options.Save, ...
                DefaultName = options.DefaultName);

        end % function uiGetFileWrap

        function [answer, isOK] = uiInputDlgWrap(app, options)

            arguments
                app
                options.Prompt (1, :) string = "Input"
                options.Title (1, 1) string = "Input dialog"
                options.Default (1, :) string = ""
                options.Dims (1, 2) double = [1 50]
            end

            [answer, isOK] = app.DialogService.inputText( ...
                Prompt = options.Prompt, ...
                Title = options.Title, ...
                Default = options.Default, ...
                Dims = options.Dims);

        end % function uiInputDlgWrap

        function [answer, isOK] = uiConfirmWrap(app, message, title, options)

            arguments
                app
                message (1, 1) string
                title (1, 1) string = "Confirm"
                options.Options (1, :) string = ["OK", "Cancel"]
                options.DefaultOption (1, 1) string = "OK"
                options.CancelOption (1, 1) string = "Cancel"
                options.Icon (1, 1) string = "question"
            end

            [answer, isOK] = app.DialogService.confirm( ...
                message, ...
                title, ...
                Options = options.Options, ...
                DefaultOption = options.DefaultOption, ...
                CancelOption = options.CancelOption, ...
                Icon = options.Icon);

        end % function uiConfirmWrap

        function uiAlertWrap(app, message, options)

            arguments
                app
                message (1, 1) string
                options.Title (1, 1) string = "Message"
                options.Icon (1, 1) string = "info"
                options.Interpreter (1, 1) string = "none"
            end

            app.DialogService.alert( ...
                message, ...
                Title = options.Title, ...
                Icon = options.Icon, ...
                Interpreter = options.Interpreter);

        end % function uiAlertWrap

        function dependencies = createMainAppDependencies(~)

            dependencies = openmebius.bootstrap ...
                .MainAppCompositionRoot.create();

        end % createMainAppDependencies

        function performStartupUpdateCheck(app)

            app.checkLatestVersionOnStartup();

        end % performStartupUpdateCheck

    end % methods (Access = protected)

    methods (Access = private)

        %% Private presentation adapter functions
        function applyApplicationDependencies(app, dependencies)

            arguments
                app
                dependencies (1, 1) openmebius.bootstrap ...
                    .MainAppDependencies
            end

            app.AppDependencies = dependencies;
            app.ApplicationController = dependencies.ApplicationController;
            app.Presenter = dependencies.MainPresenter;
            app.ProjectPresenter = dependencies.ProjectPresenter;
            app.ModelPresenter = dependencies.ModelPresenter;
            app.LabelConfigPresenter = dependencies.LabelConfigPresenter;
            app.BatchPresenter = dependencies.BatchPresenter;
            app.RunConfigPresenter = dependencies.RunConfigPresenter;
            app.BatchExperimentSelectionEditorPresenter = ...
                dependencies.BatchExperimentSelectionEditorPresenter;
            app.ExperimentPresenter = dependencies.ExperimentPresenter;
            app.ResultPresenter = dependencies.ResultPresenter;
            app.ResultPlotPresenter = dependencies.ResultPlotPresenter;
            app.ProgressBarFactory = dependencies.ProgressBarFactory;
            app.ChildAppHost = dependencies.ChildAppHost;
            app.BatchConfigurationController = ...
                dependencies.BatchConfigurationController;
            app.BatchExperimentSelectionEditorController = ...
                dependencies.BatchExperimentSelectionEditorController;
            app.ExperimentEditController = ...
                dependencies.ExperimentEditController;
            app.SlackNotifier = dependencies.SlackNotifier;
            app.NotificationDispatcher = ...
                dependencies.NotificationDispatcher;

        end % applyApplicationDependencies

        function renderMainViewModel(app, viewModel)
            % RENDERMAINVIEWMODEL Render the main view model
            % renderMainViewModel(app, viewModel)
            %
            %  Input:
            %   viewModel: An object of class MainViewModel

            if isempty(viewModel)
                return
            end

            if isprop(viewModel, "UiState")
                app.renderUiState(viewModel.UiState);
                return
            end

            % For transitional versions where viewModel may be a struct.
            if isstruct(viewModel) && isfield(viewModel, "UiState")
                app.renderUiState(viewModel.UiState);
                return
            end

            error( ...
                "OpenMebius2:Presentation:InvalidViewModel", ...
            "MainViewModel must contain UiState.");

        end % method renderMainViewModel

        function renderProjectOperationViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            if viewModel.ModelStatus ~= ""
                app.updateStatus("model", viewModel.ModelStatus);
            end

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

            if isempty(viewModel.Session)
                return
            end

            try
                app.applyProjectSession(viewModel.Session);
                app.addProjectDirectoryHistoryItem( ...
                    viewModel.Session.Paths.RootDirectory);

                switch viewModel.ArtifactMode
                    case "open"
                        app.renderProjectArtifacts();
                        app.refreshPresentation();

                    case "create"
                        app.renderCreatedProjectArtifacts();
                        app.refreshPresentation();
                end

            catch exception
                app.updateStatus("model", "error");

                if viewModel.ArtifactMode == "create"
                    title = "Project create failed";
                else
                    title = "Project load failed";
                end

                app.publishException( ...
                    exception, ...
                    Title = title, ...
                    Alert = true);
            end

        end % renderProjectOperationViewModel

        function renderModelOperationViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            if viewModel.SectionStatus ~= ""
                app.updateStatus("model", viewModel.SectionStatus);
            end

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

            try
                app.applyModelValidationStyles( ...
                    viewModel.ValidationStyles);

                for reportIndex = 1:numel(viewModel.ValidationReports)
                    app.showModelValidationReport( ...
                        viewModel.ValidationReports{reportIndex});
                end

                if ~isempty(viewModel.Result)
                    pause(0.5)

                    app.loadEMUModel();
                    app.loadPathway();
                end

                if ~isempty(viewModel.CompletionNotification)
                    app.publishNotification( ...
                        viewModel.CompletionNotification);
                end

                if viewModel.CompletionStatus ~= ""
                    app.updateStatus( ...
                        "model", ...
                        viewModel.CompletionStatus);
                end

                if viewModel.FinishEditCommit
                    app.finishPresentationEditCommit( ...
                        viewModel.EditCommitSucceeded);
                end

            catch exception

                if viewModel.FinishEditCommit
                    app.finishPresentationEditCommit(false);
                end

                app.updateStatus("model", "error");
                app.publishException( ...
                    exception, ...
                    Title = viewModel.ErrorTitle, ...
                    Alert = true);
            end

        end % renderModelOperationViewModel

        function renderModelPathwayEditViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotificationpublishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

            if ~isempty(viewModel.UpdatedModelTable)
                app.ModelTable.Data = viewModel.UpdatedModelTable;
                app.ModelTable.ColumnName = ...
                    viewModel.UpdatedModelTable.Properties.VariableNames;
                app.ModelTable.RowName = ...
                    viewModel.UpdatedModelTable.Properties.RowNames;
            end

            if ~isempty(viewModel.Pathway.Image)
                app.renderPathwayPlot(viewModel.Pathway);
            end

        end % renderModelPathwayEditViewModel

        function applyModelValidationStyles(app, styleRules)

            if isempty(styleRules)
                return
            end

            targets = string({styleRules.Target});

            if any(targets == "model")
                app.resetModelTableColorFormat();
            end

            if any(targets == "ms" | targets == "atom")
                app.resetMSTableColorFormat();
            end

            for ruleIndex = 1:numel(styleRules)
                rows = styleRules(ruleIndex).Rows;

                if isempty(rows)
                    continue
                end

                switch string(styleRules(ruleIndex).Target)
                    case "model"
                        target = app.ModelTable;

                    case "ms"
                        target = app.MSTable;

                    case "atom"
                        target = app.AtomTable;

                    otherwise
                        error( ...
                            "OpenMebius2:Model:InvalidStyleTarget", ...
                            "Unknown model style target: %s", ...
                            string(styleRules(ruleIndex).Target));
                end

                addStyle( ...
                    target, ...
                    app.styleError, ...
                    'row', ...
                    rows);
            end

        end % applyModelValidationStyles

        function renderProjectArtifacts(app)

            % -------------------------------------------------------------
            % Model UI
            % -------------------------------------------------------------
            app.updateStatus("model", "running");

            app.publishMessage("info", "Constructing EMU network...");

            loadEMUModel(app)

            loadPathway(app)

            app.publishMessage("info", "EMU network was successfully constructed.");
            app.updateStatus("model", "finished");

            % -------------------------------------------------------------
            % Experiment UI
            % -------------------------------------------------------------
            app.updateStatus("experiment", "running");

            loadExpData(app)

            app.updateStatus("experiment", "finished");

            % -------------------------------------------------------------
            % Batch UI
            % -------------------------------------------------------------
            app.updateStatus("batch", "running");

            loadBatchTable(app)

            app.updateStatus("batch", "finished");
            app.publishMessage("info", "Batch table loaded successfully.");

            % -------------------------------------------------------------
            % Result UI
            % -------------------------------------------------------------
            app.updateStatus("result", "running");

            loadResult(app)

            if isempty(app.ResultSubTable.Data)
                app.updateStatus("result", "init");
                app.publishMessage("info", "No result files found in the results directory.");
            else
                app.updateStatus("result", "finished");
                app.publishMessage("info", "Result files loaded successfully.");
            end

        end % method renderProjectArtifacts

        function renderCreatedProjectArtifacts(app)

            app.updateStatus("model", "running");

            app.publishMessage("info", "Constructing EMU network...");

            pause(0.5)

            loadEMUModel(app)

            loadPathway(app)

            app.updateStatus("model", "finished");
            app.publishMessage("info", "New project created and model loaded successfully.");

            app.updateStatus("experiment", "init");
            app.updateStatus("batch", "init");
            app.updateStatus("result", "init");

        end % method renderCreatedProjectArtifacts

        function renderBatchTable(app, viewModel)

            if isempty(viewModel)
                return
            end

            removeStyle(app.RunTable);

            app.RunTable.Data = viewModel.Data;

            try
                app.RunTable.UserData = struct( ...
                    "RawData", viewModel.RawData);
            catch
                % Fall back to the displayed table on older releases.
            end

            if isempty(viewModel.Data)
                app.RunTable.ColumnName = [];
                app.RunTable.RowName = [];
                app.RunTable.ColumnEditable = false;
                return
            end

            app.RunTable.ColumnName = ...
                viewModel.Data.Properties.VariableNames;

            app.RunTable.RowName = ...
                viewModel.Data.Properties.RowNames;

            app.RunTable.ColumnEditable = ...
                viewModel.ColumnEditable;

            app.applyBatchStyleRules(viewModel.StyleRules);

        end % method renderBatchTable

        function renderBatchOperationViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            if ~isempty(viewModel.TableViewModel)
                app.renderBatchTable(viewModel.TableViewModel);
                app.refreshPresentation();
            end

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

        end % renderBatchOperationViewModel

        function renderRunConfigLaunchViewModel(app, viewModel)

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

            if ~viewModel.IsAvailable
                return
            end

            app.closeRunConfigApp();
            context = openmebius.presentation.batch ...
                .RunConfigContext( ...
                Session = viewModel.Session, ...
                Presenter = app.RunConfigPresenter, ...
                Editor = viewModel.Editor, ...
                ConfigurationController = ...
                app.BatchConfigurationController, ...
                ExperimentEditController = ...
                app.ExperimentEditController, ...
                ExperimentPresenter = ...
                app.ExperimentPresenter, ...
                ExperimentSelectionController = ...
                app.BatchExperimentSelectionEditorController, ...
                ExperimentSelectionPresenter = ...
                app.BatchExperimentSelectionEditorPresenter);
            app.RunConfigApp = RunConfig(context);
            app.attachRunConfigListeners(app.RunConfigApp);

        end % renderRunConfigLaunchViewModel

        function renderLabelConfigLaunchViewModel(app, viewModel)

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

            if ~viewModel.IsAvailable
                return
            end

            app.closeLabelConfigApp();
            context = openmebius.presentation.model ...
                .LabelConfigContext( ...
                LabelTable = viewModel.LabelTable, ...
                RatioTables = viewModel.RatioTables);
            app.LabelConfigApp = LabelConfig(context);
            app.attachLabelConfigListeners(app.LabelConfigApp);

        end % renderLabelConfigLaunchViewModel

        function renderBatchProgress(app, viewModel)

            if isempty(viewModel)
                return
            end

            progressBar = app.getProgressBar();

            progressBar.setProgress( ...
                viewModel.Rate, ...
                viewModel.Message);

            drawnow limitrate

        end % method renderBatchProgress

        function renderBatchRunViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            if viewModel.SectionStatus ~= ""
                app.updateStatus("batch", viewModel.SectionStatus);
            end

            if viewModel.CompletionStatus ~= ""
                app.publishBatchCompletion(viewModel);
            elseif ~isempty(viewModel.Notification)
                app.publishNotification(viewModel.Notification);
            end

        end % renderBatchRunViewModel

        function renderExperimentCalculationViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            app.updateStatus("experiment", viewModel.SectionStatus);

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

        end % renderExperimentCalculationViewModel

        function renderExperimentImportViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            if ~isempty(viewModel.Result)
                app.renderExperimentImportResult( ...
                    viewModel.Result, ...
                    LogMessages = false);
            end

            if viewModel.SectionStatus ~= ""
                app.updateStatus( ...
                    "experiment", ...
                    viewModel.SectionStatus);
            end

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

        end % renderExperimentImportViewModel

        function renderExperimentEditViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            if ~isempty(viewModel.UpdatedTable)
                app.LabelTable.Data = viewModel.UpdatedTable;
            end

            if viewModel.SectionStatus ~= ""
                app.updateStatus( ...
                    "experiment", ...
                    viewModel.SectionStatus);
            end

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

        end % renderExperimentEditViewModel

        function renderResultOperationViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            if ~isempty(viewModel.Report)
                app.report = viewModel.Report;
            end

            if ~isempty(viewModel.Suggestion)
                app.ViewSuggestionApp = ...
                    ViewSuggestion( ...
                    viewModel.Suggestion, ...
                    app.NotificationDispatcher.reporter());
            end

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

        end % renderResultOperationViewModel

        function renderResultRelativeViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

            if viewModel.RelativeTo == ""
                return
            end

            app.loadMainResultTable( ...
                relative = true, ...
                relativeTo = viewModel.RelativeTo);

        end % renderResultRelativeViewModel

        function renderResultRangePlotViewModel(app, viewModel)

            if isempty(viewModel)
                return
            end

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

            if isempty(viewModel.UpperBounds) || ...
                    isempty(viewModel.LowerBounds)
                return
            end

            if app.isLoadedObject(app.RangePlotFigure)
                delete(app.RangePlotFigure);
            end

            rangeFigure = uifigure( ...
                'Name', 'Flux range plot', ...
                'Position', [120 80 1100 820]);
            app.RangePlotFigure = rangeFigure;
            layout = uigridlayout(rangeFigure, [1 1]);
            layout.Padding = [12 12 12 12];
            axes = uiaxes(layout);
            axes.Layout.Row = 1;
            axes.Layout.Column = 1;

            try
                RangePlot( ...
                    axes, ...
                    viewModel.UpperBounds, ...
                    viewModel.LowerBounds, ...
                    Bestfit = viewModel.BestFits, ...
                    BestfitStyle = "triangle", ...
                    FontSize = 10, ...
                    ReactionNames = viewModel.ReactionNames);
                title(axes, "Flux ranges");
                xlabel(axes, "Flux");
            catch exception
                delete(rangeFigure);
                app.RangePlotFigure = [];
                app.publishException( ...
                    exception, ...
                    Title = "Range plot failed", ...
                    Alert = true);
            end

        end % renderResultRangePlotViewModel

        function renderResultMainTable(app, viewModel)

            if isempty(viewModel)
                return
            end

            removeStyle(app.ResultMainTable);

            app.ResultMainTable.Data = viewModel.Data;

            try
                app.ResultMainTable.UserData = struct( ...
                    "RawData", viewModel.RawData);
            catch
                % If UserData is unavailable for some MATLAB release,
                % updateResultPlot will fall back to ResultMainTable.Data.
            end

            if isempty(viewModel.Data)
                app.ResultMainTable.ColumnName = [];
                app.ResultMainTable.RowName = [];
                app.ResultMainTable.ColumnEditable = false;
                return
            end

            app.ResultMainTable.ColumnName = ...
                viewModel.Data.Properties.VariableNames;

            app.ResultMainTable.RowName = ...
                viewModel.Data.Properties.RowNames;

            app.ResultMainTable.ColumnEditable = ...
                viewModel.ColumnEditable;

            app.applyResultStyleRules( ...
                app.ResultMainTable, ...
                viewModel.StyleRules);

        end % method renderResultMainTable

        function renderResultSubTable(app, viewModel)

            if isempty(viewModel)
                return
            end

            removeStyle(app.ResultSubTable);

            app.ResultSubTable.Data = viewModel.Data;

            try
                app.ResultSubTable.UserData = struct( ...
                    "RawData", viewModel.RawData);
            catch
                % Fall back to the displayed table on older releases.
            end

            if isempty(viewModel.Data)
                app.ResultSubTable.ColumnName = [];
                app.ResultSubTable.RowName = [];
                app.ResultSubTable.ColumnEditable = false;
                return
            end

            app.ResultSubTable.ColumnName = ...
                viewModel.Data.Properties.VariableNames;

            app.ResultSubTable.RowName = ...
                viewModel.Data.Properties.RowNames;

            app.ResultSubTable.ColumnEditable = ...
                viewModel.ColumnEditable;

            app.applyResultStyleRules( ...
                app.ResultSubTable, ...
                viewModel.StyleRules);

        end % method renderResultSubTable

        function renderResultPlot(app, viewModel)

            if isempty(viewModel)
                return
            end

            if ~isempty(viewModel.Notification)
                app.publishNotification(viewModel.Notification);
            end

            switch viewModel.Kind

                case openmebius.presentation.result.ResultPlotKind.None
                    app.clearResultPlots();

                case openmebius.presentation.result.ResultPlotKind.OverviewFlux
                    app.renderOverviewResultPlot(viewModel);

                case openmebius.presentation.result.ResultPlotKind.OptimizationState
                    app.clearResultPlots();
                    app.renderOptimizationRSSHistogram(viewModel.SubPlot);

                otherwise
                    app.clearResultPlots();
            end

        end % method renderResultPlot

        function renderOverviewResultPlot(app, viewModel)

            mainPlot = viewModel.MainPlot;
            subPlot = viewModel.SubPlot;

            app.MainUIAxes.Visible = 'on';
            app.SubUIAxes.Visible = 'on';

            if isfield(mainPlot, "Kind") && mainPlot.Kind == "pathway"
                app.renderPathwayPlot(mainPlot.Pathway);

            else
                app.resetResultAxes(app.MainUIAxes);
            end

            if isfield(subPlot, "Kind") && ...
                    subPlot.Kind == "monte-carlo-ci"
                app.renderMonteCarloConfidenceInterval(subPlot);

            elseif isfield(subPlot, "Kind") && ...
                    subPlot.Kind == "grid-search-profile"
                app.renderGridSearchProfile(subPlot);

            elseif isfield(subPlot, "Kind") && ...
                    subPlot.Kind == "optimization-rss-histogram"
                app.renderOptimizationRSSHistogram(subPlot);

            else
                app.resetResultAxes(app.SubUIAxes);
            end

        end % method renderOverviewResultPlot

        function renderPathwayPlot(app, viewModel)

            axes = app.MainUIAxes;
            cla(axes);

            if isempty(viewModel) || isempty(viewModel.Image)
                return
            end

            pathwayImage = viewModel.Image;

            if viewModel.IsDarkTheme
                pathwayImage = app.convertPathwayImageForDarkTheme( ...
                    pathwayImage);
            end

            pathwayGraphic = image( ...
                axes, pathwayImage, 'HitTest', 'off');
            imageRatio = size(pathwayImage, 1) / ...
                size(pathwayImage, 2);
            axes.DataAspectRatio = [1 imageRatio 1];
            axes.Visible = 'off';
            axis(axes, 'image');
            title(axes, 'Metabolic Pathway');
            xlabel(axes, '');
            ylabel(axes, '');
            axes.HitTest = 'on';
            axes.PickableParts = 'all';
            axes.ContextMenu = app.ContextMenu;
            pathwayGraphic.ContextMenu = app.ContextMenu;

            if viewModel.IsDarkTheme
                labelColor = '#FFFFFF';
            else
                labelColor = '#000000';
            end

            for labelIndex = 1:numel(viewModel.Labels)

                if ~isfinite(viewModel.X(labelIndex)) || ...
                        ~isfinite(viewModel.Y(labelIndex))
                    continue
                end

                if viewModel.Highlight(labelIndex)
                    color = '#009E73';
                    weight = 'bold';
                else
                    color = labelColor;
                    weight = 'normal';
                end

                text( ...
                    axes, ...
                    viewModel.X(labelIndex), ...
                    viewModel.Y(labelIndex), ...
                    viewModel.Labels(labelIndex), ...
                    'Color', color, ...
                    'FontSize', 14, ...
                    'FontWeight', weight);
            end

        end % renderPathwayPlot

        function imageOut = convertPathwayImageForDarkTheme(~, imageIn)

            if isa(imageIn, 'uint8')
                imageIn = im2double(imageIn);
            end

            if size(imageIn, 3) == 1
                imageOut = 1 - imageIn;
                return
            end

            if size(imageIn, 3) == 3
                hsvImage = rgb2hsv(imageIn);
                hsvImage(:, :, 3) = 0.9 - hsvImage(:, :, 3);
                hsvImage(:, :, 3) = max( ...
                    0.2, min(hsvImage(:, :, 3), 0.95));
                imageOut = hsv2rgb(hsvImage);
                return
            end

            imageOut = imageIn;

        end % convertPathwayImageForDarkTheme

        function renderMonteCarloConfidenceInterval(app, plotData)

            lowerBounds = double(plotData.LowerBounds(:)');
            upperBounds = double(plotData.UpperBounds(:)');
            bestFit = double(plotData.BestFit);
            iterationCount = numel(lowerBounds);

            finiteValues = [ ...
                                lowerBounds(isfinite(lowerBounds)), ...
                                upperBounds(isfinite(upperBounds)), ...
                                bestFit(isfinite(bestFit))];

            if iterationCount == 0 || isempty(finiteValues)
                app.resetResultAxes(app.SubUIAxes);
                return
            end

            yMinimum = min(finiteValues);
            yMaximum = max(finiteValues);
            yMargin = max(0.1 * (yMaximum - yMinimum), 0.1);
            iterations = 1:iterationCount;
            axes = app.SubUIAxes;

            cla(axes);
            app.prepareCartesianResultAxes(axes);
            axes.FontSize = 16;
            axes.FontName = 'Arial';
            axes.XLim = [0 iterationCount + 1];
            axes.YLim = [yMinimum - yMargin yMaximum + yMargin];
            axes.XLabel.String = "Iteration";
            axes.YLabel.String = "Flux";
            axes.Title.String = plotData.Title;
            axes.XTick = 0:100:iterationCount;
            axes.XTickLabel = string(axes.XTick);
            axes.XTickLabelRotation = 0;

            hold(axes, 'on');
            plot( ...
                axes, iterations, repmat(bestFit, size(iterations)), ...
                '-', ...
                'Color', "#E69F00", ...
                'LineWidth', 3, ...
                'DisplayName', 'Best Fit');
            plot( ...
                axes, iterations, lowerBounds, ...
                '-', ...
                'Color', "#56B4E9", ...
                'LineWidth', 3, ...
                'DisplayName', 'Flux LB');
            plot( ...
                axes, iterations, upperBounds, ...
                '-', ...
                'Color', "#009E73", ...
                'LineWidth', 3, ...
                'DisplayName', 'Flux UB');
            legend(axes, 'show', 'Location', 'best');
            hold(axes, 'off');

        end % renderMonteCarloConfidenceInterval

        function renderGridSearchProfile(app, plotData)

            profileX = double(plotData.X(:));
            profileRSS = double(plotData.Y(:));
            finiteProfile = isfinite(profileX) & isfinite(profileRSS);
            axes = app.SubUIAxes;

            if ~any(finiteProfile)
                app.resetResultAxes(axes);
                return
            end

            profileX = profileX(finiteProfile);
            profileRSS = profileRSS(finiteProfile);
            cla(axes);
            app.prepareCartesianResultAxes(axes);
            axes.FontSize = 16;
            axes.FontName = 'Arial';
            axes.XLabel.String = "Fixed flux";
            axes.YLabel.String = "RSS";
            axes.Title.String = plotData.Title;
            axes.XGrid = 'on';
            axes.YGrid = 'on';
            yLimits = double(plotData.YLimits(:)).';
            axes.YLim = yLimits;

            fvaLowerBound = double(plotData.FVALowerBound);
            fvaUpperBound = double(plotData.FVAUpperBound);
            xLimits = double(plotData.XLimits(:)).';
            axes.XLim = xLimits;
            hold(axes, 'on');

            hasFVALegendEntry = false;

            if fvaLowerBound > xLimits(1)
                lowerShadeUpper = min(fvaLowerBound, xLimits(2));
                patch( ...
                    axes, ...
                    [xLimits(1), lowerShadeUpper, ...
                     lowerShadeUpper, xLimits(1)], ...
                    [yLimits(1), yLimits(1), ...
                     yLimits(2), yLimits(2)], ...
                    [0.85, 0.85, 0.85], ...
                    'EdgeColor', 'none', ...
                    'FaceAlpha', 0.65, ...
                    'DisplayName', 'Outside FVA');
                hasFVALegendEntry = true;
            end

            if fvaUpperBound < xLimits(2)
                upperShadeLower = max(fvaUpperBound, xLimits(1));
                visibility = 'off';

                if ~hasFVALegendEntry
                    visibility = 'on';
                end

                patch( ...
                    axes, ...
                    [upperShadeLower, xLimits(2), ...
                     xLimits(2), upperShadeLower], ...
                    [yLimits(1), yLimits(1), ...
                     yLimits(2), yLimits(2)], ...
                    [0.85, 0.85, 0.85], ...
                    'EdgeColor', 'none', ...
                    'FaceAlpha', 0.65, ...
                    'HandleVisibility', visibility, ...
                    'DisplayName', 'Outside FVA');
            end

            if isfield(plotData, "TrialX") && ...
                    isfield(plotData, "TrialRSS")
                trialX = double(plotData.TrialX(:));
                trialRSS = double(plotData.TrialRSS(:));
                finiteTrials = isfinite(trialX) & isfinite(trialRSS);

                if any(finiteTrials)
                    scatter( ...
                        axes, ...
                        trialX(finiteTrials), ...
                        trialRSS(finiteTrials), ...
                        18, ...
                        [0.7 0.7 0.7], ...
                        'filled', ...
                        'DisplayName', 'Trials');
                end

            end

            plot( ...
                axes, ...
                profileX, ...
                profileRSS, ...
                '-o', ...
                'Color', "#0072B2", ...
                'MarkerFaceColor', "#0072B2", ...
                'LineWidth', 2.5, ...
                'DisplayName', 'Minimum RSS');

            if isfield(plotData, "ObjectiveThreshold") && ...
                    isscalar(plotData.ObjectiveThreshold) && ...
                    isfinite(plotData.ObjectiveThreshold)
                yline( ...
                    axes, ...
                    double(plotData.ObjectiveThreshold), ...
                    '--', ...
                    'Color', "#D55E00", ...
                    'LineWidth', 2, ...
                    'DisplayName', 'Objective threshold');
            end

            if isfield(plotData, "LowerBound") && ...
                    isscalar(plotData.LowerBound) && ...
                    isfinite(plotData.LowerBound)
                xline( ...
                    axes, ...
                    double(plotData.LowerBound), ...
                    ':', ...
                    'Color', "#009E73", ...
                    'LineWidth', 1.5, ...
                    'DisplayName', 'CI lower bound');
            end

            if isfield(plotData, "UpperBound") && ...
                    isscalar(plotData.UpperBound) && ...
                    isfinite(plotData.UpperBound)
                xline( ...
                    axes, ...
                    double(plotData.UpperBound), ...
                    ':', ...
                    'Color', "#CC79A7", ...
                    'LineWidth', 1.5, ...
                    'DisplayName', 'CI upper bound');
            end

            legend(axes, 'show', 'Location', 'best');
            hold(axes, 'off');

        end % renderGridSearchProfile

        function renderOptimizationRSSHistogram(app, plotData)

            rss = double(plotData.RSS(:));
            rss = rss(isfinite(rss) & rss >= 0);
            threshold = double(plotData.Threshold);
            axes = app.SubUIAxes;

            if isempty(rss) || ~isscalar(threshold) || ...
                    ~isfinite(threshold) || threshold < 0
                app.resetResultAxes(axes);
                return
            end

            cla(axes);
            app.prepareCartesianResultAxes(axes);
            axes.FontSize = 16;
            axes.FontName = 'Arial';
            axes.XLabel.String = "RSS";
            axes.YLabel.String = "Frequency";
            axes.Title.String = plotData.Title;
            axes.XGrid = 'on';
            axes.YGrid = 'on';
            useLogScale = isfield(plotData, "UseLogScale") && ...
                isscalar(plotData.UseLogScale) && plotData.UseLogScale;
            rssForPlot = rss;
            histogramArguments = {'BinMethod', 'fd'};

            if useLogScale
                positiveRSS = rss(rss > 0);

                if ~isempty(positiveRSS)
                    lowerEdge = min(positiveRSS);

                    if any(rss == 0)
                        lowerEdge = max(lowerEdge / 10, realmin('double'));
                        rssForPlot(rssForPlot == 0) = lowerEdge;
                    end

                    upperEdge = max(rssForPlot);

                    if lowerEdge < upperEdge
                        [~, logBinEdges] = histcounts( ...
                            log10(rssForPlot), 'BinMethod', 'fd');
                        histogramArguments = { ...
                            'BinEdges', 10 .^ logBinEdges};
                    end

                    axes.XScale = 'log';
                end

            end

            hold(axes, 'on');
            histogram( ...
                axes, ...
                rssForPlot, ...
                histogramArguments{:}, ...
                'FaceColor', "#0072B2", ...
                'EdgeColor', 'none', ...
                'DisplayName', 'RSS');

            if strcmp(axes.XScale, 'linear') || threshold > 0
                xline( ...
                    axes, ...
                    threshold, ...
                    '-', ...
                    'Color', "#D55E00", ...
                    'LineWidth', 2, ...
                    'DisplayName', 'Threshold');
            end

            maximumValue = max([rssForPlot; threshold]);
            alpha = 0.05 * maximumValue;

            if alpha == 0
                alpha = 0.05;
            end

            upperXLimit = maximumValue + alpha;

            if strcmp(axes.XScale, 'linear')
                axes.XLim = [0, upperXLimit];
            else
                currentXLimits = axes.XLim;

                if currentXLimits(1) < upperXLimit
                    axes.XLim = [currentXLimits(1), upperXLimit];
                end

            end

            legend(axes, 'show', 'Location', 'best');
            hold(axes, 'off');

        end % renderOptimizationRSSHistogram

        function prepareCartesianResultAxes(~, axes)

            axes.Visible = 'on';
            axes.XScale = 'linear';
            axes.YScale = 'linear';
            axes.XColorMode = 'auto';
            axes.YColorMode = 'auto';
            axes.XTickMode = 'auto';
            axes.YTickMode = 'auto';
            axes.XTickLabelMode = 'auto';
            axes.YTickLabelMode = 'auto';
            axes.XLabel.Visible = 'on';
            axes.YLabel.Visible = 'on';
            axes.Title.Visible = 'on';

        end % prepareCartesianResultAxes

        function resetResultAxes(~, axes)

            legend(axes, 'off');
            cla(axes, 'reset');
            axes.Visible = 'on';

        end % resetResultAxes

        function clearResultPlots(app)

            app.resetResultAxes(app.MainUIAxes);
            app.resetResultAxes(app.SubUIAxes);

        end % method clearResultPlots

        function renderUiState(app, ui)
            % RENDERUISTATE Render the UI state
            % renderUiState(app, ui)
            %
            %  Input:
            %   ui: An object of class UiState

            if isempty(ui)
                return
            end

            if isfield(ui, "MainInteractionEnabled")

                app.applyMainInteractionEnabled(ui.MainInteractionEnabled);

                if ~ui.MainInteractionEnabled
                    return
                end

            end

            % Project panel
            % ---------------------------------------------------------------------
            if isfield(ui, "ProjectPanelEnabled")
                app.ProjectPanel.Enable = app.onOff(ui.ProjectPanelEnabled);
            end

            if isfield(ui, "ProjectBrowseEnabled")
                app.ProjectBrowseButton.Enable = app.onOff(ui.ProjectBrowseEnabled);
            end

            if isfield(ui, "ProjectDirectoryEnabled")
                app.ProjectDirectoryDropDown.Enable = ...
                    app.onOff(ui.ProjectDirectoryEnabled);
            end

            if isfield(ui, "ProjectLoadEnabled")
                app.ProjectLoadButton.Enable = app.onOff(ui.ProjectLoadEnabled);
            end

            if isfield(ui, "ProjectMetadataEditable")
                value = app.onOff(ui.ProjectMetadataEditable);
                app.ProjectNameEditField.Enable = value;
                app.ProjectAuthorEditField.Enable = value;
                app.OrganismEditField.Enable = value;
            end

            if isfield(ui, "ProjectSaveEnabled")
                app.ProjectSaveButton.Enable = app.onOff(ui.ProjectSaveEnabled);
            end

            if isfield(ui, "TemplateModelBrowseEnabled")
                app.TemplateModelBrowseButton.Enable = ...
                    app.onOff(ui.TemplateModelBrowseEnabled);
            end

            if isfield(ui, "TemplateModelDirectoryEnabled")
                app.TemplateModelDirectoryDropDown.Enable = ...
                    app.onOff(ui.TemplateModelDirectoryEnabled);
            end

            if isfield(ui, "TemplateModelLoadEnabled")
                app.TemplateModelLoadButton.Enable = ...
                    app.onOff(ui.TemplateModelLoadEnabled);
            end

            if isfield(ui, "TemplateModelSaveEnabled")
                app.TemplateModelSaveButton.Enable = ...
                    app.onOff(ui.TemplateModelSaveEnabled);
            end

            if isfield(ui, "ProjectCreateEnabled")
                app.ProjectCreateButton.Enable = ...
                    app.onOff(ui.ProjectCreateEnabled);
            end

            if isfield(ui, "DuplicateProjectEnabled")
                app.DuplicatecurrentprojectMenu.Enable = ...
                    app.onOff(ui.DuplicateProjectEnabled);
            end

            % Stoichiometry tab
            if isfield(ui, "ModelEnabled")
                value = app.onOff(ui.ModelEnabled);
                app.ModelTable.Enable = value;
                app.ModelReloadButton.Enable = value;
            end

            if isfield(ui, "ModelEditEnabled")
                app.ModelEditButton.Enable = app.onOff(ui.ModelEditEnabled);
            end

            if isfield(ui, "ModelSaveEnabled")
                app.ModelSaveButton.Enable = app.onOff(ui.ModelSaveEnabled);
            end

            if isfield(ui, "ModelTableEditable")
                app.applyTableEditable(app.ModelTable, ui.ModelTableEditable);
            end

            % MS tab
            if isfield(ui, "MsEnabled")
                value = app.onOff(ui.MsEnabled);
                app.MSTable.Enable = value;
                app.AtomTable.Enable = value;
                app.MSReloadButton.Enable = value;
            end

            if isfield(ui, "MsEditEnabled")
                app.MSEditButton.Enable = app.onOff(ui.MsEditEnabled);
            end

            if isfield(ui, "MsSaveEnabled")
                app.MSSaveButton.Enable = app.onOff(ui.MsSaveEnabled);
            end

            if isfield(ui, "MsTableEditable")
                app.applyTableEditable(app.MSTable, ui.MsTableEditable);
            end

            if isfield(ui, "AtomTableEditable")
                app.applyTableEditable(app.AtomTable, ui.AtomTableEditable);
            end

            % Experiment tab
            if isfield(ui, "ExperimentEnabled")
                value = app.onOff(ui.ExperimentEnabled);
                app.ExpTable.Enable = value;
                app.BiomassTable.Enable = value;
                app.ExpCalculationButton.Enable = value;
                app.ExpImportButton.Enable = value;
                app.ExpReloadButton.Enable = value;
                app.ExpSaveButton.Enable = value;

                app.applyContextMenu( ...
                    app.ExpTable, ...
                    app.ExperimentContextMenu, ...
                    ui.ExperimentEnabled);
            end

            if isfield(ui, "ExperimentTableEditable")
                app.applyTableEditable(app.ExpTable, ui.ExperimentTableEditable);
            end

            if isfield(ui, "BiomassTableEditable")
                app.applyTableEditable(app.BiomassTable, ui.BiomassTableEditable);
            end

            % Tracer tab
            if isfield(ui, "TracerEnabled")
                value = app.onOff(ui.TracerEnabled);
                app.UptakeTable.Enable = value;
                app.LabelTable.Enable = value;
                app.TracerConfigButton.Enable = value;
                app.TracerReloadButton.Enable = value;
                app.TracerSaveButton.Enable = value;

                app.applyContextMenu( ...
                    app.LabelTable, ...
                    app.ContextMenu3, ...
                    ui.TracerEnabled);
            end

            if isfield(ui, "TracerTableEditable")
                app.applyTableEditable(app.LabelTable, ui.TracerTableEditable);
            end

            if isfield(ui, "UptakeTableEditable")
                app.applyTableEditable(app.UptakeTable, ui.UptakeTableEditable);
            end

            % Run tab
            if isfield(ui, "RunConfigurationEnabled")
                value = app.onOff(ui.RunConfigurationEnabled);
                app.RunAutoButton.Enable = value;
                app.RunConfigButton.Enable = value;
                app.RunReloadButton.Enable = value;
                app.RunSaveButton.Enable = value;
            end

            if isfield(ui, "RunTableEnabled")
                app.RunTable.Enable = app.onOff(ui.RunTableEnabled);
            end

            if isfield(ui, "RunContextMenuEnabled")
                app.applyContextMenu( ...
                    app.RunTable, ...
                    app.ContextMenuRun, ...
                    ui.RunContextMenuEnabled);

            elseif isfield(ui, "RunTableEnabled")
                app.applyContextMenu( ...
                    app.RunTable, ...
                    app.ContextMenuRun, ...
                    ui.RunTableEnabled);
            end

            if isfield(ui, "RunTableEditable")

                if ui.RunTableEditable
                    app.restoreRunTableEditable();
                else
                    app.rememberRunTableEditable();
                    app.applyTableEditable(app.RunTable, false);
                end

            end

            if isfield(ui, "RunButtonEnabled")
                app.RunRunButton.Enable = app.onOff(ui.RunButtonEnabled);
            end

            if isfield(ui, "RunButtonText")
                app.RunRunButton.Text = char(ui.RunButtonText);
            end

            % Result tab
            if isfield(ui, "ResultEnabled")
                value = app.onOff(ui.ResultEnabled);
                app.ResultDropDown.Enable = value;
                app.ResultMainTable.Enable = value;
                app.ResultSubTable.Enable = value;
                app.ResultReportButton.Enable = value;
                app.ResultReloadButton.Enable = value;
                app.ResultSaveButton.Enable = value;

                app.applyContextMenu( ...
                    app.ResultMainTable, ...
                    app.ContextMenu2, ...
                    ui.ResultEnabled);

                app.applyContextMenu( ...
                    app.ResultSubTable, ...
                    app.ContextMenuResultSelect, ...
                    ui.ResultEnabled);
            end

            if isfield(ui, "ResultMainTableEditable")
                app.applyTableEditable(app.ResultMainTable, ...
                    ui.ResultMainTableEditable);
            end

            if isfield(ui, "ResultSubTableEditable")
                app.applyTableEditable(app.ResultSubTable, ...
                    ui.ResultSubTableEditable);
            end

            % Menu
            if isfield(ui, "MenuEnabled")
                value = app.onOff(ui.MenuEnabled);
                app.FilesMenu.Enable = value;
                app.ModelMenu.Enable = value;
                app.BatchMenu.Enable = value;
                app.ViewMenu.Enable = value;
            end

            % Pathway context menu
            if isfield(ui, "PathwayContextMenuEnabled")
                app.applyContextMenu( ...
                    app.MainUIAxes, ...
                    app.ContextMenu, ...
                    ui.PathwayContextMenuEnabled);
            end

        end % method renderUiState

        function value = onOff(~, enabled)

            if enabled
                value = 'on';
            else
                value = 'off';
            end

        end

        function applyContextMenu(~, component, contextMenu, enabled)

            if enabled
                component.ContextMenu = contextMenu;
            else
                component.ContextMenu = [];
            end

        end

        function applyTableEditable(~, tableObject, editable)
            % APPLYTABLEEDITABLE
            % Applies a scalar or vector editable flag to a UITable.

            if isempty(tableObject)
                return
            end

            if isempty(tableObject.Data)
                tableObject.ColumnEditable = false;
                return
            end

            if isscalar(editable)
                n = 1;

                try

                    if istable(tableObject.Data)
                        n = width(tableObject.Data);
                    else
                        n = size(tableObject.Data, 2);
                    end

                catch
                    n = 1;
                end

                tableObject.ColumnEditable = repmat(logical(editable), 1, n);
                return
            end

            tableObject.ColumnEditable = logical(editable);
        end

        function initializePresentation(app)

            app.requireApplicationDependency( ...
                app.Presenter, "MainPresenter");

            app.refreshPresentation();

        end % method initializePresentation

        function refreshPresentation(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.refresh(context);
            app.renderMainViewModel(viewModel);

        end % method refreshPresentation

        function cleanup = beginPresentationOperation(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.beginOperation(context);
            app.renderMainViewModel(viewModel);

            cleanup = onCleanup(@() app.finishPresentationOperation());

        end % method beginPresentationOperation

        function finishPresentationOperation(app)

            if isempty(app.Presenter)
                return
            end

            context = app.capturePresentationContext();
            viewModel = app.Presenter.finishOperation(context);
            app.renderMainViewModel(viewModel);

        end % method finishPresentationOperation

        function beginPresentationEditCommit(app)

            context = app.capturePresentationContext();

            viewModel = app.Presenter.beginEditCommit(context);

            app.renderMainViewModel(viewModel);

        end

        function finishPresentationEditCommit(app, success)

            arguments
                app
                success (1, 1) logical
            end

            context = app.capturePresentationContext();

            viewModel = app.Presenter.finishEditCommit(context, success);

            app.renderMainViewModel(viewModel);

        end

        function resetPresentation(app)

            app.requireApplicationDependency( ...
                app.Presenter, "MainPresenter");

            context = app.capturePresentationContext();

            viewModel = app.Presenter.reset(context);

            app.renderMainViewModel(viewModel);

        end

        function beginPresentationRun(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.beginRun(context);
            app.renderMainViewModel(viewModel);
            drawnow;

        end % method beginPresentationRun

        function finishPresentationRun(app)

            if isempty(app.Presenter)
                return
            end

            context = app.capturePresentationContext();
            viewModel = app.Presenter.finishRun(context);
            app.renderMainViewModel(viewModel);

        end % method finishPresentationRun

        function finishPresentationRunSafely(app)

            try

                if isempty(app.Presenter)
                    return
                end

                context = app.capturePresentationContext();
                viewModel = app.Presenter.finishRun(context);
                app.renderMainViewModel(viewModel);
                drawnow;

            catch ME

                try
                    app.publishMessage( ...
                        "error", ...
                        "Failed to restore run UI state: " + string(ME.message), ...
                        DiagnosticText = string(ME.message));
                catch
                end

            end

        end % method finishPresentationRunSafely

        function requestPresentationCancelRun(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.requestCancelRun(context);
            app.renderMainViewModel(viewModel);
            drawnow;

        end % method requestPresentationCancelRun

        function beginPresentationEdit(app, target)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.beginEdit(target, context);
            app.renderMainViewModel(viewModel);

        end % method beginPresentationEdit

        function finishPresentationEdit(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.finishEdit(context);
            app.renderMainViewModel(viewModel);

        end % method finishPresentationEdit

        function rememberRunTableEditable(app)

            if isempty(app.RunTable)
                return
            end

            if isempty(app.RunTable.Data)
                return
            end

            if ~isempty(app.RunTableEditable)
                return
            end

            try
                current = app.RunTable.ColumnEditable;

                if isempty(current)
                    return
                end

                app.RunTableEditable = logical(current);

            catch
                % Do not fail rendering due to transient UITable state.
            end

        end % method rememberRunTableEditable

        function restoreRunTableEditable(app)

            try

                if isempty(app.RunTableEditable)
                    return
                end

                app.RunTable.ColumnEditable = app.RunTableEditable;
                app.RunTableEditable = [];

            catch
                % If the table shape has changed, keep current ColumnEditable.
            end

        end % method restoreRunTableEditable

        function context = capturePresentationContext(app)

            context = struct();

            % ---------------------------------------------------------------------
            % Project paths
            % ---------------------------------------------------------------------

            context.ProjectDirectory = ...
                app.safeStringScalar(app.ProjectDirectoryDropDown.Value);

            context.TemplateModelDirectory = ...
                app.safeStringScalar(app.TemplateModelDirectoryDropDown.Value);

            context.DirectoryModel = app.safeStringScalar(app.directoryModel);
            context.DirectoryExp = app.safeStringScalar(app.directoryExp);
            context.DirectoryResult = app.safeStringScalar(app.directoryResult);

            context.ProjectDirectoryExists = ...
                context.ProjectDirectory ~= "" && isfolder(context.ProjectDirectory);

            context.TemplateModelDirectoryExists = ...
                context.TemplateModelDirectory ~= "" && ...
                isfolder(context.TemplateModelDirectory);

            context.DirectoryModelExists = ...
                context.DirectoryModel ~= "" && isfolder(context.DirectoryModel);

            context.DirectoryExpExists = ...
                context.DirectoryExp ~= "" && isfolder(context.DirectoryExp);

            context.DirectoryResultExists = ...
                context.DirectoryResult ~= "" && isfolder(context.DirectoryResult);

            % ---------------------------------------------------------------------
            % Project metadata fields
            % ---------------------------------------------------------------------
            context.ProjectName = ...
                app.safeStringScalar(app.ProjectNameEditField.Value);

            context.ProjectAuthor = ...
                app.safeStringScalar(app.ProjectAuthorEditField.Value);

            context.Organism = ...
                app.safeStringScalar(app.OrganismEditField.Value);

            context.HasProjectMetadata = ...
                context.ProjectName ~= "" || ...
                context.ProjectAuthor ~= "" || ...
                context.Organism ~= "";

            % ---------------------------------------------------------------------
            % Loaded application objects
            % ---------------------------------------------------------------------
            model = app.ApplicationController.model();
            experiments = app.ApplicationController.experiments();
            batch = app.ApplicationController.batch();
            result = app.ApplicationController.result();
            context.HasModelObject = app.isLoadedObject(model);
            context.HasExperimentObject = app.isLoadedObject(experiments);
            context.HasBatchObject = app.isLoadedObject(batch);
            context.HasResultObject = app.isLoadedObject(result);

            context.HasModelError = app.objectHasError(model);
            context.HasExperimentError = app.objectHasError(experiments);
            context.HasBatchError = app.objectHasError(batch);
            context.HasResultError = app.objectHasError(result);

            context.HasModel = ...
                context.HasModelObject && ~context.HasModelError;

            context.HasExperiments = ...
                context.HasExperimentObject && ~context.HasExperimentError;

            context.HasBatches = ...
                context.HasBatchObject && ~context.HasBatchError;

            % The Result tab remains useful before result rows are displayed.
            context.HasResults = ...
                context.HasResultObject && ~context.HasResultError;

            % A project is considered fully loaded only when the project directories
            % and the four major application objects are available.
            context.HasProject = ...
                context.ProjectDirectoryExists && ...
                context.DirectoryModelExists && ...
                context.DirectoryExpExists && ...
                context.DirectoryResultExists && ...
                context.HasModel && ...
                context.HasExperiments && ...
                context.HasBatches && ...
                context.HasResults;

            % Template mode is used after loading a template model but before
            % creating a full project.
            context.IsTemplateMode = ...
                ~context.HasProject && ...
                context.TemplateModelDirectoryExists && ...
                context.HasModel;

            context.CanCreateProjectFromTemplate = context.IsTemplateMode;

            % ---------------------------------------------------------------------
            % Table contents
            % ---------------------------------------------------------------------
            context.ModelTableRowCount = app.tableRowCount(app.ModelTable.Data);
            context.MSTableRowCount = app.tableRowCount(app.MSTable.Data);
            context.AtomTableRowCount = app.tableRowCount(app.AtomTable.Data);
            context.ExpTableRowCount = app.tableRowCount(app.ExpTable.Data);
            context.BiomassTableRowCount = app.tableRowCount(app.BiomassTable.Data);
            context.UptakeTableRowCount = app.tableRowCount(app.UptakeTable.Data);
            context.LabelTableRowCount = app.tableRowCount(app.LabelTable.Data);
            context.RunTableRowCount = app.tableRowCount(app.RunTable.Data);
            context.ResultSubTableRowCount = app.tableRowCount(app.ResultSubTable.Data);
            context.ResultMainTableRowCount = app.tableRowCount(app.ResultMainTable.Data);

            context.HasModelRows = context.ModelTableRowCount > 0;
            context.HasMSRows = context.MSTableRowCount > 0;
            context.HasExperimentRows = context.ExpTableRowCount > 0;
            context.HasTracerRows = context.LabelTableRowCount > 0 || ...
                context.UptakeTableRowCount > 0;
            context.HasBatchRows = context.RunTableRowCount > 0;
            context.HasResultRows = context.ResultSubTableRowCount > 0;

            % For the first Presenter / UiPolicy step, this should approximate the
            % current GUI behavior without changing domain logic.
            context.CanRun = ...
                context.HasProject && ...
                context.HasModel && ...
                context.HasExperiments && ...
                context.HasBatches && ...
                context.HasBatchRows && ...
                context.DirectoryResultExists;

            % ---------------------------------------------------------------------
            % Current edit states inferred from existing UI
            % ---------------------------------------------------------------------
            context.IsModelEditing = ...
                app.isEnabled(app.ModelSaveButton) && ...
                app.hasEditableColumn(app.ModelTable.ColumnEditable);

            context.IsMSEditing = ...
                app.isEnabled(app.MSSaveButton) && ...
                (app.hasEditableColumn(app.MSTable.ColumnEditable) || ...
                app.hasEditableColumn(app.AtomTable.ColumnEditable));

            % Current implementation has no explicit Experiment / Tracer edit mode.
            % Their tables are edited directly when the tab is unlocked.
            context.IsExperimentEditing = false;
            context.IsTracerEditing = false;

            context.IsAnyTableEditing = ...
                context.IsModelEditing || ...
                context.IsMSEditing || ...
                context.IsExperimentEditing || ...
                context.IsTracerEditing;

            % ---------------------------------------------------------------------
            % Running / child-window states
            % ---------------------------------------------------------------------
            context.IsBatchRunning = ...
                app.safeStringScalar(app.RunRunButton.Text) == "Cancel";

            context.IsProjectPanelLocked = ...
                ~app.isEnabled(app.ProjectPanel);

            context.HasOpenChildApp = any([
                                           app.isLoadedObject(app.LabelConfigApp)
                                           app.isLoadedObject(app.TracerConfigApp)
                                           app.isLoadedObject(app.RunConfigApp)
                                           app.isLoadedObject(app.MSViewApp)
                                           app.isLoadedObject(app.ComparisonViewApp)
                                           app.isLoadedObject(app.ViewComparisonApp)
                                           app.isLoadedObject(app.RunAddBatchApp)
                                           app.isLoadedObject(app.ViewSuggestionApp)
                                           app.isLoadedObject(app.LogApp)
                                           ]);

            context.HasProgressBar = app.isLoadedObject(app.ProgressBar);

            % ---------------------------------------------------------------------
            % Current selections
            % ---------------------------------------------------------------------
            context.SelectedRunRows = app.selectedRows(app.RunTable);
            context.SelectedResultRows = app.selectedRows(app.ResultSubTable);
            context.SelectedResultDetailRows = app.selectedRows(app.ResultMainTable);
            context.SelectedModelRows = app.selectedRows(app.ModelTable);
            context.SelectedExperimentRows = app.selectedRows(app.ExpTable);
            context.SelectedTracerRows = app.selectedRows(app.LabelTable);

            context.HasSelectedRunRows = ~isempty(context.SelectedRunRows);
            context.HasSelectedResultRows = ~isempty(context.SelectedResultRows);
            context.HasSelectedModelRows = ~isempty(context.SelectedModelRows);
            context.HasSelectedExperimentRows = ~isempty(context.SelectedExperimentRows);
            context.HasSelectedTracerRows = ~isempty(context.SelectedTracerRows);

            % ---------------------------------------------------------------------
            % Current view mode
            % ---------------------------------------------------------------------
            context.CurrentTab = "";

            try

                if ~isempty(app.TabGroup.SelectedTab)
                    context.CurrentTab = ...
                        app.safeStringScalar(app.TabGroup.SelectedTab.Title);
                end

            catch
                context.CurrentTab = "";
            end

            context.ResultMode = ...
                app.safeStringScalar(app.ResultDropDown.Value);

            if context.ResultMode == ""
                context.ResultMode = "Overview";
            end

            context.TypeSimulation = ...
                app.safeStringScalar(app.typeSimulation);

            if context.TypeSimulation == ""
                context.TypeSimulation = "Flux";
            end

        end % method capturePresentationContext

        function tf = isLoadedObject(app, value)
            % ISLOADEDOBJECT
            % True when the value exists and, if it is a handle object, is valid.
            % This method intentionally does not inspect UI components.

            tf = false;

            if isempty(value)
                return
            end

            try

                if isobject(value)

                    try

                        if any(~isvalid(value), "all")
                            return
                        end

                    catch
                        % Some value objects do not support isvalid.
                        % In that case, non-empty is treated as loaded.
                    end

                end

                if app.objectHasError(value)
                    return
                end

                tf = true;

            catch
                tf = false;
            end

        end % function isLoadedObject

        function tf = objectHasError(~, value)
            % OBJECTHASERROR
            % Checks common legacy isError property without throwing.

            tf = false;

            if isempty(value)
                return
            end

            try

                if isobject(value) && isprop(value, "isError")
                    raw = value.isError;

                    if isempty(raw)
                        tf = false;
                    elseif islogical(raw)
                        tf = any(raw(:));
                    elseif isnumeric(raw)
                        tf = any(logical(raw(:)));
                    elseif isstring(raw) || ischar(raw)
                        raw = string(raw);
                        tf = any(raw == "true" | raw == "1" | raw == "error");
                    else
                        tf = false;
                    end

                end

            catch
                % If error state cannot be inspected, do not mark it as error.
                tf = false;
            end

        end % function objectHasError

        function n = tableRowCount(~, data)
            % TABLEROWCOUNT
            % Returns row count for table, cell, numeric array, string array, etc.

            n = 0;

            if isempty(data)
                return
            end

            try

                if istable(data)
                    n = height(data);
                else
                    n = size(data, 1);
                end

            catch
                n = 0;
            end

        end % function tableRowCount

        function n = tableWidthCount(~, data)
            % TABLEWIDTHCOUNT
            % Returns column count for table-like data.

            n = 0;

            if isempty(data)
                return
            end

            try

                if istable(data)
                    n = width(data);
                else
                    n = size(data, 2);
                end

            catch
                n = 0;
            end

        end % function tableWidthCount

        function tf = isEnabled(~, componentOrValue)
            % ISENABLED
            % Accepts either a UI component with Enable property or the Enable value.

            tf = false;

            try

                if ischar(componentOrValue) || isstring(componentOrValue)
                    value = string(componentOrValue);
                else
                    value = string(componentOrValue.Enable);
                end

                if isempty(value)
                    return
                end

                value = lower(value(1));

                tf = value == "on" || value == "true" || value == "1";

            catch
                tf = false;
            end

        end % function isEnabled

        function tf = hasEditableColumn(~, columnEditable)
            % HASEDITABLECOLUMN
            % True if any column is editable.

            tf = false;

            if isempty(columnEditable)
                return
            end

            try
                tf = any(logical(columnEditable(:)));
            catch
                tf = false;
            end

        end % function hasEditableColumn

        function rows = selectedRows(~, tableObject)
            % SELECTEDROWS
            % Extracts unique selected row indices from a UITable.

            rows = zeros(0, 1);

            try
                selection = tableObject.Selection;

                if isempty(selection)
                    return
                end

                if size(selection, 2) >= 1
                    rows = unique(selection(:, 1));
                    rows = rows(:);
                end

            catch
                rows = zeros(0, 1);
            end

        end % function selectedRows

        function value = safeStringScalar(~, raw)
            % SAFESTRINGSCALAR
            % Converts raw UI or property value to a non-missing scalar string.

            value = "";

            if isempty(raw)
                return
            end

            try
                value = string(raw);

                if isempty(value)
                    value = "";
                    return
                end

                value = value(1);

                if ismissing(value)
                    value = "";
                end

            catch
                value = "";
            end

        end % function safeStringScalar

        %% Notification publishing and rendering
        function publishNotification(app, notification)
            % Compatibility adapter for typed presentation notifications.

            if isempty(notification)
                return
            end

            if iscell(notification)

                for i = 1:numel(notification)
                    app.publishNotification(notification{i});
                end

                return
            end

            if numel(notification) > 1

                for i = 1:numel(notification)
                    app.publishNotification(notification(i));
                end

                return
            end

            if isa(notification, ...
                "openmebius.core.notification.Message")

                message = notification;

            elseif isa(notification, ...
                "openmebius.presentation.notification.Notification")

                message = notification.toMessage();

            else

                message = openmebius.core.notification.Message( ...
                    string(notification), ...
                    "info", ...
                    Code = "presentation.notification", ...
                    Source = "OpenMebius2");

            end

            app.NotificationDispatcher.publish(message);

        end % method publishNotification

        function publishMessage(app, level, text, options)

            arguments
                app
                level (1, 1) string
                text (1, 1) string
                options.Code (1, 1) string = "ui.message"
                options.Title (1, 1) string = ""
                options.Alert (1, 1) logical = false
                options.DiagnosticText (1, 1) string = ""
            end

            attention = "passive";

            if options.Alert
                attention = "action-required";
            end

            app.NotificationDispatcher.publish( ...
                openmebius.core.notification.Message( ...
                text, ...
                level, ...
                Code = options.Code, ...
                Title = options.Title, ...
                DiagnosticText = options.DiagnosticText, ...
                Source = "OpenMebius2", ...
                Attention = attention));

        end % method publishMessage

        function publishException(app, exception, options)

            arguments
                app
                exception
                options.Title (1, 1) string = "Error"
                options.Alert (1, 1) logical = false
            end

            diagnosticText = "";

            try
                diagnosticText = string(getReport( ...
                    exception, "extended", "hyperlinks", "off"));
            catch
                diagnosticText = string(exception.message);
            end

            app.publishMessage( ...
                "error", ...
                string(exception.message), ...
                Code = "ui.exception", ...
                Title = options.Title, ...
                Alert = options.Alert, ...
                DiagnosticText = diagnosticText);

        end % method publishException

        function renderLogNotification(app, message)

            text = openmebius.infrastructure.logging.Logger ...
                .formatDatedLines( ...
                message.Text, ...
                message.Level, ...
                Timestamp = message.Timestamp);
            values = [app.LogTextArea.Value; text(:)];
            maximumLines = 5000;

            if numel(values) > maximumLines
                values = values(end - maximumLines + 1:end);
            end

            app.LogTextArea.Value = values;
            scroll(app.LogTextArea, "bottom");
            drawnow limitrate

        end % method renderLogNotification

        function renderAlertNotification(app, message)

            title = message.Title;

            if title == ""
                title = openmebius.presentation.notification.Notification ...
                    .defaultTitle(message.Level);
            end

            notification = openmebius.presentation.notification ...
                .Notification( ...
                message.Text, ...
                message.Level, ...
                Title = title, ...
                Timestamp = message.Timestamp, ...
                ShowAlert = true);

            uialert( ...
                app.OpenMebius2UIFigure, ...
                char(notification.Message), ...
                char(notification.Title), ...
                "Icon", char(notification.alertIcon()), ...
                "Interpreter", "none");

        end % method renderAlertNotification

        function configureNotificationSinks(app)

            app.NotificationDispatcher.addSink( ...
                openmebius.presentation.notification.UiLogSink( ...
                @(message) app.renderLogNotification(message)));
            app.NotificationDispatcher.addSink( ...
                openmebius.presentation.notification.UiAlertSink( ...
                @(message) app.renderAlertNotification(message)));

        end % method configureNotificationSinks

        function openMSComparison(app)

            presenter = openmebius.presentation.experiment ...
                .ComparisonViewPresenter( ...
                app.ApplicationController.experiments());
            catalogViewModel = presenter.presentCatalog();

            for notificationIndex = 1:numel( ...
                    catalogViewModel.Notifications)
                app.publishNotification( ...
                    catalogViewModel.Notifications{notificationIndex});
            end

            if ~catalogViewModel.IsAvailable
                return
            end

            app.closeComparisonViewApp();
            context = openmebius.presentation.experiment ...
                .ComparisonViewContext( ...
                Presenter = presenter, ...
                InitialCatalog = catalogViewModel);
            app.ComparisonViewApp = ComparisonView(context);
            app.attachComparisonViewListeners(app.ComparisonViewApp);

        end % method openMSComparison

        function showModelValidationReport(app, validationReport)

            arguments
                app
                validationReport (1, 1) openmebius.domain.model ...
                    .ModelValidationReport
            end

            for i = 1:numel(validationReport.Warnings)
                app.publishMessage( ...
                    "warning", validationReport.Warnings(i));
            end

            if ~validationReport.IsValid
                app.publishMessage( ...
                    "error", validationReport.ErrorMessage);
                return
            end

            for i = 1:numel(validationReport.Messages)
                app.publishMessage("info", validationReport.Messages(i));
            end

        end % showModelValidationReport

        function requireApplicationDependency(~, dependency, name)

            arguments
                ~
                dependency
                name (1, 1) string
            end

            isInvalidHandle = isa(dependency, "handle") && ...
                ~isvalid(dependency);

            if isempty(dependency) || isInvalidHandle
                error( ...
                    "OpenMebius2:Composition:MissingDependency", ...
                    "%s was not provided by MainAppCompositionRoot.", ...
                    name);
            end

        end % requireApplicationDependency

        function attachPreferencesListeners(app, preferencesApp)

            if isempty(preferencesApp) || ~isvalid(preferencesApp)
                return
            end

            app.ChildAppHost.attach( ...
                "Preferences", ...
                preferencesApp, ...
                {"PreferencesClosed", ...
                 @(src, event) app.onPreferencesClosed(src, event)});

        end % method attachPreferencesListeners

        function detachPreferencesListeners(app)

            app.ChildAppHost.detach("Preferences");

        end % method detachPreferencesListeners

        function attachRunConfigListeners(app, runConfigApp)

            app.ChildAppHost.attach( ...
                "RunConfig", ...
                runConfigApp, ...
                {"Applied", ...
                 @(source, event) app.onRunConfigurationApplied(source, event); ...
                 "BatchExperimentSelectionApplied", ...
                 @(source, event) app.onBatchExperimentSelectionApplied(source, event); ...
                 "NotificationRequested", ...
                 @(source, event) app.onNotificationRequested(source, event); ...
                 "Closed", ...
                 @(source, event) app.onRunConfigurationClosed(source, event)});

        end % attachRunConfigListeners

        function detachRunConfigListeners(app)

            app.ChildAppHost.detach("RunConfig");

        end % detachRunConfigListeners

        function closeRunConfigApp(app)

            app.ChildAppHost.close("RunConfig");
            app.RunConfigApp = [];

        end % closeRunConfigApp

        function attachLabelConfigListeners(app, labelConfigApp)

            app.ChildAppHost.attach( ...
                "LabelConfig", ...
                labelConfigApp, ...
                {"Applied", ...
                 @(source, event) app.onLabelConfigurationApplied(source, event); ...
                 "NotificationRequested", ...
                 @(source, event) app.onNotificationRequested(source, event); ...
                 "Closed", ...
                 @(source, event) app.onLabelConfigurationClosed(source, event)});

        end % attachLabelConfigListeners

        function detachLabelConfigListeners(app)

            app.ChildAppHost.detach("LabelConfig");

        end % detachLabelConfigListeners

        function closeLabelConfigApp(app)

            app.ChildAppHost.close("LabelConfig");
            app.LabelConfigApp = [];

        end % closeLabelConfigApp

        function attachTracerConfigListeners(app, tracerConfigApp)

            app.ChildAppHost.attach( ...
                "TracerConfig", ...
                tracerConfigApp, ...
                {"Applied", ...
                 @(source, event) app.onTracerConfigurationApplied(source, event); ...
                 "Closed", ...
                 @(source, event) app.onTracerConfigurationClosed(source, event)});

        end % attachTracerConfigListeners

        function detachTracerConfigListeners(app)

            app.ChildAppHost.detach("TracerConfig");

        end % detachTracerConfigListeners

        function closeTracerConfigApp(app)

            app.ChildAppHost.close("TracerConfig");
            app.TracerConfigApp = [];

        end % closeTracerConfigApp

        function attachMSViewListeners(app, msViewApp)

            app.ChildAppHost.attach( ...
                "MSView", ...
                msViewApp, ...
                {"ComparisonRequested", @(~, ~) app.openMSComparison(); ...
                 "NotificationRequested", ...
                 @(source, event) app.onNotificationRequested(source, event); ...
                 "Closed", ...
                 @(source, event) app.onMSViewClosed(source, event)});

        end % attachMSViewListeners

        function detachMSViewListeners(app)

            app.ChildAppHost.detach("MSView");

        end % detachMSViewListeners

        function closeMSViewApp(app)

            app.ChildAppHost.close("MSView");
            app.MSViewApp = [];

        end % closeMSViewApp

        function attachComparisonViewListeners(app, comparisonViewApp)

            app.ChildAppHost.attach( ...
                "ComparisonView", ...
                comparisonViewApp, ...
                {"NotificationRequested", ...
                 @(source, event) app.onNotificationRequested(source, event); ...
                 "Closed", ...
                 @(source, event) app.onComparisonViewClosed(source, event)});

        end % attachComparisonViewListeners

        function detachComparisonViewListeners(app)

            app.ChildAppHost.detach("ComparisonView");

        end % detachComparisonViewListeners

        function closeComparisonViewApp(app)

            app.ChildAppHost.close("ComparisonView");
            app.ComparisonViewApp = [];

        end % closeComparisonViewApp

        function attachViewComparisonListeners(app, viewComparisonApp)

            app.ChildAppHost.attach( ...
                "ViewComparison", ...
                viewComparisonApp, ...
                {"NotificationRequested", ...
                 @(source, event) app.onNotificationRequested(source, event); ...
                 "Closed", ...
                 @(source, event) app.onViewComparisonClosed(source, event)});

        end % attachViewComparisonListeners

        function detachViewComparisonListeners(app)

            app.ChildAppHost.detach("ViewComparison");

        end % detachViewComparisonListeners

        function closeViewComparisonApp(app)

            app.ChildAppHost.close("ViewComparison");
            app.ViewComparisonApp = [];

        end % closeViewComparisonApp

        function attachRunAddBatchListeners(app, runAddBatchApp)

            app.ChildAppHost.attach( ...
                "RunAddBatch", ...
                runAddBatchApp, ...
                {"Applied", ...
                 @(source, event) app.onBatchExperimentSelectionApplied(source, event); ...
                 "Closed", ...
                 @(source, event) app.onRunAddBatchClosed(source, event)});

        end % attachRunAddBatchListeners

        function detachRunAddBatchListeners(app)

            app.ChildAppHost.detach("RunAddBatch");

        end % detachRunAddBatchListeners

        function onBatchExperimentSelectionApplied(app, ~, event)

            [outcome, batch] = app.ApplicationController ...
                .applyBatchExperimentSelection(event.Selection);
            app.renderBatchOperationViewModel( ...
                app.BatchPresenter ...
                .presentExperimentSelectionOutcome( ...
                outcome, batch));

        end % onBatchExperimentSelectionApplied

        function onRunAddBatchClosed(app, ~, ~)

            app.detachRunAddBatchListeners();
            app.RunAddBatchApp = [];

        end % onRunAddBatchClosed

        function closeRunAddBatchApp(app)

            app.ChildAppHost.close("RunAddBatch");
            app.RunAddBatchApp = [];

        end % closeRunAddBatchApp

        function onRunConfigurationApplied(app, ~, ~)

            app.updateBatchTable();

        end % onRunConfigurationApplied

        function onRunConfigurationClosed(app, ~, ~)

            app.detachRunConfigListeners();
            app.RunConfigApp = [];
            app.refreshPresentation();

        end % onRunConfigurationClosed

        function onLabelConfigurationApplied(app, ~, event)

            outcome = app.ApplicationController ...
                .applyLabelConfiguration( ...
                event.LabelTable, event.RatioTables);
            app.renderModelOperationViewModel( ...
                app.ModelPresenter ...
                .presentLabelConfigurationOutcome(outcome));

        end % onLabelConfigurationApplied

        function onNotificationRequested(app, ~, event)

            app.publishNotification(event.Notification);

        end % onNotificationRequested

        function onLabelConfigurationClosed(app, ~, ~)

            app.detachLabelConfigListeners();
            app.LabelConfigApp = [];
            app.refreshPresentation();

        end % onLabelConfigurationClosed

        function renderTracerConfigurationViewModel(app, viewModel)

            app.renderTracerConfigurationNotifications(viewModel);

            if ~viewModel.IsSuccessful
                return
            end

            app.closeTracerConfigApp();
            context = openmebius.presentation.experiment ...
                .TracerConfigContext( ...
                EditorTable = viewModel.EditorTable, ...
                Position = viewModel.Position);
            app.TracerConfigApp = TracerConfig(context);
            app.attachTracerConfigListeners(app.TracerConfigApp);

        end % renderTracerConfigurationViewModel

        function onTracerConfigurationApplied(app, ~, event)

            outcome = app.ApplicationController ...
                .applyTracerConfiguration( ...
                event.Position, event.EditorTable);
            viewModel = app.ExperimentPresenter ...
                .presentTracerConfigurationApplyOutcome(outcome);
            app.renderTracerConfigurationNotifications(viewModel);

            if viewModel.IsSuccessful
                position = viewModel.Position;
                app.LabelTable.Data{position(1), position(2)} = ...
                    {char(viewModel.Pattern)};
            end

        end % onTracerConfigurationApplied

        function onTracerConfigurationClosed(app, ~, ~)

            app.detachTracerConfigListeners();
            app.TracerConfigApp = [];
            app.refreshPresentation();

        end % onTracerConfigurationClosed

        function onMSViewClosed(app, ~, ~)

            app.detachMSViewListeners();
            app.MSViewApp = [];
            app.refreshPresentation();

        end % onMSViewClosed

        function onComparisonViewClosed(app, ~, ~)

            app.detachComparisonViewListeners();
            app.ComparisonViewApp = [];
            app.refreshPresentation();

        end % onComparisonViewClosed

        function onViewComparisonClosed(app, ~, ~)

            app.detachViewComparisonListeners();
            app.ViewComparisonApp = [];
            app.refreshPresentation();

        end % onViewComparisonClosed

        function renderTracerConfigurationNotifications(app, viewModel)

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

        end % renderTracerConfigurationNotifications

        %% Apply Session
        function applyMainInteractionEnabled(app, enabled)

            arguments
                app
                enabled (1, 1) logical
            end

            if enabled
                app.restoreMainInteraction();
            else
                app.lockMainInteraction();
            end

        end % method applyMainInteractionEnabled

        function lockMainInteraction(app)

            if ~isempty(app.MainInteractionSnapshot)
                return
            end

            app.MainInteractionSnapshot = {};

            objects = findall(app.OpenMebius2UIFigure);

            for i = 1:numel(objects)

                obj = objects(i);

                try

                    if isprop(obj, "Enable")

                        app.MainInteractionSnapshot(end + 1, :) = { ...
                                                                       obj, ...
                                                                       obj.Enable}; %#ok<AGROW>

                        obj.Enable = 'off';

                    end

                catch
                    % Ignore components that cannot be locked.
                end

            end

            drawnow limitrate

        end % method lockMainInteraction

        function restoreMainInteraction(app)

            if isempty(app.MainInteractionSnapshot)
                return
            end

            snapshot = app.MainInteractionSnapshot;
            app.MainInteractionSnapshot = {};

            for i = 1:size(snapshot, 1)

                obj = snapshot{i, 1};
                value = snapshot{i, 2};

                try

                    if isvalid(obj) && isprop(obj, "Enable")
                        obj.Enable = value;
                    end

                catch
                    % Ignore stale UI handles.
                end

            end

            drawnow limitrate

        end % method restoreMainInteraction

        function applyProjectSession(app, session)

            arguments
                app
                session openmebius.domain.project.ProjectSession
            end

            app.ProjectDirectoryDropDown.Value = ...
                session.Paths.RootDirectory;

            app.ProjectNameEditField.Value = ...
                session.Metadata.Name;

            app.ProjectAuthorEditField.Value = ...
                session.Metadata.Author;

            app.OrganismEditField.Value = ...
                session.Metadata.Organism;

            app.directoryModel = ...
                session.Paths.ModelDirectory;

            app.directoryExp = ...
                session.Paths.ExperimentDirectory;

            app.directoryResult = ...
                session.Paths.ResultDirectory;

        end % method applyProjectSession

        function addProjectDirectoryHistoryItem(app, projectDirectory)

            projectDirectory = string(projectDirectory);

            if projectDirectory == ""
                return
            end

            items = string(app.ProjectDirectoryDropDown.Items);

            if ~any(items == projectDirectory)
                items(end + 1) = projectDirectory;
                app.ProjectDirectoryDropDown.Items = items;
            end

        end % addProjectDirectoryHistoryItem

        function projectDirectory = resolveProjectOpenInput(app, projectInput)

            arguments
                app
                projectInput (1, 1) string
            end

            projectDirectory = ...
                openmebius.infrastructure.project.FileProjectRepository ...
                .resolveProjectDirectory(projectInput);

        end % method resolveProjectOpenInput

        function projectInput = normalizeStartupProjectInput(~, projectInput)
            % NORMALIZESTARTUPPROJECTINPUT
            % Normalizes optional startup argument.
            %
            % Accepts:
            %   ""
            %   project directory
            %   setting.om2
            %   setting.json

            if nargin < 2 || isempty(projectInput)
                projectInput = "";
                return
            end

            try
                projectInput = string(projectInput);
            catch
                projectInput = "";
                return
            end

            if isempty(projectInput)
                projectInput = "";
                return
            end

            projectInput = strtrim(projectInput(1));

            if ismissing(projectInput)
                projectInput = "";
            end

        end % method normalizeStartupProjectInput

        function result = reloadExperimentState(app, options)

            arguments
                app
                options.LogMessages (1, 1) logical = true
            end

            outcome = app.ApplicationController.reloadExperiments( ...
                app.directoryExp);
            outcome.rethrowFailure();
            result = outcome.Result;

            app.renderExperimentImportResult( ...
                result, ...
                LogMessages = options.LogMessages);

        end % method reloadExperimentState

        function renderExperimentImportResult(app, result, options)

            arguments
                app
                result openmebius.application.experiment.ExperimentImportResult
                options.LogMessages (1, 1) logical = true
            end

            app.loadExpData();
            loadBatchTable(app);

            if options.LogMessages

                for i = 1:numel(result.Messages)
                    app.publishMessage("info", result.Messages(i));
                end

            end

        end % method renderExperimentImportResult

        function applyBatchStyleRules(app, styleRules)

            if isempty(styleRules)
                return
            end

            for i = 1:numel(styleRules)

                style = app.batchStyleFromKey(styleRules(i).StyleKey);

                addStyle( ...
                    app.RunTable, ...
                    style, ...
                    'cell', ...
                    [styleRules(i).Rows, styleRules(i).Columns]);

            end

        end % method applyBatchStyleRules

        function style = batchStyleFromKey(app, styleKey)

            switch lower(string(styleKey))

                case "info"
                    style = app.styleInfoIcon;

                case "success"
                    style = app.styleSuccessIcon;

                case "warning"
                    style = app.styleWarningIcon;

                case "error"
                    style = app.styleErrorIcon;

                case "question"
                    style = app.styleQuestionIcon;

                otherwise
                    error( ...
                        "OpenMebius2:Batch:UnknownStyleKey", ...
                        "Unknown batch style key: %s", string(styleKey));
            end

        end % method batchStyleFromKey

        function style = resultStyleFromRule(app, rule)

            key = lower(string(rule.StyleKey));

            switch key

                case "chi2-passed"

                    if app.isDarkTheme()
                        style = app.styleIsPassedDark;
                    else
                        style = app.styleIsPassed;
                    end

                case "chi2-failed"

                    if app.isDarkTheme()
                        style = app.styleIsNotPassedDark;
                    else
                        style = app.styleIsNotPassed;
                    end

                case "align-right"
                    style = uistyle("HorizontalAlignment", "right");

                case "background"
                    style = uistyle("BackgroundColor", char(rule.Value));

                otherwise
                    error( ...
                        "OpenMebius2:Result:UnknownStyleKey", ...
                        "Unknown result style key: %s", key);
            end

        end % method resultStyleFromRule

        function applyResultStyleRules(app, tableObject, styleRules)

            if isempty(styleRules)
                return
            end

            for i = 1:numel(styleRules)

                style = app.resultStyleFromRule(styleRules(i));

                target = char(styleRules(i).Target);

                switch string(styleRules(i).Target)

                    case "cell"
                        addStyle( ...
                            tableObject, ...
                            style, ...
                            target, ...
                            [styleRules(i).Rows, styleRules(i).Columns]);

                    case "column"
                        addStyle( ...
                            tableObject, ...
                            style, ...
                            target, ...
                            styleRules(i).Columns);

                    case "row"
                        addStyle( ...
                            tableObject, ...
                            style, ...
                            target, ...
                            styleRules(i).Rows);

                    otherwise
                        error( ...
                            "OpenMebius2:Result:InvalidStyleTarget", ...
                            "Unknown style target: %s", string(styleRules(i).Target));
                end

            end

        end % method applyResultStyleRules

        function progressBar = getProgressBar(app)

            if isempty(app.ProgressBar) || ~isvalid(app.ProgressBar)
                app.ProgressBar = app.ProgressBarFactory.create( ...
                    app.GridLayout2, 3, 1);
            end

            progressBar = app.ProgressBar;

        end % method getProgressBar

        function rows = selectedTableRows(~, tableObject)

            rows = zeros(0, 1);

            try
                selection = tableObject.Selection;

                if isempty(selection)
                    return
                end

                selectionType = "cell";

                if isprop(tableObject, 'SelectionType')
                    selectionType = lower(string(tableObject.SelectionType));
                end

                switch selectionType
                    case "row"
                        rows = selection(:);

                    case "cell"
                        rows = selection(:, 1);

                    otherwise
                        return
                end

                rows = double(rows(:));
                rows = rows(~isnan(rows));
                rows = rows(rows >= 1);
                rows = unique(rows, "stable");

                data = tableObject.Data;

                if isempty(data)
                    rows = zeros(0, 1);
                    return
                end

                if istable(data)
                    maxRow = height(data);
                else
                    maxRow = size(data, 1);
                end

                rows = rows(rows <= maxRow);

            catch
                rows = zeros(0, 1);
            end

        end % method selectedTableRows

        function removeSelectedBatches(app)

            selectedRows = app.selectedTableRows(app.RunTable);

            if isempty(selectedRows)
                app.publishMessage("warning", "Please select a batch to remove.");
                return
            end

            batchData = app.getBatchOperationalData();
            batchIds = string(batchData.ID(selectedRows));
            [answer, isOK] = app.uiConfirmWrap( ...
                "Are you sure you want to remove the selected batch?", ...
                "Remove Batch", ...
                Options = ["Yes", "No"], ...
                DefaultOption = "No", ...
                CancelOption = "No", ...
                Icon = "warning");

            if ~isOK || answer ~= "Yes"
                return
            end

            [outcome, batch] = app.ApplicationController ...
                .removeBatches(batchIds);
            app.renderBatchOperationViewModel( ...
                app.BatchPresenter.presentRemoveOutcome( ...
                outcome, batch));

        end % removeSelectedBatches

        function duplicateSelectedBatches(app)

            selectedRows = app.selectedTableRows(app.RunTable);

            if isempty(selectedRows)
                app.publishMessage( ...
                    "warning", "Please select a batch to duplicate.");
                return
            end

            batchData = app.getBatchOperationalData();
            batchIds = string(batchData.ID(selectedRows));
            [outcome, batch] = app.ApplicationController ...
                .duplicateBatches(batchIds, batchData);
            app.renderBatchOperationViewModel( ...
                app.BatchPresenter.presentDuplicateOutcome( ...
                outcome, batch));

        end % duplicateSelectedBatches

        function context = captureResultPlotContext(app)

            context = struct();

            context.Mode = string(app.ResultDropDown.Value);

            context.SelectedMainRows = ...
                app.selectedTableRows(app.ResultMainTable);

            context.SelectedSubRows = ...
                app.selectedTableRows(app.ResultSubTable);

            context.MainTableData = app.getResultMainRawData();
            context.SubTableData = app.getResultSubRawData();

            context.MainTableRowNames = ...
                app.getResultReactionIds(context.MainTableData);
            context.SubTableRowNames = string.empty(0, 1);

            try
                context.SubTableRowNames = string(app.ResultSubTable.RowName);
            catch
                context.SubTableRowNames = string.empty(0, 1);
            end

        end % method captureResultPlotContext

        function reactionID = selectedModelReactionID(app)

            reactionID = "";
            selectedRows = app.selectedTableRows(app.ModelTable);
            data = app.ModelTable.Data;

            if isempty(selectedRows) || ~istable(data) || ...
                    isempty(data.Properties.RowNames)
                return
            end

            selectedRow = selectedRows(1);
            reactionIDs = string(data.Properties.RowNames);

            if selectedRow >= 1 && selectedRow <= numel(reactionIDs)
                reactionID = reactionIDs(selectedRow);
            end

        end % selectedModelReactionID

        %% Batch completion notification helper
        function publishBatchCompletion(app, viewModel)

            status = lower(string(viewModel.CompletionStatus));

            switch status
                case "finished"
                    code = "batch.completed";
                case "canceled"
                    code = "batch.canceled";
                otherwise
                    code = "batch.failed";
            end

            projectName = "";

            try
                project = app.ApplicationController.project();

                if ~isempty(project)
                    projectName = project.Metadata.Name;
                end

            catch
                projectName = "";
            end

            context = struct( ...
                Status = status, ...
                ProjectName = projectName, ...
                BatchStatus = status, ...
                DeltaTime = viewModel.ElapsedTime, ...
                ErrorMessage = viewModel.ErrorMessage);
            notification = viewModel.Notification;

            if isa(notification, ...
                "openmebius.presentation.notification.Notification")
                message = notification.toMessage( ...
                    Code = code, ...
                    Source = "BatchPresenter", ...
                    Context = context);
            else
                message = openmebius.core.notification.Message( ...
                    string(notification), ...
                    "info", ...
                    Code = code, ...
                    Title = "OpenMebius2 Batch Run", ...
                    Source = "BatchPresenter", ...
                    Context = context);
            end

            app.NotificationDispatcher.publish(message);

        end % method publishBatchCompletion

        function beginPresentationPreferences(app)

            context = app.capturePresentationContext();
            viewModel = app.Presenter.beginPreferences(context);
            app.renderMainViewModel(viewModel);

        end % method beginPresentationPreferences

        function finishPresentationPreferences(app)

            try
                context = app.capturePresentationContext();
                viewModel = app.Presenter.finishPreferences(context);
                app.renderMainViewModel(viewModel);
            catch ME

                try
                    app.publishMessage("warning", ...
                        "Failed to restore main UI after Preferences: " + ...
                        string(ME.message));
                catch
                end

                app.restoreMainInteraction();
            end

        end % method finishPresentationPreferences

        %% Private initialization function
        function initializeLogView(app)

            % Initial message
            app.LogTextArea.Value = ...
                "--------------------------------------------------------------------------------------------------------------------------------------------------------------" + newline + ...
                "OpenMebius2 is a MATLAB App for 13C metabolic flux analysis (13C-MFA) and related calculations." + newline + ...
                "Please load a project directory to start the analysis." + newline + ...
                "If you are new to OpenMebius2, please see the documentation and examples at tutorial page." + newline + newline + ...
                "You can use the following resources to try this software:" + newline + ...
                "- GitHub repository: URL" + newline + ...
                "- Tutorial page: URL" + newline + ...
                "- Article: URL" + newline + newline + ...
                "If you use OpenMebius2 in your scientific work, please consider to cite following publication: " + newline + ...
                "For more information, please visit URL" + newline + ...
                "--------------------------------------------------------------------------------------------------------------------------------------------------------------";

        end % function initializeLogView

        function initDirectory(app, directory)
            % Initialize the results directory

            app.directoryModel = fullfile(directory, "model");
            app.directoryExp = fullfile(directory, "experiments");
            app.directoryResult = fullfile(directory, "results");

            % Create the directories if they do not exist
            if ~isfolder(app.directoryModel)
                mkdir(app.directoryModel);
            end

            if ~isfolder(app.directoryExp)
                mkdir(app.directoryExp);
            end

            if ~isfolder(app.directoryResult)
                mkdir(app.directoryResult);
            end

        end % function initResults

        function initStatusTable(app, options)
            % INITSTATUSTABLE Initialize the status component with default values

            arguments
                app
                options.update = false
                options.rows = "";
            end % arguments

            ui = app.StatusHTML;

            if app.isDarkTheme()
                css = [
                       "body { background-color: #222; color: #EEE; margin: 0; padding: 0; font-family: sans-serif; }" ...
                           "table { width: 100%; border-collapse: collapse; }" ...
                           "td { padding: 8px; background-color: #222; border: none; }" ...
                           "td:first-child { width: 10%; text-align: center; }" ...
                           "td:nth-child(2) { width: 20%; }" ...
                           "td:last-child  { width: 70%; }"
                       ];
            else
                css = [
                       "body { background-color: #fff; color: #000; margin: 0; padding: 0; font-family: sans-serif; }" ...
                           "table { width: 100%; border-collapse: collapse; }" ...
                           "td { padding: 8px; background-color: #fff; border: none; }" ...
                           "td:first-child { width: 10%; text-align: center; }" ...
                           "td:nth-child(2) { width: 20%; }" ...
                           "td:last-child  { width: 70%; }"
                       ];
            end % if app.isDarkTheme()

            cssChar = char(strjoin(css, ""));
            htmlHeader = ['<html><head><style>', cssChar, '</style></head><body>'];
            htmlHeader = [htmlHeader, '<table>'];
            htmlFooter = '</table></body></html>';

            if options.update

                % If the table is initialized, we will update it
                rows = options.rows;

                if isempty(rows)
                    rows = {
                            '<tr><td></td><td>Model</td><td>Initializing...</td></tr>', ...
                                '<tr><td></td><td>Experiment</td><td>Initializing...</td></tr>', ...
                                '<tr><td></td><td>Batch</td><td>Initializing...</td></tr>', ...
                                '<tr><td></td><td>Result</td><td>Initializing...</td></tr>'
                            };
                end % if isempty(rows)

            else

                rows = {
                        '<tr><td>ℹ️</td><td>Model</td><td>Not loaded</td></tr>', ...
                            '<tr><td>ℹ️</td><td>Experiment</td><td>Not loaded</td></tr>', ...
                            '<tr><td>ℹ️</td><td>Batch</td><td>Not started</td></tr>', ...
                            '<tr><td>ℹ️</td><td>Result</td><td>Not loaded</td></tr>'
                        };

            end % if options.update

            htmlBody = strjoin(rows, "");
            htmlContent = [htmlHeader, htmlBody, htmlFooter];

            if ~ischar(htmlContent)
                htmlContent = char(htmlContent);
            end % if ~ischar(htmlContent)

            ui.HTMLSource = htmlContent;

            if ~options.update
                app.StatusState.reset();
            end

        end % function initStatusTable

        %% Private load function
        function loadHistory(app)

            system = System();
            directoryLog = system.getCacheDirectory();

            if ~isfolder(directoryLog)
                mkdir(directoryLog);
                return
            end

            fileLog = fullfile(directoryLog, 'history.mat');

            if isfile(fileLog)
                load(fileLog, 'projectHistory', 'tempModelHistory');
                app.ProjectDirectoryDropDown.Items = projectHistory;
                app.TemplateModelDirectoryDropDown.Items = tempModelHistory;
                app.ProjectDirectoryDropDown.Value = "";
                app.TemplateModelDirectoryDropDown.Value = "";
            end

        end % method loadHistory

        function renderWorkspaceTable(~, tableObject, viewModel)

            tableObject.Data = viewModel.Data;
            tableObject.ColumnName = viewModel.ColumnName;
            tableObject.RowName = viewModel.RowName;
            tableObject.ColumnEditable = viewModel.ColumnEditable;

        end % renderWorkspaceTable

        function renderModelTableViewModel(app, viewModel)

            app.renderWorkspaceTable(app.ModelTable, viewModel);
            app.resetModelTableColorFormat();

            if ~isempty(viewModel.ErrorRows)
                addStyle( ...
                    app.ModelTable, app.styleError, ...
                    'row', viewModel.ErrorRows);
            end

        end % renderModelTableViewModel

        function renderMassSpectrometryTableViewModels( ...
                app, massSpectrometry, atom)

            app.renderWorkspaceTable(app.MSTable, massSpectrometry);
            app.renderWorkspaceTable(app.AtomTable, atom);
            app.resetMSTableColorFormat();

            if ~isempty(massSpectrometry.ErrorRows)
                addStyle( ...
                    app.MSTable, app.styleError, ...
                    'row', massSpectrometry.ErrorRows);
            end

            if ~isempty(atom.ErrorRows)
                addStyle( ...
                    app.AtomTable, app.styleError, ...
                    'row', atom.ErrorRows);
            end

        end % renderMassSpectrometryTableViewModels

        function renderModelWorkspaceViewModel(app, viewModel)

            app.renderModelTableViewModel(viewModel.ModelTable);
            app.renderMassSpectrometryTableViewModels( ...
                viewModel.MassSpectrometryTable, ...
                viewModel.AtomTable);
            app.renderWorkspaceTable( ...
                app.BiomassTable, viewModel.BiomassTable);

        end % renderModelWorkspaceViewModel

        function renderExperimentWorkspaceViewModel(app, viewModel)

            app.renderWorkspaceTable( ...
                app.ExpTable, viewModel.InformationTable);
            app.renderWorkspaceTable( ...
                app.LabelTable, viewModel.TracerTable);
            app.renderWorkspaceTable( ...
                app.UptakeTable, viewModel.UptakeTable);

        end % renderExperimentWorkspaceViewModel

        function loadEMUModel(app)

            viewModel = app.ModelPresenter ...
                .presentWorkspaceTables(app.ApplicationController.model());
            app.renderModelWorkspaceViewModel(viewModel);

        end % function loadEMUModel

        function loadModelTable(app, options)

            arguments
                app
                options.ColumnEditable logical = false
            end

            viewModel = app.ModelPresenter.presentModelTable( ...
                app.ApplicationController.model(), ...
                ColumnEditable = options.ColumnEditable);
            app.renderModelTableViewModel(viewModel);

        end % function loadModelTable

        function loadMSTable(app, options)

            arguments
                app
                options.isColumnEditable = false
            end

            [massSpectrometry, atom] = app.ModelPresenter ...
                .presentMassSpectrometryTables( ...
                app.ApplicationController.model(), ...
                ColumnEditable = options.isColumnEditable);
            app.renderMassSpectrometryTableViewModels( ...
                massSpectrometry, atom);

        end % function loadMSTable

        function loadTracerTable(app)

            columnEditable = app.LabelTable.ColumnEditable;

            if isempty(columnEditable)
                columnEditable = false;
            end

            viewModel = app.ExperimentPresenter.presentTracerTable( ...
                app.ApplicationController.experiments(), ...
                ColumnEditable = columnEditable);
            app.renderWorkspaceTable(app.LabelTable, viewModel);

        end % function loadTracerTable

        function loadPathway(app)

            viewModel = app.ModelPresenter.presentPathway( ...
                app.ApplicationController.model(), ...
                IsDarkTheme = app.isDarkTheme());

            if ~isempty(viewModel.Notification)
                app.publishNotification(viewModel.Notification);
            end

            app.renderPathwayPlot(viewModel);

            if isempty(viewModel.Image)
                return
            end

            app.publishMessage("info", "Pathway loaded successfully");

        end % function loadPathway

        function loadExpData(app)

            viewModel = app.ExperimentPresenter ...
                .presentWorkspaceTables( ...
                app.ApplicationController.experiments());
            app.renderExperimentWorkspaceViewModel(viewModel);

        end % function loadExpData

        function loadBatchTable(app)

            batch = app.ApplicationController.batch();

            if isempty(batch) || ~isvalid(batch)
                msg = "Batch object is not valid.";
                app.publishMessage("error", msg);
                app.updateStatus("batch", "error");
                return
            end

            app.getProgressBar();

            viewModel = app.BatchPresenter.presentTable(batch);

            app.renderBatchTable(viewModel);

        end % function loadBatchTable

        function loadResult(app)

            result = app.ApplicationController.result();

            if isempty(result) || ~isvalid(result)
                msg = "Result object is not valid.";
                app.publishMessage("error", msg);
                app.updateStatus("result", "error");
                return
            end

            viewModel = app.ResultPresenter.presentIndex( ...
                result, app.ApplicationController.batch());

            app.renderResultSubTable(viewModel);

            app.renderResultMainTable( ...
                openmebius.presentation.result.ResultTableViewModel());

            if isempty(viewModel.Data)
                return
            end

        end % method loadResult

        function loadMainResultTable(app, options)

            arguments
                app
                options.relative = false
                options.relativeTo = ""
            end

            type = string(app.ResultDropDown.Value);
            rows = app.selectedTableRows(app.ResultSubTable);

            if isempty(rows)
                return
            end

            switch type

                case "Overview"

                    row = rows(1);
                    data = app.getResultSubRawData();
                    batchID = string(data.ID(row));

                    loadResultOverView( ...
                        app, ...
                        batchID, ...
                        relative = options.relative, ...
                        relativeTo = options.relativeTo);

                case {"MDV", "Details", "Detailed"}

                    row = rows(1);
                    data = app.getResultSubRawData();
                    batchID = string(data.ID(row));

                    loadResultMDV(app, batchID);

                case "MDV (Summary)"

                    row = rows(1);
                    data = app.getResultSubRawData();
                    batchID = string(data.ID(row));

                    loadResultMDVSummary(app, batchID);

                case "Comparison"

                    if numel(rows) < 2
                        msg = "Please select at least two results for comparison.";
                        app.publishMessage("warning", msg);
                        return
                    end

                    data = app.getResultSubRawData();
                    batchIDs = string(data.ID(rows));
                    names = string(data.Name(rows));

                    loadResultComparison( ...
                        app, ...
                        batchIDs, ...
                        names, ...
                        relative = options.relative, ...
                        relativeTo = options.relativeTo);

                otherwise

                    error( ...
                        "OpenMebius2:Result:InvalidViewMode", ...
                        "Unknown result view mode: %s", type);
            end

        end % method loadMainResultTable

        function loadResultOverView(app, batchID, options)

            arguments
                app
                batchID string
                options.relative = false
                options.relativeTo = ""
            end

            viewModel = app.ResultPresenter.presentMain( ...
                app.ApplicationController.result(), ...
                "Overview", ...
                batchID, ...
                "", ...
                Relative = options.relative, ...
                RelativeTo = options.relativeTo, ...
                IsDarkTheme = app.isDarkTheme());

            app.renderResultMainTable(viewModel);

        end % method loadResultOverView

        function loadResultMDV(app, batchID)

            viewModel = app.ResultPresenter.presentMain( ...
                app.ApplicationController.result(), ...
                "MDV", ...
                batchID, ...
                "", ...
                IsDarkTheme = app.isDarkTheme());

            app.renderResultMainTable(viewModel);

        end % method loadResultMDV

        function loadResultMDVSummary(app, batchID)

            viewModel = app.ResultPresenter.presentMain( ...
                app.ApplicationController.result(), ...
                "MDV (Summary)", ...
                batchID, ...
                "", ...
                IsDarkTheme = app.isDarkTheme());

            app.renderResultMainTable(viewModel);

        end % method loadResultMDVSummary

        function loadResultComparison(app, batchIDs, names, options)

            arguments
                app
                batchIDs string
                names string
                options.relative = false
                options.relativeTo = ""
            end

            viewModel = app.ResultPresenter.presentMain( ...
                app.ApplicationController.result(), ...
                "Comparison", ...
                batchIDs, ...
                names, ...
                Relative = options.relative, ...
                RelativeTo = options.relativeTo, ...
                IsDarkTheme = app.isDarkTheme());

            app.renderResultMainTable(viewModel);

        end % method loadResultComparison

        function loadSubResultTable(app, ~, ~, ~, ~)

            viewModel = app.ResultPresenter.presentIndex( ...
                app.ApplicationController.result(), ...
                app.ApplicationController.batch());

            app.renderResultSubTable(viewModel);

        end % method loadSubResultTable

        %% Private reset function
        function resetAllComponents(app)
            % RESETALLCOMPONENTS Reset all transient table and plot state.

            tables = { ...
                      app.ModelTable, app.MSTable, app.AtomTable, ...
                      app.ExpTable, app.BiomassTable, ...
                      app.UptakeTable, app.LabelTable, app.RunTable, ...
                      app.ResultMainTable, app.ResultSubTable};

            for tableIndex = 1:numel(tables)
                tableObject = tables{tableIndex};
                tableObject.Data = [];
                tableObject.ColumnName = [];
                tableObject.RowName = [];
                tableObject.ColumnEditable = false;

                try
                    tableObject.Selection = [];
                catch
                    % Selection is unavailable on older MATLAB releases.
                end

                try
                    tableObject.UserData = [];
                catch
                    % UserData is unavailable on older MATLAB releases.
                end

                removeStyle(tableObject);
            end

            axesObjects = {app.MainUIAxes, app.SubUIAxes};

            for axesIndex = 1:numel(axesObjects)
                axes = axesObjects{axesIndex};
                cla(axes);
                axes.XLim = [0 1];
                axes.YLim = [0 1];
                axes.XTick = [];
                axes.YTick = [];
                axes.XLabel.String = "";
                axes.YLabel.String = "";
                axes.Title.String = "";
                axes.XLabel.Visible = 'off';
                axes.YLabel.Visible = 'off';
                axes.Title.Visible = 'off';
                axes.XColor = 'none';
                axes.YColor = 'none';
                axes.XGrid = 'off';
                axes.YGrid = 'off';
            end

        end % method resetAllComponents

        function resetTableColorFormat(app)

            resetModelTableColorFormat(app);
            resetMSTableColorFormat(app);

        end % function resetTableColorFormat

        function resetModelTableColorFormat(app)
            removeStyle(app.ModelTable);
        end

        function resetMSTableColorFormat(app)
            removeStyle(app.MSTable);
            removeStyle(app.AtomTable);
        end

        function resetResultTableColorFormat(app)
            removeStyle(app.ResultSubTable);
            removeStyle(app.ResultMainTable);
        end

        %% Private update function
        function handleResultAvailable(app, ~)

            loadResult(app);

            % Drawnow
            drawnow;

        end % function handleResultAvailable

        function updateResultPlot(app, selectionSource)

            if nargin < 2
                selectionSource = "";
            end

            context = app.captureResultPlotContext();
            context.SelectionSource = string(selectionSource);
            viewModel = app.ResultPlotPresenter.present( ...
                app.ApplicationController.model(), ...
                app.ApplicationController.result(), ...
                context, ...
                IsDarkTheme = app.isDarkTheme());
            app.renderResultPlot(viewModel);

        end % method updateResultPlot

        function updateResultPlotFromSubTable(app)

            if app.IsUpdatingResultSelection
                return
            end

            app.IsUpdatingResultSelection = true;
            cleanup = onCleanup( ...
                @() app.finishResultSelectionUpdate());
            app.ResultMainTable.Selection = [];
            loadMainResultTable(app);
            drawnow limitrate;
            updateResultPlot(app, "SubTable");

        end % method updateResultPlotFromSubTable

        function finishResultSelectionUpdate(app)

            app.IsUpdatingResultSelection = false;

        end % method finishResultSelectionUpdate

        %% Private clipboard function
        function clipboardText = copyTableToClipboard(~, tableObject)
            % COPYTABLETOCLIPBOARD Copy the specified table content to clipboard

            data = tableObject.Data;
            [numRows, numCols] = size(data);

            % Copy only selected range if available (rectangular bounding box)
            r1 = 1; r2 = numRows;
            c1 = 1; c2 = numCols;

            if isprop(tableObject, 'Selection')
                sel = tableObject.Selection;

                if ~isempty(sel) && size(sel, 2) >= 2
                    rows = sel(:, 1);
                    cols = sel(:, 2);
                    r1 = double(max(1, min(rows, [], "all")));
                    r2 = double(min(numRows, max(rows, [], "all")));
                    c1 = double(max(1, min(cols, [], "all")));
                    c2 = double(min(numCols, max(cols, [], "all")));
                end

            end

            numRowsOut = max(0, r2 - r1 + 1);
            lines = strings(numRowsOut, 1);

            TAB = char(9);
            CRLF = char([13 10]);

            outRow = 0;

            r1 = r1(1); r2 = r2(1);
            c1 = c1(1); c2 = c2(1);

            for i = r1:r2

                outRow = outRow + 1;

                rowCells = strings(1, max(0, c2 - c1 + 1));

                outCol = 0;

                for j = c1:c2

                    outCol = outCol + 1;

                    if istable(data) || iscell(data)
                        v = data{i, j};
                    else
                        v = data(i, j);
                    end

                    rowCells(outCol) = localToStringScalar(v);
                end

                lines(outRow) = strjoin(rowCells, TAB);

            end

            clipboardText = strjoin(lines, CRLF);

            % Copy as char to keep Excel-friendly plain text
            clipboard('copy', char(clipboardText));

            function s = localToStringScalar(v)
                % Convert table cell content into a scalar string.
                % Treat missing/empty as "" to avoid propagating <missing>.
                if iscell(v)

                    if isempty(v)
                        s = "";
                        return;
                    end

                    if isscalar(v)
                        s = localToStringScalar(v{1});
                        return;
                    end

                    s = localToStringScalar(v{1});
                    return;
                end

                if isstring(v)

                    if isempty(v)
                        s = "";
                        return;
                    end

                    s = v(1);

                    if ismissing(s)
                        s = "";
                    end

                    return;
                end

                if ischar(v)
                    s = string(v);
                    return;
                end

                if ismissing(v)
                    s = "";
                    return;
                end

                try
                    s = string(v);

                    if ismissing(s)
                        s = "";
                    end

                catch
                    s = "";
                end

            end

        end % function copyTableToClipboard

        function pasteClipboardToTable(~, tableObject)
            % PASTECLIPBOARDTOTABLE Paste clipboard content to the specified table

            clipboardContent = clipboard('paste');
            rows = regexp(clipboardContent, '\r\n|\n|\r', 'split');

            if ~isempty(rows) && isempty(rows{end})
                rows(end) = [];
            end

            numRows = length(rows);

            data = tableObject.Data;
            isTableData = istable(data);

            % Paste starting position (top-left of current selection)
            startRow = 1;
            startCol = 1;

            if isprop(tableObject, 'Selection')
                sel = tableObject.Selection;

                if ~isempty(sel) && size(sel, 2) >= 2
                    startRow = max(1, min(sel(:, 1)));
                    startCol = max(1, min(sel(:, 2)));
                end

            end

            if isTableData
                maxRows = height(data);
                maxCols = width(data);
            else
                maxRows = size(tableObject.Data, 1);
                maxCols = size(tableObject.Data, 2);
            end

            for i = 1:numRows

                if isempty(rows{i})
                    continue
                end

                cols = regexp(rows{i}, '\t', 'split');
                numCols = length(cols);

                for j = 1:numCols

                    targetRow = startRow + (i - 1);
                    targetCol = startCol + (j - 1);

                    if targetRow > maxRows || targetCol > maxCols
                        continue
                    end

                    token = cols{j};

                    if ischar(token)
                        token = strrep(token, char(13), '');
                    end

                    if isTableData
                        existing = data{targetRow, targetCol};

                        if isnumeric(existing)
                            v = str2double(token);

                            if isnan(v)
                                v = NaN;
                            end

                            data{targetRow, targetCol} = v;

                        elseif islogical(existing)
                            t = strtrim(token);
                            data{targetRow, targetCol} = any(strcmpi(t, {'1', 'true', 't', 'yes', 'y'}));

                        else
                            data{targetRow, targetCol} = string(token);
                        end

                    else
                        tableObject.Data{targetRow, targetCol} = token;
                    end

                end

            end

            if isTableData
                tableObject.Data = data;
            end

        end % function pasteClipboardToTable

        %% Style functions
        function setBatchInitialStyle(app, status)

            if isempty(app.RunTable.Data)
                return
            end

            styleRules = ...
                app.BatchPresenter.styleRulesForStatus( ...
                app.RunTable.Data, ...
                status);

            app.applyBatchStyleRules(styleRules);

        end

        function data = getResultMainRawData(app)

            data = app.ResultMainTable.Data;

            try
                userData = app.ResultMainTable.UserData;

                if isstruct(userData) && ...
                        isfield(userData, "RawData") && ...
                        ~isempty(userData.RawData)

                    data = userData.RawData;
                end

            catch
                data = app.ResultMainTable.Data;
            end

        end % method getResultMainRawData

        function data = getResultSubRawData(app)

            data = app.ResultSubTable.Data;

            try
                userData = app.ResultSubTable.UserData;

                if isstruct(userData) && ...
                        isfield(userData, "RawData") && ...
                        ~isempty(userData.RawData)
                    data = userData.RawData;
                end

            catch
                data = app.ResultSubTable.Data;
            end

        end % method getResultSubRawData

        function data = getBatchOperationalData(app)

            displayed = app.RunTable.Data;
            data = displayed;

            try
                userData = app.RunTable.UserData;

                if isstruct(userData) && ...
                        isfield(userData, "RawData") && ...
                        ~isempty(userData.RawData) && ...
                        height(userData.RawData) == height(displayed)
                    data = userData.RawData;
                    names = intersect( ...
                        string(displayed.Properties.VariableNames), ...
                        string(data.Properties.VariableNames), ...
                    "stable");
                    names(names == "ID") = [];

                    for name = names
                        data.(name) = displayed.(name);
                    end

                end

            catch
                data = displayed;
            end

        end % method getBatchOperationalData

        function rxnIDs = getResultReactionIds(app, tableData)

            rxnIDs = strings(0, 1);

            try
                rxnIDs = string(app.ResultMainTable.RowName);
                rxnIDs = rxnIDs(:);

                if ~isempty(rxnIDs) && any(rxnIDs ~= "")
                    return
                end

            catch
            end

            try
                rxnIDs = string(tableData.Properties.RowNames);
                rxnIDs = rxnIDs(:);

                if ~isempty(rxnIDs) && any(rxnIDs ~= "")
                    return
                end

            catch
            end

            candidates = ["ID", "RxnID", "Reaction", "ReactionID"];

            try
                names = string(tableData.Properties.VariableNames);

                for i = 1:numel(candidates)
                    idx = find(names == candidates(i), 1);

                    if ~isempty(idx)
                        rxnIDs = string(tableData{:, idx});
                        rxnIDs = rxnIDs(:);
                        return
                    end

                end

            catch
            end

        end % method getResultReactionIds

        %% Status function
        function handleBatchProgress(app, progress)

            viewModel = ...
                app.BatchPresenter.presentProgress( ...
                progress, ...
                app.getBatchOperationalData());

            if ~isempty(viewModel.Notification)
                app.publishNotification(viewModel.Notification);
            end

            app.applyBatchStyleRules(viewModel.StyleRules);

            app.renderBatchProgress(viewModel);

        end % function handleBatchProgress

        function checkLatestVersionOnStartup(app)
            % CHECKLATESTVERSIONONSTARTUP Checks whether a newer version exists.
            % Update failures must not prevent application startup.

            try
                currentVersion = System.getCurrentVersion();
                [latestVersion, releaseURL] = System.getLatestOpenMebius2Version();

                if System.isVersionNewer(latestVersion, currentVersion)
                    msg = "A newer OpenMebius2 version is available: " + latestVersion + ...
                        " (current: " + currentVersion + ").";
                    app.publishMessage("warning", msg);

                    answer = uiconfirm(app.OpenMebius2UIFigure, ...
                        char(msg + newline + "Open the GitHub releases page?"), ...
                        'Update available', ...
                        'Options', {'Open releases', 'Later'}, ...
                        'DefaultOption', 1, ...
                        'CancelOption', 2, ...
                        'Icon', 'warning');

                    if strcmp(answer, 'Open releases')
                        web(char(releaseURL), '-browser');
                    end

                else
                    msg = "OpenMebius2 is up to date: " + currentVersion + ".";
                    app.publishMessage("info", msg);
                end

            catch ME
                msg = "Unable to check for OpenMebius2 updates: " + string(ME.message);
                app.publishMessage("warning", msg);
            end

        end % method checkLatestVersionOnStartup

    end % methods (Access = private)

    methods (Access = private)

        function initializeMainApp(app, filepath)

            app.DialogService = openmebius.presentation.dialog ...
                .AppDialogService(app.OpenMebius2UIFigure);
            dependencies = app.createMainAppDependencies();
            app.applyApplicationDependencies(dependencies);
            app.configureNotificationSinks();
            app.ApplicationController.setNotificationReporter( ...
                app.NotificationDispatcher.reporter());

            if nargin < 2
                filepath = "";
            else
                filepath = string(filepath);
            end

            app.loadHistory();
            app.initializeLogView();
            app.initStatusTable();
            app.initializePresentation();
            app.performStartupUpdateCheck();
            filepath = app.normalizeStartupProjectInput(filepath);

            if filepath == ""
                return
            end

            try
                projectDirectory = app.resolveProjectOpenInput(filepath);

                if projectDirectory ~= ""
                    app.ProjectDirectoryDropDown.Value = projectDirectory;
                    app.ProjectLoadButtonPushed();
                end

            catch exception
                app.publishException( ...
                    exception, ...
                    Title = "Project open failed", ...
                    Alert = true);
            end

        end % initializeMainApp

        function browseProjectDirectory(app)

            [projectDirectory, isOK] = app.uiGetDirWrap( ...
                Title = "Select Project Directory", ...
                StartPath = app.ProjectDirectoryDropDown.Value);

            if ~isOK || strcmp( ...
                    app.ProjectDirectoryDropDown.Value, projectDirectory)
                return
            end

            app.ProjectDirectoryDropDown.Value = projectDirectory;
            app.addProjectDirectoryHistoryItem(projectDirectory);
            app.updateStatus("model", "init");
            app.refreshPresentation();

        end % browseProjectDirectory

        function createProjectFromDialog(app)

            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            [answers, isOK] = app.uiInputDlgWrap( ...
                Prompt = "Enter the name of the new project directory:", ...
                Title = "Create Project Directory", ...
                Default = "NewProject");

            if ~isOK || isempty(answers)
                return
            end

            directoryName = string(answers(1));

            if directoryName == ""
                app.publishMessage("error", "Project directory name cannot be empty.");
                return
            end

            [projectParentDirectory, isOK] = app.uiGetDirWrap( ...
                StartPath = app.ProjectDirectoryDropDown.Value, ...
                Title = "Select Parent Directory for New Project");

            if ~isOK
                return
            end

            app.renderProjectOperationViewModel( ...
                app.ProjectPresenter.presentCreateStarted());
            outcome = app.ApplicationController.createProject( ...
                ParentDirectory = string(projectParentDirectory), ...
                ProjectDirectoryName = directoryName, ...
                TemplateModelDirectory = string( ...
                app.TemplateModelDirectoryDropDown.Value), ...
                Name = string(app.ProjectNameEditField.Value), ...
                Author = string(app.ProjectAuthorEditField.Value), ...
                Organism = string(app.OrganismEditField.Value));
            app.renderProjectOperationViewModel( ...
                app.ProjectPresenter.presentCreateOutcome(outcome));

        end % createProjectFromDialog

        function duplicateCurrentProjectFromDialog(app)

            currentProject = app.ApplicationController.project();

            if isempty(currentProject)
                app.publishMessage( ...
                    "error", "No project is currently loaded.");
                app.refreshPresentation();
                return
            end

            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            currentName = string(currentProject.Metadata.Name);

            if strtrim(currentName) == ""
                [~, currentName] = fileparts( ...
                    currentProject.Paths.RootDirectory);
                currentName = string(currentName);
            end

            [answers, isOK] = app.uiInputDlgWrap( ...
                Prompt = "Enter the name of the duplicated project:", ...
                Title = "Duplicate Current Project", ...
                Default = currentName + "_2");

            if ~isOK || isempty(answers)
                return
            end

            projectName = strtrim(string(answers(1)));

            if projectName == ""
                app.publishMessage( ...
                    "error", "Project name cannot be empty.");
                return
            end

            startPath = string(fileparts( ...
                currentProject.Paths.RootDirectory));

            if startPath == "" || ~isfolder(startPath)
                startPath = string(pwd);
            end

            [parentDirectory, isOK] = app.uiGetDirWrap( ...
                StartPath = startPath, ...
                Title = "Select Destination for Duplicated Project");

            if ~isOK
                return
            end

            outcome = app.ApplicationController.duplicateProject( ...
                ParentDirectory = string(parentDirectory), ...
                ProjectDirectoryName = projectName);
            app.renderProjectOperationViewModel( ...
                app.ProjectPresenter.presentDuplicateOutcome(outcome));

        end % duplicateCurrentProjectFromDialog

        function acceptProjectDirectoryValue(app)

            selectedValue = string(app.ProjectDirectoryDropDown.Value);

            try
                value = app.resolveProjectOpenInput(selectedValue);
            catch exception
                message = "Selected project path does not exist: " + ...
                    selectedValue;
                app.publishMessage( ...
                    "error", ...
                    message + newline + string(exception.message), ...
                    DiagnosticText = string(exception.message));
                return
            end

            app.ProjectDirectoryDropDown.Value = value;

            if ~any(strcmp(app.ProjectDirectoryDropDown.Items, value))
                app.ProjectDirectoryDropDown.Items{end + 1} = value;
            end

        end % acceptProjectDirectoryValue

        function browseTemplateModelDirectory(app)

            [directory, isOK] = app.uiGetDirWrap( ...
                Title = "Select Template Model Directory", ...
                StartPath = app.TemplateModelDirectoryDropDown.Value);

            if ~isOK || strcmp( ...
                    app.TemplateModelDirectoryDropDown.Value, directory)
                return
            end

            app.TemplateModelDirectoryDropDown.Value = directory;

            if ~any(strcmp( ...
                    app.TemplateModelDirectoryDropDown.Items, directory))
                app.TemplateModelDirectoryDropDown.Items{end + 1} = ...
                    directory;
            end

            app.refreshPresentation();

        end % browseTemplateModelDirectory

        function acceptTemplateModelDirectoryValue(app)

            value = string(app.TemplateModelDirectoryDropDown.Value);

            if ~isfolder(value)
                app.publishMessage( ...
                    "error", ...
                    "Selected directory does not exist: " + value);
                return
            end

            if ~any(strcmp(app.TemplateModelDirectoryDropDown.Items, value))
                app.TemplateModelDirectoryDropDown.Items{end + 1} = value;
            end

        end % acceptTemplateModelDirectoryValue

        function showSelectedPathwayReaction(app, indices)

            if isempty(indices)
                return
            end

            row = indices(1, 1);
            data = app.ModelTable.Data;

            if ~istable(data) || row < 1 || row > height(data)
                return
            end

            reactionIDs = string(data.Properties.RowNames);

            if row > numel(reactionIDs)
                return
            end

            viewModel = app.ModelPresenter.presentPathway( ...
                app.ApplicationController.model(), ...
                HighlightReactionIDs = reactionIDs(row), ...
                IsDarkTheme = app.isDarkTheme());

            if ~isempty(viewModel.Notification)
                app.publishNotification(viewModel.Notification);
            end

            app.renderPathwayPlot(viewModel);

        end % showSelectedPathwayReaction

        function importExperimentFilesFromDialog(app)

            [files, isOK] = app.uiGetFileWrap( ...
                Filter = {'*.xlsx;*.xls', ...
                      'Excel Files (*.xlsx, *.xls)'}, ...
                Title = 'Select Experimental Data File', ...
                MultiSelect = "on");

            if ~isOK || isempty(files)
                app.renderExperimentImportViewModel( ...
                    app.ExperimentPresenter.presentFileImportCanceled());
                return
            end

            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter.presentImportStarted());
            outcome = app.ApplicationController.importExperimentFiles( ...
                app.directoryExp, string(files(:)));
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter.presentFileImportOutcome(outcome));

        end % importExperimentFilesFromDialog

        function openSelectedMSView(app)

            selection = app.ExpTable.Selection;

            if isempty(selection) || size(selection, 1) ~= 1

                if isempty(selection)
                    message = "Please select an experiment to view MS data.";
                else
                    message = "Please select only one experiment to view MS data.";
                end

                app.publishMessage("warning", message);
                return
            end

            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            presenter = openmebius.presentation.experiment ...
                .MSViewPresenter(app.ApplicationController.experiments());

            if ~presenter.hasCalculatedMDV()
                app.publishMessage("warning", ...
                    "MDV-derived tables have not been calculated. " + ...
                    "Press Calculate MDV in the Experiment tab before " + ...
                "viewing MDV, biomass-corrected MDV or enrichment data.");
            end

            app.closeMSViewApp();
            context = openmebius.presentation.experiment.MSViewContext( ...
                Presenter = presenter, ...
                InitialExperimentIndex = selection(1, 1), ...
                IsDarkTheme = app.isDarkTheme());
            app.MSViewApp = MSView(context);
            app.attachMSViewListeners(app.MSViewApp);

        end % openSelectedMSView

        function runOrCancelBatch(app)

            if app.Presenter.isRunning()
                app.requestPresentationCancelRun();
                app.renderBatchRunViewModel( ...
                    app.BatchPresenter.presentCancelRequested());
                app.ApplicationController.cancelBatch();
                return
            end

            app.beginPresentationRun();
            cleanupPresentation = onCleanup( ...
                @() app.finishPresentationRunSafely()); %#ok<NASGU>
            app.renderBatchRunViewModel( ...
                app.BatchPresenter.presentRunStarted());
            app.updateBatchTable();
            app.refreshPresentation();
            outcome = app.ApplicationController.runBatch( ...
                app.directoryResult, ...
                ProgressReporter = ...
                @(progress) app.handleBatchProgress(progress), ...
                NotificationReporter = ...
                @(notification) app.publishNotification(notification), ...
                ResultReporter = ...
                @(resultData) app.handleResultAvailable(resultData));
            app.renderBatchRunViewModel( ...
                app.BatchPresenter.presentRunOutcome(outcome));
            outcome.rethrowFailure();

        end % runOrCancelBatch

        function openParallelBatchEditor(app)

            outcome = app.ApplicationController.prepareParallelBatch();
            viewModel = app.BatchExperimentSelectionEditorPresenter ...
                .presentParallelEditor(outcome);

            for notificationIndex = 1:numel(viewModel.Notifications)
                app.publishNotification( ...
                    viewModel.Notifications{notificationIndex});
            end

            if ~viewModel.IsAvailable
                return
            end

            app.closeRunAddBatchApp();
            context = openmebius.presentation.batch ...
                .RunAddBatchContext(Editor = viewModel);
            app.RunAddBatchApp = RunAddBatch(context);
            app.attachRunAddBatchListeners(app.RunAddBatchApp);

        end % openParallelBatchEditor

        function [batchIDs, batchNames] = selectedResultIdentities(app)

            rows = app.selectedTableRows(app.ResultSubTable);
            data = app.getResultSubRawData();
            batchIDs = strings(0, 1);
            batchNames = strings(0, 1);

            if isempty(rows) || ~istable(data) || ...
                    ~all(ismember(["ID", "Name"], ...
                    string(data.Properties.VariableNames))) || ...
                    any(rows > height(data))
                return
            end

            batchIDs = string(data.ID(rows));
            batchNames = string(data.Name(rows));
            batchIDs = batchIDs(:);
            batchNames = batchNames(:);

        end % selectedResultIdentities

        function exportSelectedResults(app)

            [folder, isOK] = app.uiGetDirWrap( ...
                StartPath = app.directoryResult, ...
                Title = "Select Directory to Save Result Files");

            if ~isOK
                return
            end

            [batchIDs, batchNames] = app.selectedResultIdentities();

            if isempty(batchIDs)
                app.renderResultOperationViewModel( ...
                    app.ResultPresenter.presentExportSelectionRequired());
                return
            end

            outcome = app.ApplicationController.exportResults( ...
                batchIDs, batchNames, string(folder));
            app.renderResultOperationViewModel( ...
                app.ResultPresenter.presentExportOutcome(outcome));

        end % exportSelectedResults

        function reloadMainWindow(app)

            app.restoreMainInteraction();
            app.closeReloadWindowChildren();
            app.ApplicationController.resetWorkspace();

            app.saveHistory();
            app.loadHistory();
            app.ProjectDirectoryDropDown.Value = "";
            app.TemplateModelDirectoryDropDown.Value = "";
            app.ProjectNameEditField.Value = "";
            app.ProjectAuthorEditField.Value = "";
            app.OrganismEditField.Value = "";
            app.directoryModel = "";
            app.directoryExp = "";
            app.directoryResult = "";
            app.typeSimulation = "Flux";
            app.report = [];

            app.initializeLogView();
            app.resetAllComponents();
            app.ResultDropDown.Value = 'Overview';
            app.TabGroup.SelectedTab = app.StoichiometryTab;
            app.RunTableEditable = [];
            app.initStatusTable();
            app.resetPresentation();

        end % reloadMainWindow

        function closeReloadWindowChildren(app)

            if ~isempty(app.ChildAppHost) && isvalid(app.ChildAppHost)
                app.ChildAppHost.closeAll();
            end

            app.PreferencesApp = [];
            app.LabelConfigApp = [];
            app.TracerConfigApp = [];
            app.RunConfigApp = [];
            app.MSViewApp = [];
            app.ComparisonViewApp = [];
            app.ViewComparisonApp = [];
            app.RunAddBatchApp = [];

            app.deleteIfValid(app.ViewSuggestionApp);
            app.deleteIfValid(app.RangePlotFigure);
            app.deleteIfValid(app.LogApp);

            try
                if ~isempty(app.ProgressBar) && isvalid(app.ProgressBar)
                    app.ProgressBar.close();
                    delete(app.ProgressBar);
                end
            catch
                % Reload must tolerate an already-closed progress bar.
            end

            app.ViewSuggestionApp = [];
            app.RangePlotFigure = [];
            app.LogApp = [];
            app.ProgressBar = CustomProgressBar.empty;

        end % closeReloadWindowChildren

        function deleteIfValid(~, value)

            if isempty(value)
                return
            end

            try
                if isvalid(value)
                    delete(value);
                end
            catch
                % Reload must tolerate already-closed transient windows.
            end

        end % deleteIfValid

        function openPreferences(app)

            try

                if ~isempty(app.PreferencesApp) && isvalid(app.PreferencesApp)
                    figure(app.PreferencesApp.PreferencesUIFigure);
                    return
                end

            catch
                app.PreferencesApp = [];
            end

            try
                app.beginPresentationPreferences();
                app.PreferencesApp = Preferences( ...
                    app.SlackNotifier, ...
                    app.NotificationDispatcher.reporter());
                app.attachPreferencesListeners(app.PreferencesApp);
            catch exception
                app.finishPresentationPreferences();
                app.publishException( ...
                    exception, ...
                    Title = "Preferences failed", ...
                    Alert = true);
            end

        end % openPreferences

        function showRangePlot(app)

            presenter = openmebius.presentation.result ...
                .ViewComparisonPresenter( ...
                app.ApplicationController.batch(), ...
                app.ApplicationController.result());
            catalogViewModel = presenter.presentCatalog();

            for notificationIndex = 1:numel( ...
                    catalogViewModel.Notifications)
                app.publishNotification( ...
                    catalogViewModel.Notifications{notificationIndex});
            end

            if ~catalogViewModel.IsAvailable
                return
            end

            [selectedBatchIDs, ~] = app.selectedResultIdentities();
            app.closeViewComparisonApp();
            context = openmebius.presentation.result ...
                .ViewComparisonContext( ...
                Presenter = presenter, ...
                InitialCatalog = catalogViewModel, ...
                InitialBatchIDs = selectedBatchIDs);
            app.ViewComparisonApp = ViewComparison(context);
            app.attachViewComparisonListeners(app.ViewComparisonApp);

        end % showRangePlot

        function showSuggestion(app)

            [batchIDs, batchNames] = app.selectedResultIdentities();
            outcome = app.ApplicationController.loadSuggestion( ...
                batchIDs, batchNames);
            app.renderResultOperationViewModel( ...
                app.ResultPresenter.presentSuggestionOutcome(outcome));

        end % showSuggestion

        function copySelectedTracer(app)

            selected = app.LabelTable.Selection;

            if isempty(selected)
                app.renderExperimentEditViewModel( ...
                    app.ExperimentPresenter ...
                    .presentTracerCopySelectionRequired());
                return
            end

            outcome = app.ApplicationController.copyTracerToAllEntries( ...
                app.LabelTable.Data, selected);
            app.renderExperimentEditViewModel( ...
                app.ExperimentPresenter.presentTracerCopyOutcome(outcome));

        end % copySelectedTracer

        function importRawMSFromDirectory(app)

            [directory, isOK] = app.uiGetDirWrap( ...
                Title = "Select Directory Containing MS Data Text Files", ...
                StartPath = app.directoryExp);

            if ~isOK
                return
            end

            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter.presentImportStarted());
            outcome = app.ApplicationController.importRawMS( ...
                directory, app.directoryExp);
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter.presentRawMSImportOutcome(outcome));

        end % importRawMSFromDirectory

        function exportTemplateWorkbook(app)

            if ~app.ApplicationController.hasModel()
                app.renderModelOperationViewModel( ...
                    app.ModelPresenter.presentTemplateExportUnavailable());
                return
            end

            app.renderModelOperationViewModel( ...
                app.ModelPresenter.presentTemplateExportStarted());
            [file, isOK] = app.uiGetFileWrap( ...
                Filter = {'*.xlsx', 'Excel Files (*.xlsx)'}, ...
                Title = 'Save Template Excel File', ...
                MultiSelect = "off", ...
                DefaultName = "Template_MS_Table.xlsx", ...
                Save = true);

            if ~isOK
                return
            end

            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            outcome = app.ApplicationController ...
                .exportMassSpectrometryTemplate(string(file));
            app.renderModelOperationViewModel( ...
                app.ModelPresenter.presentTemplateExportOutcome(outcome));

        end % exportTemplateWorkbook

        function saveMassSpectrometryEdits(app)

            app.beginPresentationEditCommit();
            app.renderModelOperationViewModel( ...
                app.ModelPresenter.presentMassSpectrometrySaveStarted());
            outcome = app.ApplicationController.saveMassSpectrometry( ...
                app.MSTable.Data, app.AtomTable.Data);
            app.renderModelOperationViewModel( ...
                app.ModelPresenter ...
                .presentMassSpectrometrySaveOutcome(outcome));

        end % saveMassSpectrometryEdits

        function calculateExperimentData(app)

            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            app.renderExperimentCalculationViewModel( ...
                app.ExperimentPresenter.presentCalculationStarted());
            outcome = app.ApplicationController.calculateExperiment( ...
                app.ExpTable.Data, ...
                app.UptakeTable.Data, ...
                app.LabelTable.Data);
            app.renderExperimentCalculationViewModel( ...
                app.ExperimentPresenter.presentCalculationOutcome(outcome));

        end % calculateExperimentData

        function saveTracerEdits(app)

            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            app.renderExperimentEditViewModel( ...
                app.ExperimentPresenter.presentEditStarted());
            outcome = app.ApplicationController.saveTracer( ...
                app.UptakeTable.Data, app.LabelTable.Data);
            app.renderExperimentEditViewModel( ...
                app.ExperimentPresenter.presentTracerSaveOutcome(outcome));

        end % saveTracerEdits

        function openRunConfiguration(app)

            requestFactory = @() app.RunConfigPresenter ...
                .createLaunchRequest( ...
                app.getBatchOperationalData(), ...
                app.RunTable.Selection);
            outcome = app.ApplicationController ...
                .prepareBatchConfiguration(requestFactory);
            app.updateBatchTable();
            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            viewModel = app.RunConfigPresenter.presentLaunchOutcome(outcome);
            app.renderRunConfigLaunchViewModel(viewModel);

        end % openRunConfiguration

        function handleTableClipboardShortcut(app, component, event)

            isControl = any(strcmp(event.Modifier, 'control'));

            if isControl && strcmp(event.Key, 'c')
                app.copyTableToClipboard(component);
            elseif isControl && strcmp(event.Key, 'v')
                app.pasteClipboardToTable(component);
            end

        end % handleTableClipboardShortcut

        function openTracerFromInteraction(app, interaction)

            row = interaction.DisplayRow;
            column = interaction.DisplayColumn;

            if isempty(row) || isempty(column)
                return
            end

            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            position = [row, column];
            outcome = app.ApplicationController ...
                .prepareTracerConfiguration(app.LabelTable.Data, position);
            viewModel = app.ExperimentPresenter ...
                .presentTracerConfigurationPreparationOutcome(outcome);
            app.renderTracerConfigurationViewModel(viewModel);

        end % openTracerFromInteraction

        function reloadSelectedTab(app, event)

            if ~strcmp(event.Key, 'f5') || ~isempty(event.Modifier)
                return
            end

            switch app.TabGroup.SelectedTab.Title
                case "Stoichiometry"
                    app.ModelReloadButtonPushed();
                case "MS"
                    app.MSReloadButtonPushed();
                case "Experiment"
                    app.ExpReloadButtonPushed();
                case "Tracer"
                    app.TracerReloadButtonPushed();
                case "Batch"
                    app.RunReloadButtonPushed();
                case "Result"
                    app.ResultReloadButtonPushed();
            end

        end % reloadSelectedTab

        function reloadModelView(app)

            if strcmp(app.ModelEditButton.Enable, 'off')
                app.loadModelTable( ...
                    ColumnEditable = true(1, width(app.ModelTable.Data)));
            else
                app.loadModelTable();
            end

            app.loadPathway();
            app.publishMessage("info", "Model table reloaded");

        end % reloadModelView

        function reloadMassSpectrometryView(app)

            if strcmp(app.MSEditButton.Enable, 'off')
                app.loadMSTable(isColumnEditable = true);
            else
                app.loadMSTable();
            end

            app.publishMessage("info", "MS table reloaded");

        end % reloadMassSpectrometryView

        function saveExperimentInfo(app)

            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            app.renderExperimentEditViewModel( ...
                app.ExperimentPresenter.presentEditStarted());
            outcome = app.ApplicationController.saveExperimentInfo( ...
                app.ExpTable.Data);
            app.renderExperimentEditViewModel( ...
                app.ExperimentPresenter.presentInfoSaveOutcome(outcome));

        end % saveExperimentInfo

    end % methods (Access = private)

    %% Private callback functions
    methods (Access = private)

        function onPreferencesClosed(app, src, event)

            app.detachPreferencesListeners();
            app.PreferencesApp = [];
            app.finishPresentationPreferences();

        end % method onPreferencesClosed

    end % methods (Access = private)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filepath)

            if nargin < 2
                filepath = "";
            end

            app.initializeMainApp(filepath);
        end

        % Close request function: OpenMebius2UIFigure
        function OpenMebius2UIFigureCloseRequest(app, event)

            saveHistory(app)

            if ~isempty(app.ChildAppHost) && isvalid(app.ChildAppHost)
                app.ChildAppHost.closeAll();
            end

            delete(app)

        end

        % Button pushed function: ProjectBrowseButton
        function ProjectBrowseButtonPushed(app, event)

            app.browseProjectDirectory();
        end

        % Button pushed function: ProjectLoadButton
        function ProjectLoadButtonPushed(app, event)

            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>
            app.renderProjectOperationViewModel( ...
                app.ProjectPresenter.presentLoadStarted());

            outcome = app.ApplicationController.openProject( ...
                string(app.ProjectDirectoryDropDown.Value));
            app.renderProjectOperationViewModel( ...
                app.ProjectPresenter.presentOpenOutcome(outcome));
        end

        % Button pushed function: ProjectSaveButton
        function ProjectSaveButtonPushed(app, event)

            outcome = app.ApplicationController.saveProject( ...
                string(app.ProjectDirectoryDropDown.Value), ...
                string(app.ProjectNameEditField.Value), ...
                string(app.ProjectAuthorEditField.Value), ...
                string(app.OrganismEditField.Value));
            app.renderProjectOperationViewModel( ...
                app.ProjectPresenter.presentSaveOutcome(outcome));
        end

        % Button pushed function: ProjectCreateButton
        function ProjectCreateButtonPushed(app, event)

            app.createProjectFromDialog();
        end

        % Value changed function: ProjectDirectoryDropDown
        function ProjectDirectoryDropDownValueChanged(app, event)

            app.acceptProjectDirectoryValue();
        end

        % Button pushed function: TemplateModelBrowseButton
        function TemplateModelBrowseButtonPushed(app, event)

            app.browseTemplateModelDirectory();
        end

        % Button pushed function: TemplateModelLoadButton
        function TemplateModelLoadButtonPushed(app, event)

            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>
            app.renderModelOperationViewModel( ...
                app.ModelPresenter.presentTemplateLoadStarted());

            outcome = app.ApplicationController.loadTemplateModel( ...
                string(app.TemplateModelDirectoryDropDown.Value));
            app.renderModelOperationViewModel( ...
                app.ModelPresenter.presentTemplateLoadOutcome(outcome));
        end

        % Button pushed function: TemplateModelSaveButton
        function TemplateModelSaveButtonPushed(app, event)

        end

        % Value changed function: TemplateModelDirectoryDropDown
        function TemplateModelDirectoryDropDownValueChanged(app, event)

            app.acceptTemplateModelDirectoryValue();
        end

        % Button pushed function: ModelReloadButton
        function ModelReloadButtonPushed(app, event)

            app.reloadModelView();
        end

        % Button pushed function: ModelEditButton
        function ModelEditButtonPushed(app, event)

            import openmebius.presentation.main.EditTarget

            app.beginPresentationEdit(EditTarget.Model);

            app.publishMessage("info", "Model table is now editable");
        end

        % Button pushed function: ModelSaveButton
        function ModelSaveButtonPushed(app, event)

            app.beginPresentationEditCommit();
            app.renderModelOperationViewModel( ...
                app.ModelPresenter.presentModelSaveStarted());

            outcome = app.ApplicationController.saveModelTable( ...
                app.ModelTable.Data);
            app.renderModelOperationViewModel( ...
                app.ModelPresenter.presentModelSaveOutcome(outcome));
        end

        % Cell selection callback: ModelTable
        function ModelTableCellSelection(app, event)

            app.showSelectedPathwayReaction(event.Indices);
        end

        % Menu selected function: AddLabelMenu
        function AddLabelMenuSelected(app, event)

            point = get(app.MainUIAxes, 'CurrentPoint');
            position = [point(1, 1) point(1, 2)];
            reactionID = app.selectedModelReactionID();
            outcome = app.ApplicationController ...
                .setPathwayLabelPosition(reactionID, position);
            app.renderModelPathwayEditViewModel( ...
                app.ModelPresenter.presentPathwayEditOutcome( ...
                outcome, IsDarkTheme = app.isDarkTheme()));
        end

        % Menu selected function: RemoveLabelMenu
        function RemoveLabelMenuSelected(app, event)

            reactionID = app.selectedModelReactionID();
            outcome = app.ApplicationController ...
                .removePathwayLabelPosition(reactionID);
            app.renderModelPathwayEditViewModel( ...
                app.ModelPresenter.presentPathwayEditOutcome( ...
                outcome, IsDarkTheme = app.isDarkTheme()));
        end

        % Button pushed function: MSReloadButton
        function MSReloadButtonPushed(app, event)

            app.reloadMassSpectrometryView();
        end

        % Button pushed function: MSEditButton
        function MSEditButtonPushed(app, event)

            import openmebius.presentation.main.EditTarget

            app.beginPresentationEdit(EditTarget.MassSpectrometry);

            app.publishMessage("info", "MS table is now editable");
        end

        % Button pushed function: MSSaveButton
        function MSSaveButtonPushed(app, event)

            app.saveMassSpectrometryEdits();
        end

        % Button pushed function: ExpCalculationButton
        function ExpCalculationButtonPushed(app, event)

            app.calculateExperimentData();
        end

        % Button pushed function: ExpImportButton
        function ExpImportButtonPushed(app, event)

            app.importExperimentFilesFromDialog();
        end

        % Button pushed function: ExpReloadButton
        function ExpReloadButtonPushed(app, event)

            cleanupPresentation = app.beginPresentationOperation(); %#ok<NASGU>
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter.presentImportStarted());

            outcome = app.ApplicationController.reloadExperiments( ...
                app.directoryExp);
            app.renderExperimentImportViewModel( ...
                app.ExperimentPresenter.presentReloadOutcome(outcome));
        end

        % Button pushed function: ExpSaveButton
        function ExpSaveButtonPushed(app, event)

            app.saveExperimentInfo();
        end

        % Key press function: ExpTable
        function ExpTableKeyPress(app, event)

            app.handleTableClipboardShortcut(app.ExpTable, event);
        end

        % Menu selected function: ViewMStableMenu
        function ViewMStableMenuSelected(app, event)

            app.openSelectedMSView();
        end

        % Button pushed function: TracerConfigButton
        function LabelConfigButtonPushed(app, event)

            cleanupPresentation = ...
                app.beginPresentationOperation(); %#ok<NASGU>
            outcome = app.ApplicationController ...
                .prepareLabelConfiguration();
            viewModel = app.LabelConfigPresenter ...
                .presentLaunchOutcome(outcome);
            app.renderLabelConfigLaunchViewModel(viewModel);
        end

        % Button pushed function: TracerReloadButton
        function TracerReloadButtonPushed(app, event)

            app.loadTracerTable();
            app.publishMessage("info", "Tracer and uptake tables reloaded");
        end

        % Button pushed function: TracerSaveButton
        function TracerSaveButtonPushed(app, event)

            app.saveTracerEdits();
        end

        % Double-clicked callback: LabelTable
        function LabelTableDoubleClicked(app, event)

            app.openTracerFromInteraction(event.InteractionInformation);
        end

        % Key press function: UptakeTable
        function UptakeTableKeyPress(app, event)

            app.handleTableClipboardShortcut(app.UptakeTable, event);
        end

        % Button pushed function: RunAutoButton
        function RunAutoButtonPushed(app, event)

            [outcome, batch] = ...
                app.ApplicationController.autoFillBatch();
            app.renderBatchOperationViewModel( ...
                app.BatchPresenter.presentAutoFillOutcome( ...
                outcome, batch));
        end

        % Button pushed function: RunConfigButton
        function RunConfigButtonPushed(app, event)

            app.openRunConfiguration();
        end

        % Button pushed function: RunReloadButton
        function RunReloadButtonPushed(app, event)

            app.renderBatchOperationViewModel( ...
                app.BatchPresenter.presentReloaded( ...
                app.ApplicationController.batch()));
        end

        % Button pushed function: RunSaveButton
        function RunSaveButtonPushed(app, event)

            [outcome, batch] = app.ApplicationController.saveBatch( ...
                app.getBatchOperationalData());
            app.renderBatchOperationViewModel( ...
                app.BatchPresenter.presentSaveOutcome( ...
                outcome, batch));
        end

        % Button pushed function: RunRunButton
        function RunRunButtonPushed(app, event)

            app.runOrCancelBatch();
        end

        % Menu selected function: AddbatchMenu
        function RunAddbatchMenuSelected(app, event)

            app.openParallelBatchEditor();
        end

        % Menu selected function: RemovethisbatchMenu
        function RunRemovethisbatchMenuSelected(app, event)

            app.removeSelectedBatches();
        end

        % Menu selected function: ParallellabelingMenu
        function RunParallellabelingMenuSelected(app, event)

        end

        % Key press function: RunTable
        function RunKeyPress(app, event)

            if strcmp(event.Key, 'delete')
                app.removeSelectedBatches();
            end
        end

        % Value changed function: ResultDropDown
        function ResultDropDownValueChanged(app, event)

            loadMainResultTable(app);
            updateResultPlot(app);
        end

        % Cell selection callback: ResultSubTable
        function ResultSubTableCellSelection(app, event)

            app.updateResultPlotFromSubTable();
        end

        % Cell edit callback: ResultSubTable
        function ResultSubTableCellEdit(app, event)

        end

        % Cell selection callback: ResultMainTable
        function ResultMainTableCellSelection(app, event)

            if app.IsUpdatingResultSelection
                return
            end

            updateResultPlot(app, "MainTable");
        end

        % Button pushed function: ResultReportButton
        function ResultReportButtonPushed(app, event)

            outcome = app.ApplicationController.generateReport( ...
                app.directoryResult, ...
                IsDeployed = isdeployed);
            app.renderResultOperationViewModel( ...
                app.ResultPresenter.presentReportOutcome(outcome));
        end

        % Button pushed function: ResultReloadButton
        function ResultReloadButtonPushed(app, event)

            app.handleResultAvailable([]);
            app.renderResultOperationViewModel( ...
                app.ResultPresenter.presentReloaded());
        end

        % Button pushed function: ResultSaveButton
        function ResultSaveButtonPushed(app, event)

            app.exportSelectedResults();
        end

        % Menu selected function: ReloadWindowMenu
        function ReloadWindowMenuSelected(app, event)

            app.reloadMainWindow();
        end

        % Menu selected function: DuplicatecurrentprojectMenu
        function DuplicatecurrentprojectMenuSelected(app, event)

            app.duplicateCurrentProjectFromDialog();
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)

            app.openPreferences();
        end

        % Menu selected function: RelativetoMenu
        function RelativetoMenuSelected(app, event)

            selectedRows = ...
                app.selectedTableRows(app.ResultMainTable);
            viewModel = app.ResultPresenter ...
                .presentRelativeSelection( ...
                app.ResultMainTable.RowName, ...
                selectedRows);
            app.renderResultRelativeViewModel(viewModel);
        end

        % Menu selected function: RangeplotMenu
        function RangeplotMenuSelected(app, event)

            app.showRangePlot();
        end

        % Menu selected function: ViewsuggestionMenu
        function ViewsuggestionMenuSelected(app, event)

            app.showSuggestion();
        end

        % Menu selected function: CopythistracerforallentriesMenu
        function CopythistracerforallentriesMenuSelected(app, event)

            app.copySelectedTracer();
        end

        % Menu selected function: ImportMSdatafromtextfilesMenu
        function ImportMSdatafromtextfilesMenuSelected(app, event)

            app.importRawMSFromDirectory();
        end

        % Menu selected function: OpenMebius2manualMenu
        function OpenMebius2manualMenuSelected(app, event)

            try
                msg = "Opening the OpenMebius2 manual in your web browser.";
                app.publishMessage("info", msg);
                web('https://github.com/metabolic-engineering/OpenMebius2', '-browser');
            catch
                msg = "Unable to open the OpenMebius2 manual. Please check your internet connection.";
                app.publishMessage("error", msg);
            end
        end

        % Menu selected function: AboutOpenMebius2Menu
        function AboutOpenMebius2MenuSelected(app, event)

            msg = "This is a test message";

            app.publishNotification( ...
                openmebius.presentation.notification.Notification.info( ...
                msg, ...
                Title = "About OpenMebius2", ...
                ShowAlert = true));
        end

        % Menu selected function: ClearcacheMenu
        function ClearcacheMenuSelected(app, event)

            % Clear cache directory
            app.clearHistory();
        end

        % Menu selected function: ExporttemplateExcelfileMenu
        function ExporttemplateExcelfileMenuSelected(app, event)

            app.exportTemplateWorkbook();
        end

        % Menu selected function: ViewlogsMenu
        function ViewlogsMenuSelected(app, event)

            app.LogApp = AppLogs();
        end

        % Key press function: OpenMebius2UIFigure
        function OpenMebius2UIFigureKeyPress(app, event)

            app.reloadSelectedTab(event);
        end

        % Menu selected function: DuplicatethisbatchMenu
        function DuplicatethisbatchMenuSelected(app, event)

            app.duplicateSelectedBatches();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OpenMebius2UIFigure = uifigure('Visible', 'off');
            app.OpenMebius2UIFigure.Name = 'OpenMebius2';
            app.OpenMebius2UIFigure.Icon = fullfile(pathToMLAPP, '+img', 'logo.png');
            app.OpenMebius2UIFigure.CloseRequestFcn = createCallbackFcn(app, @OpenMebius2UIFigureCloseRequest, true);
            app.OpenMebius2UIFigure.KeyPressFcn = createCallbackFcn(app, @OpenMebius2UIFigureKeyPress, true);
            app.ApplicationMenu = uimenu(app.OpenMebius2UIFigure);
            app.ApplicationMenu.Text = 'Application';
            app.ReloadWindowMenu = uimenu(app.ApplicationMenu);
            app.ReloadWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadWindowMenuSelected, true);
            app.ReloadWindowMenu.Text = 'Reload Window';
            app.DuplicatecurrentprojectMenu = uimenu(app.ApplicationMenu);
            app.DuplicatecurrentprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @DuplicatecurrentprojectMenuSelected, true);
            app.DuplicatecurrentprojectMenu.Text = 'Duplicate current project';
            app.ClearcacheMenu = uimenu(app.ApplicationMenu);
            app.ClearcacheMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearcacheMenuSelected, true);
            app.ClearcacheMenu.Text = 'Clear cache';
            app.PreferencesMenu = uimenu(app.ApplicationMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.ExperimentaldataMenu = uimenu(app.OpenMebius2UIFigure);
            app.ExperimentaldataMenu.Text = 'Experimental data';
            app.ExporttemplateExcelfileMenu = uimenu(app.ExperimentaldataMenu);
            app.ExporttemplateExcelfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttemplateExcelfileMenuSelected, true);
            app.ExporttemplateExcelfileMenu.Text = 'Export template Excel file';
            app.FilesMenu = uimenu(app.OpenMebius2UIFigure);
            app.FilesMenu.Text = 'Files';
            app.ImportMSdatafromtextfilesMenu = uimenu(app.FilesMenu);
            app.ImportMSdatafromtextfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMSdatafromtextfilesMenuSelected, true);
            app.ImportMSdatafromtextfilesMenu.Text = 'Import MS data from text files';
            app.ModelMenu = uimenu(app.OpenMebius2UIFigure);
            app.ModelMenu.Text = 'Model';
            app.BatchMenu = uimenu(app.OpenMebius2UIFigure);
            app.BatchMenu.Text = 'Batch';
            app.ViewMenu = uimenu(app.OpenMebius2UIFigure);
            app.ViewMenu.Text = 'View';
            app.ViewReportMenu = uimenu(app.ViewMenu);
            app.ViewReportMenu.Text = 'View Report';
            app.HelpMenu = uimenu(app.OpenMebius2UIFigure);
            app.HelpMenu.Text = 'Help';
            app.ViewlogsMenu = uimenu(app.HelpMenu);
            app.ViewlogsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewlogsMenuSelected, true);
            app.ViewlogsMenu.Text = 'View logs';
            app.OpenMebius2manualMenu = uimenu(app.HelpMenu);
            app.OpenMebius2manualMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMebius2manualMenuSelected, true);
            app.OpenMebius2manualMenu.Text = 'OpenMebius2 manual';
            app.AboutOpenMebius2Menu = uimenu(app.HelpMenu);
            app.AboutOpenMebius2Menu.MenuSelectedFcn = createCallbackFcn(app, @AboutOpenMebius2MenuSelected, true);
            app.AboutOpenMebius2Menu.Text = 'About OpenMebius2';
            app.GridLayout = uigridlayout(app.OpenMebius2UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ProjectPanel = uipanel(app.GridLayout2);
            app.ProjectPanel.Title = 'Project';
            app.GridLayout3 = uigridlayout(app.ProjectPanel);
            app.ProjectLabel = uilabel(app.GridLayout3);
            app.ProjectLabel.Text = 'Project directory';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.ProjectnameEditFieldLabel = uilabel(app.GridLayout4);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjectNameEditField = uieditfield(app.GridLayout4, 'text');
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.ProjectLoadButton = uibutton(app.GridLayout5, 'push');
            app.ProjectLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectLoadButtonPushed, true);
            app.ProjectLoadButton.Text = 'Load';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.ProjectauthorEditFieldLabel = uilabel(app.GridLayout7);
            app.ProjectauthorEditFieldLabel.Text = 'Project author';
            app.ProjectAuthorEditField = uieditfield(app.GridLayout7, 'text');
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.OrganismEditFieldLabel = uilabel(app.GridLayout8);
            app.OrganismEditFieldLabel.Text = 'Organism';
            app.OrganismEditField = uieditfield(app.GridLayout8, 'text');
            app.GridLayout5_2 = uigridlayout(app.GridLayout3);
            app.ProjectSaveButton = uibutton(app.GridLayout5_2, 'push');
            app.ProjectSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectSaveButtonPushed, true);
            app.ProjectSaveButton.Text = 'Save';
            app.ProjectLabel_2 = uilabel(app.GridLayout3);
            app.ProjectLabel_2.Text = 'Template model file';
            app.GridLayout5_3 = uigridlayout(app.GridLayout3);
            app.TemplateModelLoadButton = uibutton(app.GridLayout5_3, 'push');
            app.TemplateModelLoadButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateModelLoadButtonPushed, true);
            app.TemplateModelLoadButton.Text = 'Load';
            app.TemplateModelSaveButton = uibutton(app.GridLayout3, 'push');
            app.TemplateModelSaveButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateModelSaveButtonPushed, true);
            app.TemplateModelSaveButton.Enable = 'off';
            app.TemplateModelSaveButton.Text = 'Save model as template';
            app.ProjectCreateButton = uibutton(app.GridLayout3, 'push');
            app.ProjectCreateButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectCreateButtonPushed, true);
            app.ProjectCreateButton.Enable = 'off';
            app.ProjectCreateButton.Text = 'Create project from template model';
            app.GridLayout16 = uigridlayout(app.GridLayout3);
            app.ProjectDirectoryDropDown = uidropdown(app.GridLayout16);
            app.ProjectDirectoryDropDown.Items = {};
            app.ProjectDirectoryDropDown.Editable = 'on';
            app.ProjectDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDirectoryDropDownValueChanged, true);
            app.ProjectDirectoryDropDown.Value = {};
            app.ProjectBrowseButton = uibutton(app.GridLayout16, 'push');
            app.ProjectBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectBrowseButtonPushed, true);
            app.ProjectBrowseButton.Text = 'Browse';
            app.GridLayout16_2 = uigridlayout(app.GridLayout3);
            app.TemplateModelDirectoryDropDown = uidropdown(app.GridLayout16_2);
            app.TemplateModelDirectoryDropDown.Items = {};
            app.TemplateModelDirectoryDropDown.Editable = 'on';
            app.TemplateModelDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @TemplateModelDirectoryDropDownValueChanged, true);
            app.TemplateModelDirectoryDropDown.Value = {};
            app.TemplateModelBrowseButton = uibutton(app.GridLayout16_2, 'push');
            app.TemplateModelBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateModelBrowseButtonPushed, true);
            app.TemplateModelBrowseButton.Text = 'Browse';
            app.StatusHTML = uihtml(app.GridLayout2);
            app.GridLayout9 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout9);
            app.StoichiometryTab = uitab(app.TabGroup);
            app.StoichiometryTab.Title = 'Stoichiometry';
            app.GridLayout11 = uigridlayout(app.StoichiometryTab);
            app.ModelTable = uitable(app.GridLayout11);
            app.ModelTable.ColumnName = '';
            app.ModelTable.RowName = {};
            app.ModelTable.CellSelectionCallback = createCallbackFcn(app, @ModelTableCellSelection, true);
            app.ModelTable.Multiselect = 'off';
            app.ModelTable.Enable = 'off';
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.ModelSaveButton = uibutton(app.GridLayout12, 'push');
            app.ModelSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ModelSaveButtonPushed, true);
            app.ModelSaveButton.Enable = 'off';
            app.ModelSaveButton.Text = 'Save';
            app.ModelEditButton = uibutton(app.GridLayout12, 'push');
            app.ModelEditButton.ButtonPushedFcn = createCallbackFcn(app, @ModelEditButtonPushed, true);
            app.ModelEditButton.Enable = 'off';
            app.ModelEditButton.Text = 'Edit';
            app.ModelReloadButton = uibutton(app.GridLayout12, 'push');
            app.ModelReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ModelReloadButtonPushed, true);
            app.ModelReloadButton.Enable = 'off';
            app.ModelReloadButton.Text = 'Reload';
            app.MSTab = uitab(app.TabGroup);
            app.MSTab.Title = 'MS';
            app.GridLayout11_2 = uigridlayout(app.MSTab);
            app.GridLayout12_2 = uigridlayout(app.GridLayout11_2);
            app.MSSaveButton = uibutton(app.GridLayout12_2, 'push');
            app.MSSaveButton.ButtonPushedFcn = createCallbackFcn(app, @MSSaveButtonPushed, true);
            app.MSSaveButton.Enable = 'off';
            app.MSSaveButton.Text = 'Save';
            app.MSEditButton = uibutton(app.GridLayout12_2, 'push');
            app.MSEditButton.ButtonPushedFcn = createCallbackFcn(app, @MSEditButtonPushed, true);
            app.MSEditButton.Enable = 'off';
            app.MSEditButton.Text = 'Edit';
            app.MSReloadButton = uibutton(app.GridLayout12_2, 'push');
            app.MSReloadButton.ButtonPushedFcn = createCallbackFcn(app, @MSReloadButtonPushed, true);
            app.MSReloadButton.Enable = 'off';
            app.MSReloadButton.Text = 'Reload';
            app.GridLayout13 = uigridlayout(app.GridLayout11_2);
            app.MSTable = uitable(app.GridLayout13);
            app.MSTable.ColumnName = '';
            app.MSTable.RowName = {};
            app.MSTable.Multiselect = 'off';
            app.MSTable.Enable = 'off';
            app.AtomTable = uitable(app.GridLayout13);
            app.AtomTable.ColumnName = '';
            app.AtomTable.RowName = {};
            app.AtomTable.Multiselect = 'off';
            app.AtomTable.Enable = 'off';
            app.ExperimentTab = uitab(app.TabGroup);
            app.ExperimentTab.Title = 'Experiment';
            app.GridLayout11_3 = uigridlayout(app.ExperimentTab);
            app.GridLayout12_3 = uigridlayout(app.GridLayout11_3);
            app.ExpSaveButton = uibutton(app.GridLayout12_3, 'push');
            app.ExpSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ExpSaveButtonPushed, true);
            app.ExpSaveButton.Enable = 'off';
            app.ExpSaveButton.Text = 'Save';
            app.ExpReloadButton = uibutton(app.GridLayout12_3, 'push');
            app.ExpReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ExpReloadButtonPushed, true);
            app.ExpReloadButton.Enable = 'off';
            app.ExpReloadButton.Text = 'Reload';
            app.ExpImportButton = uibutton(app.GridLayout12_3, 'push');
            app.ExpImportButton.ButtonPushedFcn = createCallbackFcn(app, @ExpImportButtonPushed, true);
            app.ExpImportButton.Enable = 'off';
            app.ExpImportButton.Text = 'Import data';
            app.ExpCalculationButton = uibutton(app.GridLayout12_3, 'push');
            app.ExpCalculationButton.ButtonPushedFcn = createCallbackFcn(app, @ExpCalculationButtonPushed, true);
            app.ExpCalculationButton.Enable = 'off';
            app.ExpCalculationButton.Text = 'Calculate';
            app.GridLayout13_2 = uigridlayout(app.GridLayout11_3);
            app.GridLayout14 = uigridlayout(app.GridLayout13_2);
            app.BiomassTable = uitable(app.GridLayout14);
            app.BiomassTable.ColumnName = '';
            app.BiomassTable.RowName = {};
            app.BiomassTable.Enable = 'off';
            app.ExpTable = uitable(app.GridLayout13_2);
            app.ExpTable.ColumnName = '';
            app.ExpTable.RowName = {};
            app.ExpTable.Enable = 'off';
            app.ExpTable.KeyPressFcn = createCallbackFcn(app, @ExpTableKeyPress, true);
            app.TracerTab = uitab(app.TabGroup);
            app.TracerTab.Title = 'Tracer';
            app.GridLayout11_4 = uigridlayout(app.TracerTab);
            app.GridLayout12_4 = uigridlayout(app.GridLayout11_4);
            app.TracerSaveButton = uibutton(app.GridLayout12_4, 'push');
            app.TracerSaveButton.ButtonPushedFcn = createCallbackFcn(app, @TracerSaveButtonPushed, true);
            app.TracerSaveButton.Enable = 'off';
            app.TracerSaveButton.Text = 'Save';
            app.TracerReloadButton = uibutton(app.GridLayout12_4, 'push');
            app.TracerReloadButton.ButtonPushedFcn = createCallbackFcn(app, @TracerReloadButtonPushed, true);
            app.TracerReloadButton.Enable = 'off';
            app.TracerReloadButton.Text = 'Reload';
            app.TracerConfigButton = uibutton(app.GridLayout12_4, 'push');
            app.TracerConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LabelConfigButtonPushed, true);
            app.TracerConfigButton.Enable = 'off';
            app.TracerConfigButton.Text = 'Config';
            app.GridLayout15 = uigridlayout(app.GridLayout11_4);
            app.UptakeTable = uitable(app.GridLayout15);
            app.UptakeTable.ColumnName = '';
            app.UptakeTable.RowName = {};
            app.UptakeTable.Enable = 'off';
            app.UptakeTable.KeyPressFcn = createCallbackFcn(app, @UptakeTableKeyPress, true);
            app.LabelTable = uitable(app.GridLayout15);
            app.LabelTable.ColumnName = '';
            app.LabelTable.RowName = {};
            app.LabelTable.DoubleClickedFcn = createCallbackFcn(app, @LabelTableDoubleClicked, true);
            app.LabelTable.Enable = 'off';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.GridLayout11_5 = uigridlayout(app.RunTab);
            app.RunTable = uitable(app.GridLayout11_5);
            app.RunTable.ColumnName = '';
            app.RunTable.RowName = {};
            app.RunTable.Enable = 'off';
            app.RunTable.KeyPressFcn = createCallbackFcn(app, @RunKeyPress, true);
            app.GridLayout12_5 = uigridlayout(app.GridLayout11_5);
            app.RunRunButton = uibutton(app.GridLayout12_5, 'push');
            app.RunRunButton.ButtonPushedFcn = createCallbackFcn(app, @RunRunButtonPushed, true);
            app.RunRunButton.Enable = 'off';
            app.RunRunButton.Text = 'Run';
            app.RunSaveButton = uibutton(app.GridLayout12_5, 'push');
            app.RunSaveButton.ButtonPushedFcn = createCallbackFcn(app, @RunSaveButtonPushed, true);
            app.RunSaveButton.Enable = 'off';
            app.RunSaveButton.Text = 'Save';
            app.RunReloadButton = uibutton(app.GridLayout12_5, 'push');
            app.RunReloadButton.ButtonPushedFcn = createCallbackFcn(app, @RunReloadButtonPushed, true);
            app.RunReloadButton.Enable = 'off';
            app.RunReloadButton.Text = 'Reload';
            app.RunConfigButton = uibutton(app.GridLayout12_5, 'push');
            app.RunConfigButton.ButtonPushedFcn = createCallbackFcn(app, @RunConfigButtonPushed, true);
            app.RunConfigButton.Enable = 'off';
            app.RunConfigButton.Text = 'Config';
            app.RunAutoButton = uibutton(app.GridLayout12_5, 'push');
            app.RunAutoButton.ButtonPushedFcn = createCallbackFcn(app, @RunAutoButtonPushed, true);
            app.RunAutoButton.Enable = 'off';
            app.RunAutoButton.Text = 'Auto';
            app.ResultTab = uitab(app.TabGroup);
            app.ResultTab.Title = 'Result';
            app.GridLayout11_6 = uigridlayout(app.ResultTab);
            app.GridLayout12_6 = uigridlayout(app.GridLayout11_6);
            app.ResultSaveButton = uibutton(app.GridLayout12_6, 'push');
            app.ResultSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ResultSaveButtonPushed, true);
            app.ResultSaveButton.Enable = 'off';
            app.ResultSaveButton.Text = 'Save';
            app.ResultReloadButton = uibutton(app.GridLayout12_6, 'push');
            app.ResultReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ResultReloadButtonPushed, true);
            app.ResultReloadButton.Enable = 'off';
            app.ResultReloadButton.Text = 'Reload';
            app.ResultReportButton = uibutton(app.GridLayout12_6, 'push');
            app.ResultReportButton.ButtonPushedFcn = createCallbackFcn(app, @ResultReportButtonPushed, true);
            app.ResultReportButton.Enable = 'off';
            app.ResultReportButton.Text = 'Report';
            app.GridLayout12_7 = uigridlayout(app.GridLayout11_6);
            app.ResultDropDown = uidropdown(app.GridLayout12_7);
            app.ResultDropDown.Items = {'Overview', 'MDV', 'MDV (Summary)', 'Comparison'};
            app.ResultDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultDropDownValueChanged, true);
            app.ResultDropDown.Enable = 'off';
            app.ResultDropDown.Value = 'Overview';
            app.GridLayout15_2 = uigridlayout(app.GridLayout11_6);
            app.ResultMainTable = uitable(app.GridLayout15_2);
            app.ResultMainTable.ColumnName = '';
            app.ResultMainTable.RowName = {};
            app.ResultMainTable.SelectionType = 'row';
            app.ResultMainTable.CellSelectionCallback = createCallbackFcn(app, @ResultMainTableCellSelection, true);
            app.ResultMainTable.Enable = 'off';
            app.ResultSubTable = uitable(app.GridLayout15_2);
            app.ResultSubTable.ColumnName = '';
            app.ResultSubTable.RowName = {};
            app.ResultSubTable.SelectionType = 'row';
            app.ResultSubTable.CellEditCallback = createCallbackFcn(app, @ResultSubTableCellEdit, true);
            app.ResultSubTable.CellSelectionCallback = createCallbackFcn(app, @ResultSubTableCellSelection, true);
            app.ResultSubTable.Enable = 'off';
            app.LogTextArea = uitextarea(app.GridLayout9);
            app.LogTextArea.Editable = 'off';
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.MainUIAxes = uiaxes(app.GridLayout10);
            title(app.MainUIAxes, 'Title')
            xlabel(app.MainUIAxes, 'X')
            ylabel(app.MainUIAxes, 'Y')
            zlabel(app.MainUIAxes, 'Z')
            app.MainUIAxes.Visible = 'off';
            app.SubUIAxes = uiaxes(app.GridLayout10);
            title(app.SubUIAxes, 'Title')
            xlabel(app.SubUIAxes, 'X')
            ylabel(app.SubUIAxes, 'Y')
            zlabel(app.SubUIAxes, 'Z')
            app.SubUIAxes.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.OpenMebius2UIFigure);
            app.AddLabelMenu = uimenu(app.ContextMenu);
            app.AddLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @AddLabelMenuSelected, true);
            app.AddLabelMenu.Text = 'Add Label';
            app.RemoveLabelMenu = uimenu(app.ContextMenu);
            app.RemoveLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveLabelMenuSelected, true);
            app.RemoveLabelMenu.Text = 'Remove Label';
            % Assign app.ContextMenu
            app.MainUIAxes.ContextMenu = app.ContextMenu;
            app.ExperimentContextMenu = uicontextmenu(app.OpenMebius2UIFigure);
            app.ViewMStableMenu = uimenu(app.ExperimentContextMenu);
            app.ViewMStableMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMStableMenuSelected, true);
            app.ViewMStableMenu.Text = 'View MS table';
            % Assign app.ExperimentContextMenu
            app.ExpTable.ContextMenu = app.ExperimentContextMenu;
            app.ContextMenuRun = uicontextmenu(app.OpenMebius2UIFigure);
            app.AddbatchMenu = uimenu(app.ContextMenuRun);
            app.AddbatchMenu.MenuSelectedFcn = createCallbackFcn(app, @RunAddbatchMenuSelected, true);
            app.AddbatchMenu.Text = 'Add batch';
            app.DuplicatethisbatchMenu = uimenu(app.ContextMenuRun);
            app.DuplicatethisbatchMenu.MenuSelectedFcn = createCallbackFcn(app, @DuplicatethisbatchMenuSelected, true);
            app.DuplicatethisbatchMenu.Text = 'Duplicate this batch';
            app.RemovethisbatchMenu = uimenu(app.ContextMenuRun);
            app.RemovethisbatchMenu.MenuSelectedFcn = createCallbackFcn(app, @RunRemovethisbatchMenuSelected, true);
            app.RemovethisbatchMenu.Text = 'Remove this batch';
            app.ParallellabelingMenu = uimenu(app.ContextMenuRun);
            app.ParallellabelingMenu.MenuSelectedFcn = createCallbackFcn(app, @RunParallellabelingMenuSelected, true);
            app.ParallellabelingMenu.Text = 'Parallel labeling';
            % Assign app.ContextMenuRun
            app.RunTable.ContextMenu = app.ContextMenuRun;
            app.ContextMenu2 = uicontextmenu(app.OpenMebius2UIFigure);
            app.RelativetoMenu = uimenu(app.ContextMenu2);
            app.RelativetoMenu.MenuSelectedFcn = createCallbackFcn(app, @RelativetoMenuSelected, true);
            app.RelativetoMenu.Text = 'Relative to ...';
            % Assign app.ContextMenu2
            app.ResultMainTable.ContextMenu = app.ContextMenu2;
            app.ContextMenuResultSelect = uicontextmenu(app.OpenMebius2UIFigure);
            app.RangeplotMenu = uimenu(app.ContextMenuResultSelect);
            app.RangeplotMenu.MenuSelectedFcn = createCallbackFcn(app, @RangeplotMenuSelected, true);
            app.RangeplotMenu.Text = 'Range plot';
            app.ViewsuggestionMenu = uimenu(app.ContextMenuResultSelect);
            app.ViewsuggestionMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewsuggestionMenuSelected, true);
            app.ViewsuggestionMenu.Text = 'View suggestion';
            % Assign app.ContextMenuResultSelect
            app.ResultSubTable.ContextMenu = app.ContextMenuResultSelect;
            app.ContextMenu3 = uicontextmenu(app.OpenMebius2UIFigure);
            app.CopythistracerforallentriesMenu = uimenu(app.ContextMenu3);
            app.CopythistracerforallentriesMenu.MenuSelectedFcn = createCallbackFcn(app, @CopythistracerforallentriesMenuSelected, true);
            app.CopythistracerforallentriesMenu.Text = 'Copy this tracer for all entries';
            % Assign app.ContextMenu3
            app.LabelTable.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.OpenMebius2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenMebius2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OpenMebius2UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpenMebius2UIFigure)
        end
    end
end