classdef Preferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreferencesUIFigure        matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout4                matlab.ui.container.GridLayout
        CancelButton               matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        NotificationTab            matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        SlackWebhookEditField      matlab.ui.control.EditField
        WebhookEditFieldLabel      matlab.ui.control.Label
        SlacknotificationCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        SlackNotifier openmebius.infrastructure.notification.SlackWebhookNotifier
        PreferencesClosedNotified (1, 1) logical = false
        NotificationPublisher (1, 1) function_handle = @(~) []
    end

    events
        PreferencesClosed
    end

    methods (Access = private)

        function loadSlackPreferences(app)

            if isempty(app.SlackNotifier)
                app.SlackNotifier = ...
                    openmebius.infrastructure.notification.SlackWebhookNotifier();
            end

            enabled = app.SlackNotifier.isEnabled();
            webhook = app.SlackNotifier.getWebhook();

            app.SlacknotificationCheckBox.Value = enabled;
            app.SlackWebhookEditField.Enable = app.onOff(enabled);

            if webhook == ""
                app.SlackWebhookEditField.Value = "";
            else
                app.SlackWebhookEditField.Value = ...
                    app.SlackNotifier.maskWebhook(webhook);
            end

        end % method loadSlackPreferences

        function saveSlackPreferences(app)

            if isempty(app.SlackNotifier)
                app.SlackNotifier = ...
                    openmebius.infrastructure.notification.SlackWebhookNotifier();
            end

            enabled = logical(app.SlacknotificationCheckBox.Value);
            app.SlackNotifier.setEnabled(enabled);

            value = strtrim(string(app.SlackWebhookEditField.Value));

            if isempty(value) || value == ""
                app.SlackNotifier.clearWebhook();
                return
            end

            if app.SlackNotifier.isMaskedWebhook(value)
                return
            end

            app.SlackNotifier.setWebhook(value);

            app.SlackWebhookEditField.Value = ...
                app.SlackNotifier.maskWebhook(value);

        end % method saveSlackPreferences

        function value = onOff(~, enabled)

            if enabled
                value = 'on';
            else
                value = 'off';
            end

        end % method onOff

        function showPreferenceNotification(app, notification)

            if isempty(notification)
                return
            end

            if ~isa(notification, ...
                "openmebius.presentation.notification.Notification")

                notification = ...
                    openmebius.presentation.notification.Notification.info( ...
                    string(notification));
            end

            app.NotificationPublisher( ...
                notification.toMessage( ...
                    Code = "preferences.operation", ...
                    Source = "Preferences"));

        end % method showPreferenceNotification

        function renderLocalNotification(app, message)

            notification = openmebius.presentation.notification ...
                .Notification.fromMessage( ...
                    message, ...
                    Title = message.Title, ...
                    ShowAlert = true);
            uialert( ...
                app.PreferencesUIFigure, ...
                char(notification.Message), ...
                char(notification.Title), ...
                "Icon", char(notification.alertIcon()), ...
                "Interpreter", "none");

        end % method renderLocalNotification

        function showLocalWarning(app, ME)

            notification = ...
                openmebius.presentation.notification.Notification.fromException( ...
                ME, ...
                Title = "Preferences", ...
                ShowAlert = true);

            app.showPreferenceNotification(notification);

        end % method showLocalWarning

        function notifyPreferencesClosed(app)

            if app.PreferencesClosedNotified
                return
            end

            app.PreferencesClosedNotified = true;

            try
                notify(app, 'PreferencesClosed');
            catch
                % Main app may have already been deleted.
            end

        end % method notifyPreferencesClosed

    end % methods (Access = private)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, slackNotifier, notificationPublisher)

            if nargin < 2 || isempty(slackNotifier)
                slackNotifier = ...
                    openmebius.infrastructure.notification.SlackWebhookNotifier();
            end

            app.SlackNotifier = slackNotifier;

            if nargin >= 3 && ~isempty(notificationPublisher)
                app.NotificationPublisher = notificationPublisher;
            else
                app.NotificationPublisher = ...
                    @(message) app.renderLocalNotification(message);
            end

            app.loadSlackPreferences();
        end

        % Value changed function: SlacknotificationCheckBox
        function SlacknotificationCheckBoxValueChanged(app, event)

            enabled = logical(app.SlacknotificationCheckBox.Value);

            app.SlackWebhookEditField.Enable = app.onOff(enabled);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)

            try
                app.saveSlackPreferences();
                delete(app);

            catch ME
                app.showPreferenceNotification( ...
                    openmebius.presentation.notification.Notification.fromException( ...
                    ME, ...
                    Title = "Preferences", ...
                    ShowAlert = true));
            end
        end

        % Close request function: PreferencesUIFigure
        function PreferencesUIFigureCloseRequest(app, event)

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PreferencesUIFigure = uifigure('Visible', 'off');
            app.PreferencesUIFigure.Name = 'Preferences';
            app.PreferencesUIFigure.Icon = fullfile(pathToMLAPP, '+img', 'logo.png');
            app.PreferencesUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreferencesUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.PreferencesUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.NotificationTab = uitab(app.TabGroup);
            app.NotificationTab.Title = 'Notification';
            app.GridLayout2 = uigridlayout(app.NotificationTab);
            app.SlacknotificationCheckBox = uicheckbox(app.GridLayout2);
            app.SlacknotificationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SlacknotificationCheckBoxValueChanged, true);
            app.SlacknotificationCheckBox.Text = 'Slack notification';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.WebhookEditFieldLabel = uilabel(app.GridLayout3);
            app.WebhookEditFieldLabel.Text = 'Webhook';
            app.SlackWebhookEditField = uieditfield(app.GridLayout3, 'text');
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.CloseButton = uibutton(app.GridLayout4, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CancelButton = uibutton(app.GridLayout4, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.PreferencesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preferences(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PreferencesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreferencesUIFigure)
        end
    end
end