classdef RunAddBatch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddbatchUIFigure  matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        AddAsParallel     matlab.ui.control.CheckBox
        UITable           matlab.ui.control.Table
        GridLayout2       matlab.ui.container.GridLayout
        AddButton         matlab.ui.control.Button
        CloseButton       matlab.ui.control.Button
    end


    properties (Access = private)

        MainApp % Reference to the main app
        type % Type of experiments to add
        batchID % Batch ID for INST-MFA

    end % private properties

    methods (Access = private)

        %% Private initialization methods
        function initTable(app)
            % INITTABLE Initialize the UITable with default properties

            expList = getExpList(app.MainApp.exp);

            dataTable = table( ...
                false(length(expList), 1), ... % Add column initialized to false
                expList', ... % Experiment names
                'VariableNames', {'Add', 'Experiment'} ...
            );

            app.UITable.Data = dataTable;
            app.UITable.ColumnWidth = {50, '1x'};
            app.UITable.ColumnEditable = [true, false]; % Make only the Add column editable

        end % method initTable

    end % private methods


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, type, batchID)

            app.MainApp = MainApp;
            app.type = type;

            if strcmp(type, 'inst-mfa')
                app.AddAsParallel.Visible = 'off';
                app.batchID = batchID;
            end

            initTable(app);

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)

            data = app.UITable.Data;
            % Get Add column
            addColumn = data{:, 1};
            expList = data{:, 2};

            % Get selected experiments
            selectedExps = expList(addColumn);

            if isempty(selectedExps)
                return;
            end

            switch app.type

                case 'parallel'

                    isParallel = app.AddAsParallel.Value;

                    if isParallel

                        % Combine selected experiments into a single entry for parallel labeling
                        combinedExpName = strjoin(selectedExps, ', ');

                        % Edit config for parallel labeling
                        config = struct();
                        config.numExperiments = length(selectedExps);
                        config.isParallel = true;

                        app.MainApp.batch.addBatch( ...
                            combinedExpName, ...
                            {selectedExps'}, ...
                            "Added parallel item", ...
                            config ...
                        )

                    else

                        for i = 1:length(selectedExps)

                            app.MainApp.batch.addBatch( ...
                                selectedExps{i}, ...
                                {selectedExps{i}}, ...
                                "Added item", ...
                                struct() ...
                            )

                        end % for i = 1:length(selectedExps)

                    end % if isParallel

                case 'inst-mfa'

                    % Combine selected experiments into a single entry for parallel labeling
                    combinedExpName = strjoin(selectedExps, ', ');

                    % Edit config for parallel labeling
                    config = struct();
                    config.numExperiments = length(selectedExps);
                    config.isParallel = false;
                    config.isINSTMFA = true;

                    app.MainApp.batch.editBatch( ...
                        app.batchID, ...
                        combinedExpName, ...
                        {selectedExps'}, ...
                        "Added INST-MFA item", ...
                        config ...
                    )

            end % switch app.type

            updateBatchTable(app.MainApp);

            % Close the app after adding the batch items
            % close(app.AddbatchUIFigure);

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)

            % Close the app when the Close button is pressed
            close(app.AddbatchUIFigure);

        end

        % Key press function: AddbatchUIFigure
        function AddbatchUIFigureKeyPress(app, event)

            key = event.Key;

            if strcmp(key, 'escape')
                % Close the app if the Escape key is pressed
                close(app.AddbatchUIFigure);

            end

        end

        % Close request function: AddbatchUIFigure
        function AddbatchUIFigureCloseRequest(app, event)
            uiresume(app.AddbatchUIFigure);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AddbatchUIFigure = uifigure('Visible', 'off');
            app.AddbatchUIFigure.Name = 'Add batch';
            app.AddbatchUIFigure.Icon = fullfile(pathToMLAPP, '+img', 'logo.png');
            app.AddbatchUIFigure.CloseRequestFcn = createCallbackFcn(app, @AddbatchUIFigureCloseRequest, true);
            app.AddbatchUIFigure.KeyPressFcn = createCallbackFcn(app, @AddbatchUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.AddbatchUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.AddButton = uibutton(app.GridLayout2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.AddAsParallel = uicheckbox(app.GridLayout);
            app.AddAsParallel.Text = 'Add batch row as parallel labeling';
            app.AddAsParallel.Value = true;
            % Show the figure after all components are created
            app.AddbatchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunAddBatch(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddbatchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddbatchUIFigure)
        end
    end
end