classdef RunConfig < matlab.apps.AppBase

    events
        BatchExperimentSelectionApplied
        NotificationRequested
    end

    % Properties that correspond to app components
    properties (Access = public)
        BatchconfigUIFigure matlab.ui.Figure
        GridLayout matlab.ui.container.GridLayout
        TabGroup matlab.ui.container.TabGroup
        GeneralTab matlab.ui.container.Tab
        GridLayout5_2 matlab.ui.container.GridLayout
        GridLayout7_2 matlab.ui.container.GridLayout
        GeneralRestoreDefaultButton matlab.ui.control.Button
        GeneralApplyButton matlab.ui.control.Button
        GeneralCancelButton matlab.ui.control.Button
        GridLayout6_2 matlab.ui.container.GridLayout
        TabGroup2 matlab.ui.container.TabGroup
        MonteCarloTab matlab.ui.container.Tab
        GridLayout9 matlab.ui.container.GridLayout
        GridLayout10_8 matlab.ui.container.GridLayout
        MCMethodDropDown matlab.ui.control.DropDown
        CalculationmethodsDropDownLabel matlab.ui.control.Label
        GridLayout10_7 matlab.ui.container.GridLayout
        MCKNREditField matlab.ui.control.NumericEditField
        ThenumberofrunsiKisubNRsubEditFieldLabel matlab.ui.control.Label
        GridLayout10_6 matlab.ui.container.GridLayout
        MCNasEditField matlab.ui.control.NumericEditField
        CertainthresholdiNisubASsubEditFieldLabel matlab.ui.control.Label
        GridLayout10_5 matlab.ui.container.GridLayout
        MCProximityEditField matlab.ui.control.NumericEditField
        ProximitythresholdepsilonLabel matlab.ui.control.Label
        GridLayout10_4 matlab.ui.container.GridLayout
        MCTTEditField matlab.ui.control.NumericEditField
        TerminationtoleranceTTEditFieldLabel matlab.ui.control.Label
        GridLayout10_3 matlab.ui.container.GridLayout
        MCProcedureDropDown matlab.ui.control.DropDown
        OptimizationprocedureDropDownLabel matlab.ui.control.Label
        GridLayout10_2 matlab.ui.container.GridLayout
        MCMIDSDEditField matlab.ui.control.NumericEditField
        VariationsforMIDserrorLabel matlab.ui.control.Label
        MCFixMIDCheckBox matlab.ui.control.CheckBox
        GridLayout10 matlab.ui.container.GridLayout
        MCLmaxEditField matlab.ui.control.NumericEditField
        maximumnumberoftrialsLsubmaxsubLabel matlab.ui.control.Label
        GridsearchTab matlab.ui.container.Tab
        GridLayout11 matlab.ui.container.GridLayout
        GridLayout12_4 matlab.ui.container.GridLayout
        ThresholdDropDown matlab.ui.control.DropDown
        ThresholdDropDownLabel matlab.ui.control.Label
        GridLayout12_3 matlab.ui.container.GridLayout
        IterationtimesforgridsearchEditField matlab.ui.control.NumericEditField
        IterationtimesforgridsearchEditFieldLabel matlab.ui.control.Label
        GridLayout12_2 matlab.ui.container.GridLayout
        GridintervalDeltaixiEditField matlab.ui.control.NumericEditField
        GridintervalDeltaixiEditFieldLabel matlab.ui.control.Label
        GridLayout12 matlab.ui.container.GridLayout
        ThenumberofgridpointsEditField matlab.ui.control.NumericEditField
        ThenumberofgridpointsEditFieldLabel matlab.ui.control.Label
        DeterminegridintervalautomaticallyCheckBox matlab.ui.control.CheckBox
        GridLayout8 matlab.ui.container.GridLayout
        INSTMFACheckBox matlab.ui.control.CheckBox
        DeleteResultButton matlab.ui.control.CheckBox
        GridLayoutAlgorithm_2 matlab.ui.container.GridLayout
        AlgorithmCIDropDown matlab.ui.control.DropDown
        AlgorithmforCIcalculationDropDownLabel matlab.ui.control.Label
        CalcCICheckBox matlab.ui.control.CheckBox
        PerturbateEffluxCheckBox matlab.ui.control.CheckBox
        SuggestionCheckBox matlab.ui.control.CheckBox
        LargeScaleCheckBox matlab.ui.control.CheckBox
        GridLayoutAlgorithm matlab.ui.container.GridLayout
        AlgorithmDropDown matlab.ui.control.DropDown
        CalculationalgorithmDropDownLabel matlab.ui.control.Label
        GridLayoutIteration matlab.ui.container.GridLayout
        IterationSpinner matlab.ui.control.Spinner
        IterationtimesforcalculationSpinnerLabel matlab.ui.control.Label
        MSfragmentTab matlab.ui.container.Tab
        GridLayout5 matlab.ui.container.GridLayout
        MSTable matlab.ui.control.Table
        GridLayout7 matlab.ui.container.GridLayout
        MSRestoreDefaultButton matlab.ui.control.Button
        MSApplyAllButton matlab.ui.control.Button
        MSCancelButton matlab.ui.control.Button
        EffluxperturbationTab matlab.ui.container.Tab
        GridLayout13 matlab.ui.container.GridLayout
        GridLayout22 matlab.ui.container.GridLayout
        EffluxUITable matlab.ui.control.Table
        GridLayout15 matlab.ui.container.GridLayout
        EffluxRestoreDefaultButton matlab.ui.control.Button
        EffluxApplyButton matlab.ui.control.Button
        EffluxCancelButton matlab.ui.control.Button
        TracersuggestionTab matlab.ui.container.Tab
        GridLayout14 matlab.ui.container.GridLayout
        GridLayout17 matlab.ui.container.GridLayout
        GridLayout18 matlab.ui.container.GridLayout
        LabelTable matlab.ui.control.Table
        GridLayout16 matlab.ui.container.GridLayout
        SuggestionApplyButton matlab.ui.control.Button
        SuggestionRestoreDefaultButton matlab.ui.control.Button
        SuggestionCancelButton matlab.ui.control.Button
        INSTMFATab matlab.ui.container.Tab
        GridLayout13_2 matlab.ui.container.GridLayout
        GridLayout20 matlab.ui.container.GridLayout
        INSTMFATimeCourseUITable matlab.ui.control.Table
        GridLayout21 matlab.ui.container.GridLayout
        INSTMFAPoolUITable matlab.ui.control.Table
        GridLayout15_2 matlab.ui.container.GridLayout
        INSTMFAApplyButton matlab.ui.control.Button
        INSTMFARestoreDefaultButton matlab.ui.control.Button
        INSTMFACancelButton matlab.ui.control.Button
        ContextMenu matlab.ui.container.ContextMenu
        AddnewpatternMenu matlab.ui.container.Menu
        AddnewpatternsMenu matlab.ui.container.Menu
        CopythistracerforallentriesMenu matlab.ui.container.Menu
        ContextMenuINST matlab.ui.container.ContextMenu
        AddexperimentsMenu matlab.ui.container.Menu
        RemoveselectedexperimentMenu matlab.ui.container.Menu
    end

    %% Private properties
    properties (Access = private)
        MainApp
        RunAddBatchApp
        RunAddBatchListener event.listener = event.listener.empty(0, 1)
        TracerConfigApp
        TracerConfigListeners event.listener = event.listener.empty(0, 1)
        ExperimentEditController openmebius.application.experiment.ExperimentEditController
        ExperimentPresenter openmebius.presentation.experiment.ExperimentPresenter
        selection
        selectedConfig
        MSFragmentTableMetadata
    end

    %% Public methods
    properties (Access = public)
        exp
    end

    methods (Access = protected)

        function updateINSTMFATimeCourseTable(app)

            [currentID, isSingle] = app.getCurrentIDs();

            if ~isSingle
                return;
            end

            [timePointTable, timePointTableColumnEditable] = app.MainApp.batch.getBatchINSTMFATimePoints(currentID);

            app.INSTMFATimeCourseUITable.Data = timePointTable;
            app.INSTMFATimeCourseUITable.ColumnName = timePointTable.Properties.VariableNames;
            app.INSTMFATimeCourseUITable.RowName = {};
            app.INSTMFATimeCourseUITable.ColumnEditable = timePointTableColumnEditable;

        end % updateINSTMFATimeCourseTable

    end % protected methods

    %% Private methods
    methods (Access = private)

        function setConfigValue(app)
            % SETCONFIGVALUE Set the configuration values in the app
            % based on the selected batch row

            batch = app.MainApp.batch.getBatchForGUI();
            numSelected = size(app.selection, 1);

            for i = 1:numSelected

                iId = batch.ID(app.selection(i));
                iConfig = app.MainApp.batch.getBatchConfig(iId);
                app.selectedConfig = [app.selectedConfig; iConfig];

            end

        end % setConfigValue

        function [currentID, isSingle] = getCurrentIDs(app)

            batch = app.MainApp.batch.getBatchForGUI();
            currentID = batch.ID(app.selection);

            if isscalar(currentID)
                isSingle = true;
            else
                isSingle = false;
            end

        end % getCurrentIDs

        function fillConfigValueToUI(app)
            % FILLCONFIGVALUETOUTI Fill the configuration values into the UI components

            % Iteration times
            iterationTimes = arrayfun(@(x) x.iteration, app.selectedConfig);
            app.IterationSpinner.Value = iterationTimes(1);
            clear iterationTimes

            % Algorithm
            algorithm = arrayfun(@(x) x.algorithm, app.selectedConfig, "UniformOutput", false);
            algorithm = algorithm{1};

            app.AlgorithmDropDown.Value = ...
                openmebius.presentation.batch.RunConfigMapper.algorithmToView(algorithm);

            clear algorithm

            % Large scale problem
            largeScale = arrayfun(@(x) x.largeScale, app.selectedConfig);
            app.LargeScaleCheckBox.Value = largeScale(1);
            clear largeScale

            % Suggest next flux
            suggestNextFlux = arrayfun(@(x) x.suggestNextFlux, app.selectedConfig);
            app.SuggestionCheckBox.Value = suggestNextFlux(1);
            clear suggestNextFlux

            % Perturbate efflux
            perturbateEfflux = arrayfun(@(x) x.perturbateEfflux, app.selectedConfig);
            app.PerturbateEffluxCheckBox.Value = perturbateEfflux(1);
            clear perturbateEfflux

            % Calculate confidence intervals
            isCalcCI = arrayfun(@(x) x.isCalcCI, app.selectedConfig);
            app.CalcCICheckBox.Value = isCalcCI(1);
            clear isCalcCI

            % CI Calculation Algorithm
            ciAlgorithm = arrayfun(@(x) x.CIConf.algorithm, app.selectedConfig, "UniformOutput", false);
            ciAlgorithm = ciAlgorithm{1};

            app.AlgorithmCIDropDown.Value = ...
                openmebius.presentation.batch.RunConfigMapper.ciAlgorithmToView(ciAlgorithm);

            clear ciAlgorithm

            isDeleteResultFile = arrayfun(@(x) x.deleteResultFile, app.selectedConfig);
            app.DeleteResultButton.Value = isDeleteResultFile(1);
            clear isDeleteResultFile

            % Monte Carlo-specific settings
            if strcmp(app.AlgorithmCIDropDown.Value, 'Monte Carlo')
                mcConfig = app.selectedConfig(1).CIConf.MC;

                app.MCLmaxEditField.Value = mcConfig.iteration;
                app.MCFixMIDCheckBox.Value = mcConfig.fixMID;
                app.MCMIDSDEditField.Value = mcConfig.MIDSD;

                app.MCProcedureDropDown.Value = ...
                    openmebius.presentation.batch.RunConfigMapper.mcOptimizationProcedureToView( ...
                    mcConfig.optimizationProcedure);

                app.MCTTEditField.Value = mcConfig.terminationTolerance;
                app.MCProximityEditField.Value = mcConfig.proximityThreshold;
                app.MCNasEditField.Value = mcConfig.certainThreshold;
                app.MCKNREditField.Value = mcConfig.theNumberOfRuns;

                app.MCMethodDropDown.Value = ...
                    openmebius.presentation.batch.RunConfigMapper.mcCalculationMethodToView(mcConfig.calculationMethod);

            end

            % Grid search-specific settings
            if strcmp(app.AlgorithmCIDropDown.Value, 'Grid search')
                gridConfig = app.selectedConfig(1).CIConf.grid;

                app.DeterminegridintervalautomaticallyCheckBox.Value = gridConfig.isParallel;
                app.ThenumberofgridpointsEditField.Value = gridConfig.points;
                app.GridintervalDeltaixiEditField.Value = gridConfig.delta;
                app.IterationtimesforgridsearchEditField.Value = gridConfig.iteration;

                app.ThresholdDropDown.Value = ...
                    openmebius.presentation.batch.RunConfigMapper.gridThresholdToView(gridConfig.threshold);

            end

            app.INSTMFACheckBox.Value = app.selectedConfig(1).isINSTMFA;

            if app.INSTMFACheckBox.Value

                INSTMFA = app.selectedConfig(1).INSTMFA;

                if isempty(INSTMFA)
                    return
                end

                % Pool size
                app.INSTMFAPoolUITable.Data = table( ...
                    string(INSTMFA.poolMetabolite(:)), ...
                    double(INSTMFA.poolSize(:)), ...
                    'VariableNames', {'Metabolite', 'PoolSize'} ...
                );
                app.INSTMFAPoolUITable.ColumnName = {'Metabolite', 'PoolSize'};
                app.INSTMFAPoolUITable.RowName = {};
                app.INSTMFAPoolUITable.ColumnEditable = [false, true];
                % Time course
                app.INSTMFATimeCourseUITable.Data = table( ...
                    string(INSTMFA.timePointsExpName(:)), ...
                    double(INSTMFA.timePoints(:)), ...
                    'VariableNames', {'TimePointExpName', 'TimePoint'} ...
                );
                app.INSTMFATimeCourseUITable.ColumnName = {'TimePointExpName', 'TimePoint'};
                app.INSTMFATimeCourseUITable.RowName = {};
                app.INSTMFATimeCourseUITable.ColumnEditable = [false, true];

            end % if app.INSTMFACheckBox.Value

        end % fillConfigValueToUI

        function enabledisableCIUI(app, isCalcCI)
            % ENABLEDISABLECIUI Enable or disable CI-related UI components
            % based on the isCalcCI flag

            if isCalcCI
                % Enable CI-related components
                app.AlgorithmCIDropDown.Enable = 'on';

                % Check the selected algorithm and enable/disable components accordingly
                selectedAlgorithm = app.AlgorithmCIDropDown.Value;

                switch selectedAlgorithm
                    case 'Monte Carlo'
                        % Enable Monte Carlo-specific components
                        app.MCLmaxEditField.Enable = 'on';
                        app.MCFixMIDCheckBox.Enable = 'on';
                        app.MCMIDSDEditField.Enable = 'on';
                        app.MCProcedureDropDown.Enable = 'on';
                        app.MCTTEditField.Enable = 'on';
                        app.MCProximityEditField.Enable = 'on';
                        app.MCNasEditField.Enable = 'on';
                        app.MCKNREditField.Enable = 'on';
                        app.MCMethodDropDown.Enable = 'on';

                        % Disable Grid Search-specific components
                        app.DeterminegridintervalautomaticallyCheckBox.Enable = 'off';
                        app.ThenumberofgridpointsEditField.Enable = 'off';
                        app.GridintervalDeltaixiEditField.Enable = 'off';
                        app.IterationtimesforgridsearchEditField.Enable = 'off';
                        app.ThresholdDropDown.Enable = 'off';

                    case 'Grid search'
                        % Enable Grid Search-specific components
                        app.DeterminegridintervalautomaticallyCheckBox.Enable = 'on';
                        app.ThenumberofgridpointsEditField.Enable = 'on';
                        app.GridintervalDeltaixiEditField.Enable = 'on';
                        app.IterationtimesforgridsearchEditField.Enable = 'on';
                        app.ThresholdDropDown.Enable = 'on';

                        % Disable Monte Carlo-specific components
                        app.MCLmaxEditField.Enable = 'off';
                        app.MCFixMIDCheckBox.Enable = 'off';
                        app.MCMIDSDEditField.Enable = 'off';
                        app.MCProcedureDropDown.Enable = 'off';
                        app.MCTTEditField.Enable = 'off';
                        app.MCProximityEditField.Enable = 'off';
                        app.MCNasEditField.Enable = 'off';
                        app.MCKNREditField.Enable = 'off';
                        app.MCMethodDropDown.Enable = 'off';
                end

            else
                % Disable all CI-related components
                app.AlgorithmCIDropDown.Enable = 'off';
                app.MCLmaxEditField.Enable = 'off';
                app.MCFixMIDCheckBox.Enable = 'off';
                app.MCMIDSDEditField.Enable = 'off';
                app.MCProcedureDropDown.Enable = 'off';
                app.MCTTEditField.Enable = 'off';
                app.MCProximityEditField.Enable = 'off';
                app.MCNasEditField.Enable = 'off';
                app.MCKNREditField.Enable = 'off';
                app.MCMethodDropDown.Enable = 'off';
                app.DeterminegridintervalautomaticallyCheckBox.Enable = 'off';
                app.ThenumberofgridpointsEditField.Enable = 'off';
                app.GridintervalDeltaixiEditField.Enable = 'off';
                app.IterationtimesforgridsearchEditField.Enable = 'off';
                app.ThresholdDropDown.Enable = 'off';
            end

            enabledisableGridSetting(app)

        end % enabledisableCIUI

        function enabledisableGridSetting(app)
            % ENABLEDISABLEGRIDSETTING Enable or disable grid setting UI components
            % based on the selected algorithm
            % and the value of the DeterminegridintervalautomaticallyCheckBox

            isCalcCI = app.CalcCICheckBox.Value;
            isGridSearch = strcmp(app.AlgorithmCIDropDown.Value, 'Grid search');
            isAutoGridInterval = app.DeterminegridintervalautomaticallyCheckBox.Value;

            if ~isCalcCI && ~isGridSearch
                return
            end

            if isAutoGridInterval
                % Enable grid setting components
                app.ThenumberofgridpointsEditField.Enable = 'on';
                app.GridintervalDeltaixiEditField.Enable = 'off';
            else
                % Disable grid setting components
                app.ThenumberofgridpointsEditField.Enable = 'off';
                app.GridintervalDeltaixiEditField.Enable = 'on';
            end

        end % enabledisableGridSetting

        function enabledisableEffluxPertubation(app)

            isEnable = app.PerturbateEffluxCheckBox.Value;

            [currentID, ~] = app.getCurrentIDs();
            currentID = currentID(1);

            if isEnable

                % Enable efflux perturbation components
                app.EffluxUITable.Enable = 'on';

                [tableEffluxPerturbation, editable] = app.MainApp.batch.getBatchEffluxSDTable(currentID);

                tableData = tableEffluxPerturbation;
                app.EffluxUITable.Data = tableData;
                app.EffluxUITable.ColumnName = tableData.Properties.VariableNames;
                app.EffluxUITable.ColumnEditable = editable;
                app.EffluxUITable.RowName = tableData.Properties.RowNames;

            else

                app.EffluxUITable.Data = [];
                app.EffluxUITable.ColumnName = {};
                app.EffluxUITable.ColumnEditable = [];
                app.EffluxUITable.RowName = {};

                % Disable efflux perturbation components
                app.EffluxUITable.Enable = 'off';

            end

        end % enabledisableEffluxPertubation

        function enabledisableSuggestion(app)
            % ENABLEDISABLESUGGESTION Enable or disable suggestion-related UI components
            % based on the SuggestionCheckBox value

            isSuggestLabel = app.SuggestionCheckBox.Value;

            if isSuggestLabel

                % Enable suggestion-related components
                app.LabelTable.Enable = 'on';

                batch = app.MainApp.batch.getBatchForGUI();
                batchID = batch.ID(app.selection);
                batchIDUnique = unique(batchID);
                tableSuggestion = getBatchSuggestionTable(app.MainApp.batch, batchIDUnique);

                app.LabelTable.Data = tableSuggestion;
                app.LabelTable.ColumnName = tableSuggestion.Properties.VariableNames;
                app.LabelTable.RowName = tableSuggestion.Properties.RowNames;

            else

                % Disable suggestion-related components
                app.LabelTable.Enable = 'off';

                % cleanup suggestion table
                app.LabelTable.Data = [];
                app.LabelTable.ColumnName = {};
                app.LabelTable.RowName = {};

            end

        end % enabledisableSuggestion

        function enabledisableINSTMFA(app, isINSTMFA)
            % ENABLEDISABLEINSTMFA Enable or disable INST-MFA-related UI components
            % based on the isINSTMFA flag

            if isINSTMFA

                batch = app.MainApp.batch.getBatchForGUI();
                batchID = batch.ID(app.selection);
                batchIDUnique = unique(batchID);

                if length(batchIDUnique) ~= 1
                    uialert(app.BatchconfigUIFigure, ...
                        'INST-MFA settings can only be configured when a single batch is selected.', ...
                        'Error', ...
                        'Icon', 'error');
                    app.INSTMFACheckBox.Value = false;
                    return;
                end

                tablePoolSize = app.MainApp.batch.getBatchINSTMFAPoolTable(batchIDUnique);
                [tableTimePoints, timePointColumnEditable] = ...
                    app.MainApp.batch.getBatchINSTMFATimePoints(batchIDUnique);

                app.INSTMFAPoolUITable.Data = tablePoolSize;
                app.INSTMFAPoolUITable.ColumnName = {'Metabolite', 'PoolSize'};
                app.INSTMFAPoolUITable.RowName = {};
                app.INSTMFAPoolUITable.ColumnEditable = [false, true];

                app.INSTMFATimeCourseUITable.Data = tableTimePoints;
                app.INSTMFATimeCourseUITable.ColumnName = {'TimePointExpName', 'TimePoint'};
                app.INSTMFATimeCourseUITable.RowName = {};
                app.INSTMFATimeCourseUITable.ColumnEditable = timePointColumnEditable;

                app.INSTMFAPoolUITable.Enable = 'on';
                app.INSTMFATimeCourseUITable.Enable = 'on';

            else
                app.INSTMFAPoolUITable.Data = [];
                app.INSTMFAPoolUITable.ColumnName = {};
                app.INSTMFAPoolUITable.RowName = {};
                app.INSTMFATimeCourseUITable.Data = [];
                app.INSTMFATimeCourseUITable.ColumnName = {};
                app.INSTMFATimeCourseUITable.RowName = {};

                % Disable INST-MFA-related components
                app.INSTMFAPoolUITable.Enable = 'off';
                app.INSTMFATimeCourseUITable.Enable = 'off';
            end

        end % enabledisableINSTMFA

        function loadMSFragmentTable(app)
            % LOADMSFRAGMENTTABLE Load the MS fragment table for
            % the selected batch and display it in the MSTable
            % component

            % Get the selected batch ID
            batch = app.MainApp.batch.getBatchForGUI();
            batchID = batch.ID(app.selection);
            batchIDUnique = unique(batchID);

            if length(batchID) ~= length(batchIDUnique)
                error('Duplicate batch IDs found in the selection.')
            end

            clear batchIDUnique

            fragmentSelections = app.MainApp.batch.getBatchMSFragmentSelections(batchID);
            viewModel = ...
                openmebius.presentation.batch.MSFragmentTableMapper.toViewModel( ...
                fragmentSelections);
            app.MSFragmentTableMetadata = viewModel.Metadata;

            % Fill the MSTable with the selected and available tables
            app.MSTable.Data = viewModel.Data;
            app.MSTable.ColumnName = viewModel.ColumnName;
            app.MSTable.RowName = viewModel.RowName;
            app.MSTable.ColumnEditable = viewModel.ColumnEditable;

        end % loadMSFragmentTable

        function editTimeCourse(app)
            % EDITTIMECOURSE Edit the time course table for INST-MFA

            batchIDs = app.MainApp.batch.getBatchForGUI().ID(app.selection);

            if length(batchIDs) ~= 1
                return;
            end

            app.detachRunAddBatchListener();
            experimentNames = string(getExpList(app.exp));
            app.RunAddBatchApp = RunAddBatch( ...
                experimentNames, ...
                "inst-mfa", ...
                batchIDs(1));
            app.RunAddBatchListener = addlistener( ...
                app.RunAddBatchApp, ...
                "Applied", ...
                @(source, event) ...
                    app.forwardBatchExperimentSelection(source, event));

        end % editTimeCourse

        function forwardBatchExperimentSelection(app, ~, event)

            eventData = openmebius.presentation.batch ...
                .BatchExperimentSelectionEventData(event.Selection);
            notify( ...
                app, ...
                "BatchExperimentSelectionApplied", ...
                eventData);

        end % forwardBatchExperimentSelection

        function detachRunAddBatchListener(app)

            if isempty(app.RunAddBatchListener)
                return
            end

            try
                if isvalid(app.RunAddBatchListener)
                    delete(app.RunAddBatchListener);
                end
            catch
            end

            app.RunAddBatchListener = event.listener.empty(0, 1);

        end % detachRunAddBatchListener

        function ensureExperimentEditController(app)

            if isempty(app.ExperimentEditController)
                app.ExperimentEditController = ...
                    openmebius.application.experiment ...
                    .ExperimentEditController();
            end

        end % ensureExperimentEditController

        function ensureExperimentPresenter(app)

            if isempty(app.ExperimentPresenter)
                app.ExperimentPresenter = ...
                    openmebius.presentation.experiment ...
                    .ExperimentPresenter();
            end

        end % ensureExperimentPresenter

        function attachTracerConfigListeners(app, tracerConfigApp)

            app.detachTracerConfigListeners();
            listeners = event.listener.empty(0, 1);
            listeners(end + 1, 1) = addlistener( ...
                tracerConfigApp, ...
                "Applied", ...
                @(source, event) ...
                    app.onTracerConfigurationApplied(source, event));
            listeners(end + 1, 1) = addlistener( ...
                tracerConfigApp, ...
                "Closed", ...
                @(source, event) ...
                    app.onTracerConfigurationClosed(source, event));
            app.TracerConfigListeners = listeners;

        end % attachTracerConfigListeners

        function detachTracerConfigListeners(app)

            for listenerIndex = 1:numel(app.TracerConfigListeners)
                try
                    if isvalid(app.TracerConfigListeners(listenerIndex))
                        delete(app.TracerConfigListeners(listenerIndex));
                    end
                catch
                end
            end

            app.TracerConfigListeners = event.listener.empty(0, 1);

        end % detachTracerConfigListeners

        function openTracerConfiguration(app, position)

            app.ensureExperimentEditController();
            app.ensureExperimentPresenter();
            outcome = app.ExperimentEditController ...
                .loadTracerConfiguration(app.exp, position);
            viewModel = app.ExperimentPresenter ...
                .presentTracerConfigurationLoadOutcome(outcome);
            app.renderTracerConfigurationViewModel(viewModel);

            if ~viewModel.IsSuccessful
                return
            end

            app.detachTracerConfigListeners();
            app.TracerConfigApp = TracerConfig( ...
                viewModel.EditorTable, viewModel.Position);
            app.attachTracerConfigListeners(app.TracerConfigApp);

        end % openTracerConfiguration

        function onTracerConfigurationApplied(app, ~, event)

            app.ensureExperimentEditController();
            app.ensureExperimentPresenter();
            outcome = app.ExperimentEditController ...
                .applyTracerConfiguration( ...
                    event.Position, event.EditorTable);
            viewModel = app.ExperimentPresenter ...
                .presentTracerConfigurationApplyOutcome(outcome);
            app.renderTracerConfigurationViewModel(viewModel);

            if viewModel.IsSuccessful
                position = viewModel.Position;
                app.LabelTable.Data{position(1), position(2)} = ...
                    {char(viewModel.Pattern)};
            end

        end % onTracerConfigurationApplied

        function onTracerConfigurationClosed(app, ~, ~)

            app.TracerConfigApp = [];

        end % onTracerConfigurationClosed

        function renderTracerConfigurationViewModel(app, viewModel)

            for notificationIndex = 1:numel(viewModel.Notifications)
                eventData = openmebius.presentation.notification ...
                    .NotificationEventData( ...
                        viewModel.Notifications{notificationIndex});
                notify(app, "NotificationRequested", eventData);
            end

        end % renderTracerConfigurationViewModel

        function applyGeneral(app)
            % APPLYGENERAL Apply the general settings to the selected batch

            % Get the current configuration for the selected batch
            [batchID, ] = app.getCurrentIDs();
            config = app.MainApp.batch.getBatchConfig(batchID(1));

            % Update the configuration with values from the UI
            config.iteration = app.IterationSpinner.Value;

            config.algorithm = ...
                openmebius.presentation.batch.RunConfigMapper.algorithmToConfig(app.AlgorithmDropDown.Value);

            config.largeScale = app.LargeScaleCheckBox.Value;
            config.suggestNextFlux = app.SuggestionCheckBox.Value;
            config.perturbateEfflux = app.PerturbateEffluxCheckBox.Value;

            if config.perturbateEfflux
                % Update efflux perturbation settings only when the UI table
                % contains the table data. When the efflux panel has not been
                % opened yet, UITable.Data can still be the default [] double.
                % In that case, preserve the existing batch configuration.
                tableEffluxPerturbation = app.EffluxUITable.Data;

                if istable(tableEffluxPerturbation) && ...
                        all(ismember(["Selection", "SD"], string(tableEffluxPerturbation.Properties.VariableNames)))
                    config.efflux.selection = logical(tableEffluxPerturbation.Selection(:));
                    config.efflux.substrate = string(tableEffluxPerturbation.Properties.RowNames(:));
                    config.efflux.substrateSD = double(tableEffluxPerturbation.SD(:));
                end

            end

            % Update the confidence interval calculation settings
            config.isCalcCI = app.CalcCICheckBox.Value;
            config.CIConf.algorithm = ...
                openmebius.presentation.batch.RunConfigMapper.ciAlgorithmToConfig(app.AlgorithmCIDropDown.Value);

            config.deleteResultFile = app.DeleteResultButton.Value;

            % Monte Carlo-specific settings
            if strcmp(config.CIConf.algorithm, 'Monte Carlo')
                config.CIConf.MC.iteration = app.MCLmaxEditField.Value;
                config.CIConf.MC.fixMID = app.MCFixMIDCheckBox.Value;
                config.CIConf.MC.MIDSD = app.MCMIDSDEditField.Value;
                config.CIConf.MC.procedure = app.MCProcedureDropDown.Value;
                config.CIConf.MC.terminationTolerance = app.MCTTEditField.Value;
                config.CIConf.MC.proximityThreshold = app.MCProximityEditField.Value;
                config.CIConf.MC.certainThreshold = app.MCNasEditField.Value;
                config.CIConf.MC.theNumberOfRuns = app.MCKNREditField.Value;
                config.CIConf.MC.method = app.MCMethodDropDown.Value;
                config.CIConf.MC.optimizationProcedure = ...
                    openmebius.presentation.batch.RunConfigMapper.mcOptimizationProcedureToConfig( ...
                    app.MCProcedureDropDown.Value);
                config.CIConf.MC.calculationMethod = ...
                    openmebius.presentation.batch.RunConfigMapper.mcCalculationMethodToConfig(app.MCMethodDropDown.Value);

            end

            % Grid search-specific settings
            if strcmp(config.CIConf.algorithm, 'Grid search')
                config.CIConf.grid.isParallel = app.DeterminegridintervalautomaticallyCheckBox.Value;
                config.CIConf.grid.points = app.ThenumberofgridpointsEditField.Value;
                config.CIConf.grid.delta = app.GridintervalDeltaixiEditField.Value;
                config.CIConf.grid.iteration = app.IterationtimesforgridsearchEditField.Value;
                config.CIConf.grid.threshold = ...
                    openmebius.presentation.batch.RunConfigMapper.gridThresholdToConfig(app.ThresholdDropDown.Value);
            end

            config.isINSTMFA = app.INSTMFACheckBox.Value;

            if config.isINSTMFA

                % Get pool size table
                tablePoolSize = app.INSTMFAPoolUITable.Data;
                config.INSTMFA.poolMetabolite = string(tablePoolSize.Metabolite(:));
                config.INSTMFA.poolSize = double(tablePoolSize.PoolSize(:));

                % Get time course table
                tableTimeCourse = app.INSTMFATimeCourseUITable.Data;

                if isempty(tableTimeCourse)
                    config.INSTMFA.timePointsExpName = string.empty(0, 1);
                    config.INSTMFA.timePoints = double.empty(0, 1);
                else
                    config.INSTMFA.timePointsExpName = string(tableTimeCourse.TimePointExpName(:));
                    config.INSTMFA.timePoints = double(tableTimeCourse.TimePoint(:));
                end

            end % if config.isINSTMFA

            % Save the updated configuration back to the batch
            app.MainApp.batch.updateBatchConfig(batchID, config);

        end % applyGeneral

        function applyMSFragment(app)

            data = app.MSTable.Data;
            batch = app.MainApp.batch.getBatchForGUI();
            batchID = batch.ID(app.selection);

            batchIDUnique = unique(batchID);

            if length(batchID) ~= length(batchIDUnique)
                error('Duplicate batch IDs found in the selection.')
            end

            clear batchIDUnique

            fragmentSelections = ...
                openmebius.presentation.batch.MSFragmentTableMapper.fromViewTable( ...
                data, ...
                app.MSFragmentTableMetadata);

            app.MainApp.batch.updateBatchMSFragmentSelections(fragmentSelections)

        end % applyMSFragment

        function applyEffluxPerturbation(app, batchID)
            % APPLYEFFLUXPERTURBATION Save efflux-free Selection/SD table to batch config.

            arguments
                app
                batchID (:, 1) string
            end

            tableEffluxPerturbation = app.EffluxUITable.Data;

            if isempty(tableEffluxPerturbation)
                return
            end

            if ~istable(tableEffluxPerturbation)
                error("Efflux perturbation table is invalid.");
            end

            if ~all(ismember(["Selection", "SD"], string(tableEffluxPerturbation.Properties.VariableNames)))
                error("Efflux perturbation table must contain Selection and SD columns.");
            end

            tableEffluxPerturbation.Selection = logical(tableEffluxPerturbation.Selection(:));
            tableEffluxPerturbation.SD = double(tableEffluxPerturbation.SD(:));

            app.MainApp.batch.updateBatchConfigEffluxSD(batchID, tableEffluxPerturbation);

            % The efflux tab has its own Apply button. Therefore the
            % enable/disable flag must be persisted here as well, not only
            % when the General tab Apply button is pressed.
            for i = 1:length(batchID)
                config = app.MainApp.batch.getBatchConfig(batchID(i));
                config.perturbateEfflux = app.PerturbateEffluxCheckBox.Value;
                app.MainApp.batch.updateBatchConfig(batchID(i), config);
            end % for i

        end % applyEffluxPerturbation

        function applyINSTMFA(app)

            batch = app.MainApp.batch.getBatchForGUI();
            batchID = batch.ID(app.selection);
            batchIDUnique = unique(batchID);
            config = app.MainApp.batch.getBatchConfig(batchIDUnique);

            config.isINSTMFA = app.INSTMFACheckBox.Value;

            if config.isINSTMFA

                % Get pool size table
                tablePoolSize = app.INSTMFAPoolUITable.Data;
                config.INSTMFA.poolMetabolite = string(tablePoolSize.Metabolite(:));
                config.INSTMFA.poolSize = double(tablePoolSize.PoolSize(:));

                % Get time course table
                tableTimeCourse = app.INSTMFATimeCourseUITable.Data;

                if isempty(tableTimeCourse)
                    config.INSTMFA.timePointsExpName = string.empty(0, 1);
                    config.INSTMFA.timePoints = double.empty(0, 1);
                else
                    config.INSTMFA.timePointsExpName = string(tableTimeCourse.TimePointExpName(:));
                    config.INSTMFA.timePoints = double(tableTimeCourse.TimePoint(:));
                end

            end % if config.isINSTMFA

            % Save the updated configuration back to the batch
            app.MainApp.batch.updateBatchConfig(batchIDUnique, config);

        end % applyINSTMFA

        function wireActionButtons(app)

            restoreButtons = [ ...
                app.GeneralRestoreDefaultButton
                app.MSRestoreDefaultButton
                app.EffluxRestoreDefaultButton
                app.SuggestionRestoreDefaultButton
                app.INSTMFARestoreDefaultButton];
            applyButtons = [ ...
                app.GeneralApplyButton
                app.MSApplyAllButton
                app.EffluxApplyButton
                app.SuggestionApplyButton
                app.INSTMFAApplyButton];
            cancelButtons = [ ...
                app.GeneralCancelButton
                app.MSCancelButton
                app.EffluxCancelButton
                app.SuggestionCancelButton
                app.INSTMFACancelButton];

            for buttonIndex = 1:numel(restoreButtons)
                restoreButtons(buttonIndex).ButtonPushedFcn = ...
                    @(~, ~) app.restoreDefaultValues();
                applyButtons(buttonIndex).ButtonPushedFcn = ...
                    @(~, ~) app.applyCurrentSettings();
                cancelButtons(buttonIndex).ButtonPushedFcn = ...
                    @(~, ~) app.cancelChanges();
            end

        end % wireActionButtons

        function restoreDefaultValues(app)

            effluxData = app.EffluxUITable.Data;
            instPoolData = app.INSTMFAPoolUITable.Data;
            instTimeCourseData = app.INSTMFATimeCourseUITable.Data;
            selectedConfig = app.selectedConfig;
            restoreSelection = onCleanup( ...
                @() app.restoreSelectedConfig(selectedConfig));

            app.selectedConfig = ...
                openmebius.domain.batch.BatchConfig.defaultConfig();
            app.fillConfigValueToUI();

            msData = app.MSTable.Data;

            if istable(msData) && ~isempty(msData)
                msData{:, :} = true(height(msData), width(msData));
                app.MSTable.Data = msData;
            end

            app.enabledisableCIUI(app.CalcCICheckBox.Value);
            app.enabledisableSuggestion();

            app.EffluxUITable.Data = effluxData;
            app.EffluxUITable.Enable = 'off';
            app.EffluxUITable.ColumnEditable = ...
                app.readOnlyColumns(effluxData);

            app.INSTMFAPoolUITable.Data = instPoolData;
            app.INSTMFAPoolUITable.Enable = 'off';
            app.INSTMFAPoolUITable.ColumnEditable = ...
                app.readOnlyColumns(instPoolData);

            app.INSTMFATimeCourseUITable.Data = instTimeCourseData;
            app.INSTMFATimeCourseUITable.Enable = 'off';
            app.INSTMFATimeCourseUITable.ColumnEditable = ...
                app.readOnlyColumns(instTimeCourseData);

            clear restoreSelection

        end % restoreDefaultValues

        function applyCurrentSettings(app)

            app.applyGeneral();
            app.applyMSFragment();
            app.applySuggestionSettings();
            app.MainApp.updateBatchTable();

        end % applyCurrentSettings

        function applySuggestionSettings(app)

            data = app.LabelTable.Data;

            if ~istable(data)
                return
            end

            batch = app.MainApp.batch.getBatchForGUI();
            batchIDs = unique(batch.ID(app.selection));
            updateBatchConfigSuggestionTable( ...
                app.MainApp.batch, batchIDs, data);

        end % applySuggestionSettings

        function cancelChanges(app)

            delete(app);

        end % cancelChanges

        function restoreSelectedConfig(app, selectedConfig)

            app.selectedConfig = selectedConfig;

        end % restoreSelectedConfig

        function editable = readOnlyColumns(~, data)

            if istable(data)
                editable = false(1, width(data));
            else
                editable = false(1, 0);
            end

        end % readOnlyColumns

    end % private methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, selection)

            app.MainApp = MainApp;
            app.exp = MainApp.exp;

            selectionRow = selection(:, 1);
            app.selection = unique(selectionRow);
            app.setConfigValue()
            app.fillConfigValueToUI()
            app.enabledisableCIUI(app.CalcCICheckBox.Value)
            app.enabledisableEffluxPertubation()
            app.enabledisableSuggestion()
            app.enabledisableINSTMFA(app.INSTMFACheckBox.Value)
            app.loadMSFragmentTable()
            app.wireActionButtons()

        end

        % Close request function: BatchconfigUIFigure
        function BatchconfigUIFigureCloseRequest(app, event)

            delete(app)

        end

        % Callback function: not associated with a component
        function GeneralApplyButtonPushed(app, ~)

            app.applyCurrentSettings();

        end

        % Button pushed function: GeneralApplyButton
        function GeneralApplyButtonPushed2(app, ~)

            app.applyCurrentSettings();

        end

        % Button pushed function: GeneralCancelButton
        function GeneralCancelButtonPushed(app, ~)

            app.cancelChanges();

        end

        % Callback function: not associated with a component
        function MSApplyButtonPushed(app, ~)

            app.applyCurrentSettings();

        end

        % Button pushed function: MSApplyAllButton
        function MSApplyAllButtonPushed(app, ~)

            app.applyCurrentSettings();

        end

        % Button pushed function: MSCancelButton
        function MSCancelButtonPushed(app, ~)

            app.cancelChanges();

        end

        % Value changed function: PerturbateEffluxCheckBox
        function PerturbateEffluxCheckBoxValueChanged(app, event)

            app.enabledisableEffluxPertubation()

        end

        % Callback function: not associated with a component
        function EffluxApplyButtonPushed(app, ~)

            app.applyCurrentSettings();

        end

        % Callback function: not associated with a component
        function EffluxApplyAllButtonPushed(app, ~)

            app.applyCurrentSettings();

        end

        % Button pushed function: EffluxCancelButton
        function EffluxCancelButtonPushed(app, ~)

            app.cancelChanges();

        end

        % Callback function: not associated with a component
        function SuggestionApplyButtonPushed(app, ~)

            app.applyCurrentSettings();

        end

        % Button pushed function: SuggestionCancelButton
        function SuggestionCancelButtonPushed(app, ~)

            app.cancelChanges();

        end

        % Value changed function: CalcCICheckBox
        function CalcCICheckBoxValueChanged(app, event)

            enabledisableCIUI(app, app.CalcCICheckBox.Value)

        end

        % Value changed function: AlgorithmCIDropDown
        function AlgorithmCIDropDownValueChanged(app, event)

            enabledisableCIUI(app, app.CalcCICheckBox.Value)

        end

        % Value changed function:
        % DeterminegridintervalautomaticallyCheckBox
        function DeterminegridintervalautomaticallyCheckBoxValueChanged(app, event)

            enabledisableGridSetting(app)

        end

        % Value changed function: INSTMFACheckBox
        function INSTMFACheckBoxValueChanged(app, event)

            app.SuggestionCheckBox.Value = false;
            enabledisableSuggestion(app)

            enabledisableINSTMFA(app, event.Source.Value)

        end

        % Menu selected function: AddexperimentsMenu
        function AddexperimentsMenuSelected(app, event)

            app.editTimeCourse();

        end

        % Menu selected function: RemoveselectedexperimentMenu
        function RemoveselectedexperimentMenuSelected(app, event)

            selectedRow = app.INSTMFATimeCourseUITable.Selection;
            selectedRow = unique(selectedRow(:, 1));

            if isempty(selectedRow)
                return
            end

        end

        % Value changed function: SuggestionCheckBox
        function SuggestionCheckBoxValueChanged(app, event)

            isSuggestLabel = app.SuggestionCheckBox.Value;

            if isSuggestLabel

                app.CalcCICheckBox.Value = true;
                enabledisableCIUI(app, app.CalcCICheckBox.Value)
                enabledisableSuggestion(app)

            else

                return;

            end

        end

        % Double-clicked callback: LabelTable
        function LabelTableDoubleClicked(app, event)

            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;

            if isempty(displayRow) || isempty(displayColumn)
                return
            end

            app.openTracerConfiguration( ...
                [displayRow, displayColumn]);

        end

        % Menu selected function: AddnewpatternMenu
        function AddnewpatternMenuSelected(app, event)

            tableNow = app.LabelTable.Data;
            numCol = size(tableNow, 2);
            newRow = strings(1, numCol);
            tableNew = array2table(newRow, 'VariableNames', app.LabelTable.ColumnName');

            tableNow = [tableNow; tableNew];
            app.LabelTable.Data = tableNow;

        end

        % Menu selected function: AddnewpatternsMenu
        function AddnewpatternsMenuSelected(app, event)

            n = inputdlg('Enter the number of new patterns to add:', 'Add New Patterns', [1 50], {'1'});
            nNum = str2double(n{1});

            if isnan(nNum) || nNum <= 0 || mod(nNum, 1) ~= 0
                return;
            end

            tableNow = app.LabelTable.Data;
            numCol = size(tableNow, 2);
            newRows = strings(nNum, numCol);
            tableNew = array2table(newRows, 'VariableNames', app.LabelTable.ColumnName');

            tableNow = [tableNow; tableNew];
            app.LabelTable.Data = tableNow;

        end

        % Menu selected function: CopythistracerforallentriesMenu
        function CopythistracerforallentriesMenuSelected(app, event)

            idxSelection = app.LabelTable.Selection;

            if isempty(idxSelection)
                return;
            end

            selectedLabel = app.LabelTable.Data{idxSelection(1, 1), idxSelection(1, 2)};

            % Replace all entries in the selected column with the selected label
            tableNow = app.LabelTable.Data;
            numRows = size(tableNow, 1);

            for i = 1:numRows
                tableNow{i, idxSelection(1, 2)} = selectedLabel;
            end

            app.LabelTable.Data = tableNow;

        end

        % Key press function: BatchconfigUIFigure
        function BatchconfigUIFigureKeyPress(app, event)

            key = event.Key;

            % Esc
            if strcmp(key, 'escape')
                delete(app)
            end

        end

        % Callback function: not associated with a component
        function INSTMFAApplyButtonPushed(app, ~)

            app.applyCurrentSettings();

        end

        % Callback function: not associated with a component
        function INSTMFAReloadButtonPushed(app, ~)

            app.restoreDefaultValues();

        end

        % Button pushed function: INSTMFACancelButton
        function INSTMFACancelButtonPushed(app, ~)

            app.cancelChanges();

        end

    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BatchconfigUIFigure = uifigure('Visible', 'off');
            app.BatchconfigUIFigure.Name = 'Batch config';
            app.BatchconfigUIFigure.Icon = fullfile(pathToMLAPP, '+img', 'logo.png');
            app.BatchconfigUIFigure.CloseRequestFcn = createCallbackFcn(app, @BatchconfigUIFigureCloseRequest, true);
            app.BatchconfigUIFigure.KeyPressFcn = createCallbackFcn(app, @BatchconfigUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.BatchconfigUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GridLayout5_2 = uigridlayout(app.GeneralTab);
            app.GridLayout6_2 = uigridlayout(app.GridLayout5_2);
            app.GridLayout8 = uigridlayout(app.GridLayout6_2);
            app.GridLayoutIteration = uigridlayout(app.GridLayout8);
            app.IterationtimesforcalculationSpinnerLabel = uilabel(app.GridLayoutIteration);
            app.IterationtimesforcalculationSpinnerLabel.Text = 'Iteration times for calculation';
            app.IterationSpinner = uispinner(app.GridLayoutIteration);
            app.IterationSpinner.Limits = [1 100000];
            app.IterationSpinner.Value = 10;
            app.GridLayoutAlgorithm = uigridlayout(app.GridLayout8);
            app.CalculationalgorithmDropDownLabel = uilabel(app.GridLayoutAlgorithm);
            app.CalculationalgorithmDropDownLabel.Text = 'Calculation algorithm';
            app.AlgorithmDropDown = uidropdown(app.GridLayoutAlgorithm);
            app.AlgorithmDropDown.Items = {'IPMs', 'SQP'};
            app.AlgorithmDropDown.Value = 'SQP';
            app.LargeScaleCheckBox = uicheckbox(app.GridLayout8);
            app.LargeScaleCheckBox.Text = 'Large scale problem';
            app.SuggestionCheckBox = uicheckbox(app.GridLayout8);
            app.SuggestionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuggestionCheckBoxValueChanged, true);
            app.SuggestionCheckBox.Text = 'Suggest label tracer to increase accuracy';
            app.PerturbateEffluxCheckBox = uicheckbox(app.GridLayout8);
            app.PerturbateEffluxCheckBox.ValueChangedFcn = createCallbackFcn(app, @PerturbateEffluxCheckBoxValueChanged, true);
            app.PerturbateEffluxCheckBox.Text = 'Perturbate efflux';
            app.CalcCICheckBox = uicheckbox(app.GridLayout8);
            app.CalcCICheckBox.ValueChangedFcn = createCallbackFcn(app, @CalcCICheckBoxValueChanged, true);
            app.CalcCICheckBox.Text = 'Calculate confidence intervals of fluxes';
            app.GridLayoutAlgorithm_2 = uigridlayout(app.GridLayout8);
            app.AlgorithmforCIcalculationDropDownLabel = uilabel(app.GridLayoutAlgorithm_2);
            app.AlgorithmforCIcalculationDropDownLabel.Text = 'Algorithm for CI calculation';
            app.AlgorithmCIDropDown = uidropdown(app.GridLayoutAlgorithm_2);
            app.AlgorithmCIDropDown.Items = {'Monte Carlo', 'Grid search'};
            app.AlgorithmCIDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmCIDropDownValueChanged, true);
            app.AlgorithmCIDropDown.Value = 'Monte Carlo';
            app.DeleteResultButton = uicheckbox(app.GridLayout8);
            app.DeleteResultButton.Text = 'Delete result file when batch is canceled';
            app.DeleteResultButton.Value = true;
            app.INSTMFACheckBox = uicheckbox(app.GridLayout8);
            app.INSTMFACheckBox.ValueChangedFcn = createCallbackFcn(app, @INSTMFACheckBoxValueChanged, true);
            app.INSTMFACheckBox.Text = 'Instrationaly-MFA instead of parallel labeling';
            app.TabGroup2 = uitabgroup(app.GridLayout6_2);
            app.MonteCarloTab = uitab(app.TabGroup2);
            app.MonteCarloTab.Title = 'Monte Carlo';
            app.GridLayout9 = uigridlayout(app.MonteCarloTab);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.maximumnumberoftrialsLsubmaxsubLabel = uilabel(app.GridLayout10);
            app.maximumnumberoftrialsLsubmaxsubLabel.Interpreter = 'html';
            app.maximumnumberoftrialsLsubmaxsubLabel.Text = 'The number of trials (<i>L</i><sub>max</sub>):';
            app.MCLmaxEditField = uieditfield(app.GridLayout10, 'numeric');
            app.MCLmaxEditField.Limits = [10 Inf];
            app.MCLmaxEditField.Value = 500;
            app.MCFixMIDCheckBox = uicheckbox(app.GridLayout9);
            app.MCFixMIDCheckBox.Text = 'Fix MIDs'' variations';
            app.MCFixMIDCheckBox.Value = true;
            app.GridLayout10_2 = uigridlayout(app.GridLayout9);
            app.VariationsforMIDserrorLabel = uilabel(app.GridLayout10_2);
            app.VariationsforMIDserrorLabel.Text = 'Variations for MIDs'' error:';
            app.MCMIDSDEditField = uieditfield(app.GridLayout10_2, 'numeric');
            app.MCMIDSDEditField.Limits = [1e-10 Inf];
            app.MCMIDSDEditField.Value = 0.01;
            app.GridLayout10_3 = uigridlayout(app.GridLayout9);
            app.OptimizationprocedureDropDownLabel = uilabel(app.GridLayout10_3);
            app.OptimizationprocedureDropDownLabel.Text = 'Optimization procedure';
            app.MCProcedureDropDown = uidropdown(app.GridLayout10_3);
            app.MCProcedureDropDown.Items = {'Single run', 'Multiple run'};
            app.MCProcedureDropDown.Value = 'Multiple run';
            app.GridLayout10_4 = uigridlayout(app.GridLayout9);
            app.TerminationtoleranceTTEditFieldLabel = uilabel(app.GridLayout10_4);
            app.TerminationtoleranceTTEditFieldLabel.Text = 'Termination tolerance (TT):';
            app.MCTTEditField = uieditfield(app.GridLayout10_4, 'numeric');
            app.MCTTEditField.Limits = [1e-10 Inf];
            app.MCTTEditField.Value = 0.001;
            app.GridLayout10_5 = uigridlayout(app.GridLayout9);
            app.ProximitythresholdepsilonLabel = uilabel(app.GridLayout10_5);
            app.ProximitythresholdepsilonLabel.Interpreter = 'html';
            app.ProximitythresholdepsilonLabel.Text = 'Proximity threshold (<i>&epsilon;</i>):';
            app.MCProximityEditField = uieditfield(app.GridLayout10_5, 'numeric');
            app.MCProximityEditField.Limits = [1e-10 Inf];
            app.MCProximityEditField.Value = 0.001;
            app.GridLayout10_6 = uigridlayout(app.GridLayout9);
            app.CertainthresholdiNisubASsubEditFieldLabel = uilabel(app.GridLayout10_6);
            app.CertainthresholdiNisubASsubEditFieldLabel.Interpreter = 'html';
            app.CertainthresholdiNisubASsubEditFieldLabel.Text = 'Certain threshold (<i>N</i><sub>AS</sub>):';
            app.MCNasEditField = uieditfield(app.GridLayout10_6, 'numeric');
            app.MCNasEditField.Limits = [1 Inf];
            app.MCNasEditField.Value = 3;
            app.GridLayout10_7 = uigridlayout(app.GridLayout9);
            app.ThenumberofrunsiKisubNRsubEditFieldLabel = uilabel(app.GridLayout10_7);
            app.ThenumberofrunsiKisubNRsubEditFieldLabel.Interpreter = 'html';
            app.ThenumberofrunsiKisubNRsubEditFieldLabel.Text = 'The number of runs (<i>K</i><sub>NR</sub>):';
            app.MCKNREditField = uieditfield(app.GridLayout10_7, 'numeric');
            app.MCKNREditField.Limits = [10 Inf];
            app.MCKNREditField.Value = 50;
            app.GridLayout10_8 = uigridlayout(app.GridLayout9);
            app.CalculationmethodsDropDownLabel = uilabel(app.GridLayout10_8);
            app.CalculationmethodsDropDownLabel.Text = 'Calculation methods';
            app.MCMethodDropDown = uidropdown(app.GridLayout10_8);
            app.MCMethodDropDown.Items = {'Discarding', 'Mean-varianced'};
            app.MCMethodDropDown.Value = 'Discarding';
            app.GridsearchTab = uitab(app.TabGroup2);
            app.GridsearchTab.Title = 'Grid search';
            app.GridLayout11 = uigridlayout(app.GridsearchTab);
            app.DeterminegridintervalautomaticallyCheckBox = uicheckbox(app.GridLayout11);
            app.DeterminegridintervalautomaticallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeterminegridintervalautomaticallyCheckBoxValueChanged, true);
            app.DeterminegridintervalautomaticallyCheckBox.Text = 'Determine grid interval automatically';
            app.DeterminegridintervalautomaticallyCheckBox.Value = true;
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.ThenumberofgridpointsEditFieldLabel = uilabel(app.GridLayout12);
            app.ThenumberofgridpointsEditFieldLabel.Text = 'The number of grid points';
            app.ThenumberofgridpointsEditField = uieditfield(app.GridLayout12, 'numeric');
            app.ThenumberofgridpointsEditField.Limits = [10 Inf];
            app.ThenumberofgridpointsEditField.Value = 10;
            app.GridLayout12_2 = uigridlayout(app.GridLayout11);
            app.GridintervalDeltaixiEditFieldLabel = uilabel(app.GridLayout12_2);
            app.GridintervalDeltaixiEditFieldLabel.Interpreter = 'html';
            app.GridintervalDeltaixiEditFieldLabel.Text = 'Grid interval (&Delta;<i>x</i>):';
            app.GridintervalDeltaixiEditField = uieditfield(app.GridLayout12_2, 'numeric');
            app.GridintervalDeltaixiEditField.Limits = [0.1 Inf];
            app.GridintervalDeltaixiEditField.Value = 1;
            app.GridLayout12_3 = uigridlayout(app.GridLayout11);
            app.IterationtimesforgridsearchEditFieldLabel = uilabel(app.GridLayout12_3);
            app.IterationtimesforgridsearchEditFieldLabel.Text = 'Iteration times for grid search';
            app.IterationtimesforgridsearchEditField = uieditfield(app.GridLayout12_3, 'numeric');
            app.IterationtimesforgridsearchEditField.Limits = [1 Inf];
            app.IterationtimesforgridsearchEditField.Value = 30;
            app.GridLayout12_4 = uigridlayout(app.GridLayout11);
            app.ThresholdDropDownLabel = uilabel(app.GridLayout12_4);
            app.ThresholdDropDownLabel.Text = 'Threshold';
            app.ThresholdDropDown = uidropdown(app.GridLayout12_4);
            app.ThresholdDropDown.Items = {'F-distribution', 'Chi-squared'};
            app.ThresholdDropDown.Value = 'F-distribution';
            app.GridLayout7_2 = uigridlayout(app.GridLayout5_2);
            app.GeneralCancelButton = uibutton(app.GridLayout7_2, 'push');
            app.GeneralCancelButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralCancelButtonPushed, true);
            app.GeneralCancelButton.Text = 'Cancel';
            app.GeneralApplyButton = uibutton(app.GridLayout7_2, 'push');
            app.GeneralApplyButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralApplyButtonPushed2, true);
            app.GeneralApplyButton.Text = 'Apply';
            app.GeneralRestoreDefaultButton = uibutton(app.GridLayout7_2, 'push');
            app.GeneralRestoreDefaultButton.Text = 'Restore default';
            app.MSfragmentTab = uitab(app.TabGroup);
            app.MSfragmentTab.Title = 'MS fragment';
            app.GridLayout5 = uigridlayout(app.MSfragmentTab);
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.MSCancelButton = uibutton(app.GridLayout7, 'push');
            app.MSCancelButton.ButtonPushedFcn = createCallbackFcn(app, @MSCancelButtonPushed, true);
            app.MSCancelButton.Text = 'Cancel';
            app.MSApplyAllButton = uibutton(app.GridLayout7, 'push');
            app.MSApplyAllButton.ButtonPushedFcn = createCallbackFcn(app, @MSApplyAllButtonPushed, true);
            app.MSApplyAllButton.Text = 'Apply';
            app.MSRestoreDefaultButton = uibutton(app.GridLayout7, 'push');
            app.MSRestoreDefaultButton.Text = 'Restore default';
            app.MSTable = uitable(app.GridLayout5);
            app.MSTable.ColumnName = '';
            app.MSTable.RowName = {};
            app.EffluxperturbationTab = uitab(app.TabGroup);
            app.EffluxperturbationTab.Title = 'Efflux perturbation';
            app.GridLayout13 = uigridlayout(app.EffluxperturbationTab);
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.EffluxCancelButton = uibutton(app.GridLayout15, 'push');
            app.EffluxCancelButton.ButtonPushedFcn = createCallbackFcn(app, @EffluxCancelButtonPushed, true);
            app.EffluxCancelButton.Text = 'Cancel';
            app.EffluxApplyButton = uibutton(app.GridLayout15, 'push');
            app.EffluxApplyButton.Text = 'Apply';
            app.EffluxRestoreDefaultButton = uibutton(app.GridLayout15, 'push');
            app.EffluxRestoreDefaultButton.Text = 'Restore default';
            app.GridLayout22 = uigridlayout(app.GridLayout13);
            app.EffluxUITable = uitable(app.GridLayout22);
            app.EffluxUITable.ColumnName = '';
            app.EffluxUITable.RowName = {};
            app.EffluxUITable.Enable = 'off';
            app.TracersuggestionTab = uitab(app.TabGroup);
            app.TracersuggestionTab.Title = 'Tracer suggestion';
            app.GridLayout14 = uigridlayout(app.TracersuggestionTab);
            app.GridLayout16 = uigridlayout(app.GridLayout14);
            app.SuggestionCancelButton = uibutton(app.GridLayout16, 'push');
            app.SuggestionCancelButton.ButtonPushedFcn = createCallbackFcn(app, @SuggestionCancelButtonPushed, true);
            app.SuggestionCancelButton.Text = 'Cancel';
            app.SuggestionRestoreDefaultButton = uibutton(app.GridLayout16, 'push');
            app.SuggestionRestoreDefaultButton.Text = 'Restore default';
            app.SuggestionApplyButton = uibutton(app.GridLayout16, 'push');
            app.SuggestionApplyButton.Text = 'Apply';
            app.GridLayout17 = uigridlayout(app.GridLayout14);
            app.LabelTable = uitable(app.GridLayout17);
            app.LabelTable.ColumnName = '';
            app.LabelTable.RowName = {};
            app.LabelTable.DoubleClickedFcn = createCallbackFcn(app, @LabelTableDoubleClicked, true);
            app.LabelTable.Enable = 'off';
            app.GridLayout18 = uigridlayout(app.GridLayout17);
            app.INSTMFATab = uitab(app.TabGroup);
            app.INSTMFATab.Title = 'INST-MFA';
            app.GridLayout13_2 = uigridlayout(app.INSTMFATab);
            app.GridLayout15_2 = uigridlayout(app.GridLayout13_2);
            app.INSTMFACancelButton = uibutton(app.GridLayout15_2, 'push');
            app.INSTMFACancelButton.ButtonPushedFcn = createCallbackFcn(app, @INSTMFACancelButtonPushed, true);
            app.INSTMFACancelButton.Text = 'Cancel';
            app.INSTMFARestoreDefaultButton = uibutton(app.GridLayout15_2, 'push');
            app.INSTMFARestoreDefaultButton.Text = 'Restore default';
            app.INSTMFAApplyButton = uibutton(app.GridLayout15_2, 'push');
            app.INSTMFAApplyButton.Text = 'Apply';
            app.GridLayout20 = uigridlayout(app.GridLayout13_2);
            app.GridLayout21 = uigridlayout(app.GridLayout20);
            app.INSTMFAPoolUITable = uitable(app.GridLayout21);
            app.INSTMFAPoolUITable.ColumnName = '';
            app.INSTMFAPoolUITable.RowName = {};
            app.INSTMFAPoolUITable.Enable = 'off';
            app.INSTMFATimeCourseUITable = uitable(app.GridLayout20);
            app.INSTMFATimeCourseUITable.ColumnName = '';
            app.INSTMFATimeCourseUITable.RowName = {};
            app.ContextMenu = uicontextmenu(app.BatchconfigUIFigure);
            app.AddnewpatternMenu = uimenu(app.ContextMenu);
            app.AddnewpatternMenu.MenuSelectedFcn = createCallbackFcn(app, @AddnewpatternMenuSelected, true);
            app.AddnewpatternMenu.Text = 'Add new pattern';
            app.AddnewpatternsMenu = uimenu(app.ContextMenu);
            app.AddnewpatternsMenu.MenuSelectedFcn = createCallbackFcn(app, @AddnewpatternsMenuSelected, true);
            app.AddnewpatternsMenu.Text = 'Add new patterns';
            app.CopythistracerforallentriesMenu = uimenu(app.ContextMenu);
            app.CopythistracerforallentriesMenu.MenuSelectedFcn = createCallbackFcn(app, @CopythistracerforallentriesMenuSelected, true);
            app.CopythistracerforallentriesMenu.Text = 'Copy this tracer for all entries';
            % Assign app.ContextMenu
            app.LabelTable.ContextMenu = app.ContextMenu;
            app.ContextMenuINST = uicontextmenu(app.BatchconfigUIFigure);
            app.AddexperimentsMenu = uimenu(app.ContextMenuINST);
            app.AddexperimentsMenu.MenuSelectedFcn = createCallbackFcn(app, @AddexperimentsMenuSelected, true);
            app.AddexperimentsMenu.Text = 'Add experiments';
            app.RemoveselectedexperimentMenu = uimenu(app.ContextMenuINST);
            app.RemoveselectedexperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveselectedexperimentMenuSelected, true);
            app.RemoveselectedexperimentMenu.Text = 'Remove selected experiment';
            % Assign app.ContextMenuINST
            app.INSTMFATimeCourseUITable.ContextMenu = app.ContextMenuINST;
            % Show the figure after all components are created
            app.BatchconfigUIFigure.Visible = 'on';
        end

    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BatchconfigUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end

        end

        % Code that executes before app deletion
        function delete(app)

            app.detachRunAddBatchListener();
            app.detachTracerConfigListeners();

            if ~isempty(app.RunAddBatchApp)
                try
                    if isvalid(app.RunAddBatchApp)
                        delete(app.RunAddBatchApp);
                    end
                catch
                end
            end

            if ~isempty(app.TracerConfigApp)
                try
                    if isvalid(app.TracerConfigApp)
                        delete(app.TracerConfigApp);
                    end
                catch
                end
            end

            % Delete UIFigure when app is deleted
            delete(app.BatchconfigUIFigure)
        end

    end

end